classdef MSiToolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SaveMenu                     matlab.ui.container.Menu
        LoadMenu                     matlab.ui.container.Menu
        EditcolormapMenu             matlab.ui.container.Menu
        AUGMenu                      matlab.ui.container.Menu
        MSiToolboxLabel              matlab.ui.control.Label
        ReadFromWorkspaceCheckBox    matlab.ui.control.CheckBox
        DataPanel                    matlab.ui.container.Panel
        ImzMLimportPanel             matlab.ui.container.Panel
        FilenameLabel                matlab.ui.control.Label
        SelectButton                 matlab.ui.control.Button
        FileNameLabel                matlab.ui.control.Label
        EnterButton                  matlab.ui.control.Button
        TotalnumberofMSiLabel        matlab.ui.control.Label
        EditNoImages                 matlab.ui.control.EditField
        ListListBox                  matlab.ui.control.ListBox
        ListLabel                    matlab.ui.control.Label
        AbbreviatedinformationPanel  matlab.ui.container.Panel
        NoofpixelsisLabel            matlab.ui.control.Label
        PixNumberLabel               matlab.ui.control.Label
        ypixelsLabel                 matlab.ui.control.Label
        yPixNumberLabel              matlab.ui.control.Label
        xpixelsLabel                 matlab.ui.control.Label
        xPixNumberLabel              matlab.ui.control.Label
        LocationofpixelsLabel        matlab.ui.control.Label
        xEditField                   matlab.ui.control.EditField
        yEditField                   matlab.ui.control.EditField
        xLabel                       matlab.ui.control.Label
        yLabel                       matlab.ui.control.Label
        SpectrapreviewButton         matlab.ui.control.Button
        DetailedinformationPanel     matlab.ui.container.Panel
        PreviewAll                   matlab.ui.control.Button
        PlotTICButton                matlab.ui.control.Button
        MSintensityLabel             matlab.ui.control.Label
        Maxint                       matlab.ui.control.Label
        MedianLabel                  matlab.ui.control.Label
        Medint                       matlab.ui.control.Label
        mzrangefromLabel             matlab.ui.control.Label
        Mzlow                        matlab.ui.control.Label
        toLabel                      matlab.ui.control.Label
        Mzhigh                       matlab.ui.control.Label
        MaxLabel                     matlab.ui.control.Label
        PeakViewButton               matlab.ui.control.Button
        ROIButton                    matlab.ui.control.Button
        PartialareaROIButton         matlab.ui.control.Button
        LibraryPanel                 matlab.ui.container.Panel
        PathLabel                    matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        LoadButton                   matlab.ui.control.Button
        mzcol                        matlab.ui.control.EditField
        ThecolumnwheremzislocatedinthedatabaseLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filep=pwd;
            addpath(genpath(filep));
            assignin('base','filep',filep);
            evalin('base','importMSv.load.filep=filep;');
            evalin('base','importMSv.summary.color=jet;');
            evalin('base','clear filep;');
            color_order
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            % 保存数据
            if ((evalin('base','exist(''importMSv'')'))==1)
                importMSv=evalin('base','importMSv;');
                [file,path,Index] = uiputfile('*.mat','Please select a save folder');
                if Index == 0
                    return;
                else
                    save_file=strcat(path,file);
                    save(save_file,'importMSv');
                    disp([' ****************************Saved to ' save_file '*****************************']);
                end
            else
                disp(' **************************** There are no files in the workspace *****************************');
            end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            % 导入数据
            [file,path,Index] = uigetfile('*.mat','Please select the file you want to load');
            if Index == 0
                return;
            else
                load_file=strcat(path,file);
                assignin('base','load_file',load_file);
                evalin('base','load (string(load_file));');
                evalin('base','clear load_file;');
            end
        end

        % Menu selected function: EditcolormapMenu
        function EditcolormapMenuSelected(app, event)
            Editcolor
        end

        % Value changed function: ReadFromWorkspaceCheckBox
        function ReadFromWorkspaceCheckBoxValueChanged(app, event)
            % 读入工作区数据
            value = app.ReadFromWorkspaceCheckBox.Value;
            if value==1
                %选框设置
                noFiles=evalin('base','importMSv.load.noFiles');
                app.EditNoImages.Value= num2str(noFiles);
                if noFiles==1
                    llistannc(1)={'Image no. 1'};
                    app.ListListBox.Items = llistannc;
                    app.ListListBox.Enable = 'off';
                else
                    for h=1:noFiles
                        llistnnc=['Image no. ',num2str(h)];
                        llistannc(h)={llistnnc};
                    end
                    app.ListListBox.Items = llistannc;
                    app.ListListBox.Enable = 'on';
                end
                
                % 启用各个选框
                app.SelectButton.Enable='on';
                app.SpectrapreviewButton.Enable='on';
                app.PreviewAll.Enable='on';
                app.PeakViewButton.Enable='on';
                app.ROIButton.Enable='on';
                app.PartialareaROIButton.Enable='on';
                app.mzcol.Enable='on';
                app.LoadButton.Enable='on';
                
                % 简易预览窗口数值
                currentFile = evalin('base','importMSv.load.currentFile;');
                name_short=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.name_short;']);
                no_pixels=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.no_pixels;']);
                xpixels=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.xpixels;']);
                ypixels=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.ypixels;']);
                
                app.FileNameLabel.Text = [name_short, ' (reloaded)'];
                app.PixNumberLabel.Text = num2str(no_pixels);
                app.xPixNumberLabel.Text = num2str(xpixels);
                app.yPixNumberLabel.Text = num2str(ypixels);
                app.xEditField.Enable='on';
                app.yEditField.Enable='on';
                
                % 判断是否进行过全局预览及窗口数值
                
                dataPreview=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.dataPreview;']);
                if dataPreview==2
                    maxInt=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.maxInt;']);
                    medInt=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.medInt;']);
                    mzLow=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mzLow;']);
                    mzHigh=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mzHigh;']);
                    
                    app.Maxint.Text=num2str(maxInt);
                    app.Medint.Text=num2str(medInt);
                    app.Mzlow.Text=num2str(mzLow);
                    app.Mzhigh.Text=num2str(mzHigh);
                    
                    for h=1:noFiles
                        dataPreviewn=evalin('base',['importMSv.summary.img_',num2str(h),'.dataPreview;']);
                        if dataPreviewn==2
                            name_short1=evalin('base',['importMSv.summary.img_',num2str(h),'.name_short;']);
                            tic=evalin('base',['importMSv.summary.img_',num2str(h),'.tic_summary;']);
                            tic_name=['tic_image_no',num2str(h)];%name_short1
                            assignin('base',tic_name,tic);
                            %{
                            Frequency=evalin('base',['importMSv.summary.img_',num2str(h),'.Frequency;']);
                            Frequency_name=['Frequency_',name_short1];
                            assignin('base',Frequency_name,Frequency);
                            %}
                        end
                    end
                    
                    app.PlotTICButton.Enable='on';
                else
                    app.Maxint.Text='';
                    app.Medint.Text='';
                    app.Mzlow.Text='';
                    app.Mzhigh.Text='';
                    
                    app.PlotTICButton.Enable='off';
                end
                
                %判断工作区中的数据是否运行过ROI
                runROI= evalin('base','importMSv.summary.runROI;');
                if runROI==2
                    Runroi
                end
                
                runEqual=evalin('base','importMSv.summary.runEqual;');
                if runEqual==2
                    RunPartialarearoi
                    RunEqual
                end
            end
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            % 输入文件个数
            noFiles = str2double(app.EditNoImages.Value);
            assignin('base','noFiles',noFiles);
            evalin('base','importMSv.load.noFiles=noFiles;');
            evalin('base','clear noFiles;');
            % 初始化
            evalin('base','importMSv.summary.runROI=1;');
            evalin('base','importMSv.summary.runmcr=1;');
            evalin('base','importMSv.summary.runcompare=1;');
            evalin('base','importMSv.summary.runclustering=1;');
            evalin('base','importMSv.summary.runEqual=1;');
            for ix=1:noFiles
                evalin('base',['importMSv.summary.img_',num2str(ix),'.runpartialareaROI=1;']);
            end
            %  Listbox
            if noFiles==1
                llistannc(1)={'Image no. 1'};
                
                app.ListListBox.Items = llistannc;
                app.ListListBox.Enable = 'off';
                
                evalin('base','importMSv.load.currentFile=1;');
                evalin('base','importMSv.load.loadedFiles=0;');
            else
                for i=1:noFiles
                    llistnnc=['Image no. ',num2str(i)];
                    llistannc(i)={llistnnc};
                end
                
                app.ListListBox.Items = llistannc;
                app.ListListBox.Enable = 'on';
                
                evalin('base','importMSv.load.currentFile=1;');
                loadedFiles=zeros(noFiles,1);
                assignin('base','loadedFiles',loadedFiles);
                evalin('base','importMSv.load.loadedFiles=loadedFiles;');
                evalin('base','clear loadedFiles;');
            end
           
            if noFiles==1
                app.SelectButton.Enable = 'on';
            end
        end

        % Value changed function: ListListBox
        function ListListBoxValueChanged(app, event)
            % 指定文件
            app.ListListBox.ItemsData = 1:length(app.ListListBox.Items);
            currentFile = app.ListListBox.Value;
            assignin('base','currentFile',currentFile);
            evalin('base','importMSv.load.currentFile=currentFile;');
            evalin('base','clear currentFile;');
            % loadedbefore?
            loadedFile=evalin('base','importMSv.load.loadedFiles;');
            
            if loadedFile(currentFile,1)==1
                
                name_short=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.name_short;']);
                no_pixels=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.no_pixels;']);
                ypixels=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.ypixels;']);
                xpixels=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.xpixels;']);
                
                % summary
                app.FileNameLabel.Text = [name_short, ' (reloaded)'];
                app.PixNumberLabel.Text = num2str(no_pixels);
                app.xPixNumberLabel.Text = num2str(xpixels);
                app.yPixNumberLabel.Text = num2str(ypixels);
                app.xEditField.Enable='on';
                app.yEditField.Enable='on';
                
                % dataPreview?
                dataPreview=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.dataPreview;']);
                if dataPreview==2
                    maxInt=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.maxInt;']);
                    medInt=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.medInt;']);
                    mzLow=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mzLow;']);
                    mzHigh=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mzHigh;']);
                    
                    app.Maxint.Text=num2str(maxInt);
                    app.Medint.Text=num2str(medInt);
                    app.Mzlow.Text=num2str(mzLow);
                    app.Mzhigh.Text=num2str(mzHigh);
                    
                    app.PlotTICButton.Enable='on';
                else
                    app.Maxint.Text='';
                    app.Medint.Text='';
                    app.Mzlow.Text='';
                    app.Mzhigh.Text='';
                    
                    app.PlotTICButton.Enable='off';
                end
            else % nous valors
                % summary
                app.FileNameLabel.Text = '';
                app.PixNumberLabel.Text = '';
                app.xPixNumberLabel.Text = '';
                app.yPixNumberLabel.Text = '';
                app.xEditField.Value='';
                app.yEditField.Value='';
                app.Maxint.Text='';
                app.Medint.Text='';
                app.Mzlow.Text='';
                app.Mzhigh.Text='';
                
                app.xEditField.Enable='off';
                app.yEditField.Enable='off';
            end
            app.SelectButton.Enable = 'on';
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % 选择数据
            currentFile=evalin('base','importMSv.load.currentFile;');
            [filename, pathname,Index] = uigetfile('*.imzML', 'Pick an imzML MS file');
            if Index==0
                return;
            else
                app.FileNameLabel.Text = filename;
                
                assignin('base','fileSelected',filename);
                evalin('base',['importMSv.load.img_',num2str(currentFile),'.fileName=fileSelected;']);
                evalin('base','clear fileSelected;');
                
                assignin('base','pathnameSelected',pathname);
                evalin('base',['importMSv.load.img_',num2str(currentFile),'.pathName=pathnameSelected;']);
                evalin('base','clear pathnameSelected;');
                
                [allpixel,cols,rows]=Allpixel;
                
                assignin('base','allpixel',allpixel);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.no_pixels=allpixel;']);
                evalin('base','clear allpixel;');
                
                assignin('base','ypixels',rows);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.ypixels=ypixels;']);
                evalin('base','clear ypixels;');
                
                assignin('base','xpixels',cols);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.xpixels=xpixels;']);
                evalin('base','clear xpixels;');
                
                app.PixNumberLabel.Text = num2str(allpixel);
                app.xPixNumberLabel.Text = num2str(cols);
                app.yPixNumberLabel.Text = num2str(rows);
                
                loadedFile=evalin('base','importMSv.load.loadedFiles;');
                loadedFile(currentFile,1)=1;
                assignin('base','loadedFile',loadedFile);
                evalin('base','importMSv.load.loadedFiles=loadedFile;');
                
                if sum(loadedFile)==evalin('base','importMSv.load.noFiles;')
                    evalin('base','importMSv.summary.conditionsROI=1;');
                end
                evalin('base','clear loadedFile;');
                
                file=evalin('base',['importMSv.load.img_',num2str(currentFile),'.fileName']);
                [~,name_short,~] = fileparts(file);
                assignin('base','name_short',name_short);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.name_short=name_short;']);
                evalin('base','clear name_short;');
                
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.dataPreview=1;']);
                
                app.xEditField.Enable='on';
                app.yEditField.Enable='on';
                app.SpectrapreviewButton.Enable='on';
                app.PreviewAll.Enable='on';
                app.PeakViewButton.Enable='on';
                app.ROIButton.Enable='on';
                app.PartialareaROIButton.Enable='on';
                app.mzcol.Enable='on';
                app.LoadButton.Enable='on';
            end
        end

        % Button pushed function: SpectrapreviewButton
        function SpectrapreviewButtonPushed(app, event)
            %预览特定图谱
            x = str2double(app.xEditField.Value);
            y= str2double(app.yEditField.Value);
            try
                Preview(x,y)
            catch
                errordlg('Out of index range','错误');
                disp('***********************Out of index range***********************');
            end
        end

        % Button pushed function: PeakViewButton
        function PeakViewButtonPushed(app, event)
            PeakView
        end

        % Button pushed function: PreviewAll
        function PreviewAllPushed(app, event)
            %预览图谱
            currentFile=evalin('base','importMSv.load.currentFile;');
            dataPreview=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.dataPreview;']);
            name_short=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.name_short;']);
            xpix=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.xpixels']);
            ypix=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.ypixels']);
            tic_name=['tic_image_no',num2str(currentFile)];% name_short
            %Frequency_name=['Frequency_',name_short];
            
            if dataPreview==1
                [tic,maximImg,medianImg,mzMax,mzMin,~]=PreviewIntensity;
                
                app.Maxint.Text=num2str(maximImg);
                app.Medint.Text=num2str(medianImg);
                app.Mzlow.Text=num2str(mzMin);
                app.Mzhigh.Text=num2str(mzMax);
                
                [mascara,Indexes,Indexesii]=cut(tic,xpix,ypix);
                
                newtic=tic(Indexes,:);
                
                assignin('base','mascara',mascara);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mascara=mascara;']);
                
                assignin('base','Indexes',Indexes);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.Indexes=Indexes;']);
                
                assignin('base','Indexesii',Indexesii);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.Indexesii=Indexesii;']);
                
                assignin('base',tic_name,newtic);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.tic_summary=',tic_name,';']);
                
                %assignin('base',Frequency_name,Frequency);
                %evalin('base',['importMSv.summary.img_',num2str(currentFile),'.Frequency=',Frequency_name,';']);
                
                assignin('base','maxInt',maximImg);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.maxInt=maxInt;']);
                
                assignin('base','medInt',medianImg);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.medInt=medInt;']);
                
                assignin('base','mzLow',mzMin);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mzLow=mzLow;']);
                
                assignin('base','mzHigh',mzMax);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mzHigh=mzHigh;']);
                
                evalin('base','clear Frequency maxInt medInt mzLow mzHigh mascara Indexes Indexesii');
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.dataPreview=2;']);
            else
                %{
                Frequency=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.Frequency;']);
                figure
                bar(Frequency(:,1),Frequency(:,2),1)
                xlabel('Intensity')
                ylabel('Frequency')
                title({'Intensity ranking of the top 100 frequencies' ;  '(Slide the mouse wheel to enlarge the chart)'; ''});
                %}
                %{
                figure
                plot(log(Frequency(:,2)),Frequency(:,1))
                xlabel('log(Frequency)')
                ylabel('Intensity')
                %}
            end
            
            app.PlotTICButton.Enable='on';
        end

        % Button pushed function: PlotTICButton
        function PlotTICButtonPushed(app, event)
            % 画总强度图
            currentFile=evalin('base','importMSv.load.currentFile;');
            mascara=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mascara']);
            Indexes=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.Indexes']);
            Indexesii=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.Indexesii']);
            name_short=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.name_short;']);
            colorll=evalin('base','importMSv.summary.color;');
            
            I=set_range(mascara,4,0.95);
            
            figure,imagesc(I);
            %subplot(4,1,1:3);
            axis('xy','tight','image') ;colormap(colorll);colorbar;
            title({['Name: ',name_short]; []},'Interpreter','none');
            %{
                % 下面的强度分布
                subplot(4,1,4);
                plot(tic);
            %}
            
            % 点击像素点查看质谱图
            exite=0;
            try
                [x,y]=ginput(1);
                exite=1;
            catch
            end
            if exite==1
                x=round(x);
                y=round(y);
                % 定位十字
                hold on;
                [m,n]=size(mascara);
                level_x=[0,n];
                level_y=[y,y];
                vertical_x=[x,x];
                vertical_y=[0,m];
                plot(level_x,level_y,'k',vertical_x,vertical_y,'k','linewidth',2);
                hold off;
                
                % 对应到原始谱中
                point=Indexes(y,x);
                [y1,x1]=find(Indexesii==point);
                try
                    Preview(x1,y1)
                catch
                    errordlg('Out of index range','错误');
                    disp('***********************Out of index range***********************');
                end
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [libname, libpath,Index] = uigetfile({'*.xlsx';'*.txt';'*.*'},  'Please select a database file');
            if Index==0
                return;
            else
                library_file=strcat(libpath,libname);
                app.Label.Text=string(library_file);
                
                assignin('base','library_file',library_file);
                evalin('base','importMSv.library.filepath=library_file;');
                
                warning('off')
                evalin('base','library = readtable(string(library_file));');
                evalin('base','importMSv.library.database=library;');
                warning('on')
                
                mz_col = str2double(app.mzcol.Value);
                assignin('base','mzcol',mz_col);
                evalin('base','importMSv.library.mzcol=mzcol;');
                
                evalin('base','clear library_file library mzcol;');
            end
        end

        % Button pushed function: ROIButton
        function ROIButtonPushed(app, event)
            Runroi
        end

        % Button pushed function: PartialareaROIButton
        function PartialareaROIButtonPushed(app, event)
            RunPartialarearoi
        end

        % Menu selected function: AUGMenu
        function AUGMenuSelected(app, event)
            RunAUG
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaveMenu = uimenu(app.UIFigure);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.UIFigure);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load ';
            app.EditcolormapMenu = uimenu(app.UIFigure);
            app.EditcolormapMenu.MenuSelectedFcn = createCallbackFcn(app, @EditcolormapMenuSelected, true);
            app.EditcolormapMenu.Text = 'Edit colormap';
            app.AUGMenu = uimenu(app.UIFigure);
            app.AUGMenu.MenuSelectedFcn = createCallbackFcn(app, @AUGMenuSelected, true);
            app.AUGMenu.Text = 'AUG';
            app.MSiToolboxLabel = uilabel(app.UIFigure);
            app.MSiToolboxLabel.Text = 'MSiToolbox';
            app.ReadFromWorkspaceCheckBox = uicheckbox(app.UIFigure);
            app.ReadFromWorkspaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReadFromWorkspaceCheckBoxValueChanged, true);
            app.ReadFromWorkspaceCheckBox.Text = 'Read From Workspace?';
            app.DataPanel = uipanel(app.UIFigure);
            app.DataPanel.Title = 'Data';
            app.ImzMLimportPanel = uipanel(app.DataPanel);
            app.ImzMLimportPanel.Title = 'ImzML import';
            app.FilenameLabel = uilabel(app.ImzMLimportPanel);
            app.FilenameLabel.Text = 'File name:';
            app.SelectButton = uibutton(app.ImzMLimportPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Enable = 'off';
            app.SelectButton.Text = 'Select';
            app.FileNameLabel = uilabel(app.ImzMLimportPanel);
            app.FileNameLabel.Text = '';
            app.EnterButton = uibutton(app.ImzMLimportPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.TotalnumberofMSiLabel = uilabel(app.ImzMLimportPanel);
            app.TotalnumberofMSiLabel.Text = 'Total number of MSi:';
            app.EditNoImages = uieditfield(app.ImzMLimportPanel, 'text');
            app.ListListBox = uilistbox(app.ImzMLimportPanel);
            app.ListListBox.Items = {};
            app.ListListBox.ValueChangedFcn = createCallbackFcn(app, @ListListBoxValueChanged, true);
            app.ListListBox.Enable = 'off';
            app.ListListBox.Value = {};
            app.ListLabel = uilabel(app.ImzMLimportPanel);
            app.ListLabel.Text = 'List';
            app.AbbreviatedinformationPanel = uipanel(app.DataPanel);
            app.AbbreviatedinformationPanel.Title = 'Abbreviated information';
            app.NoofpixelsisLabel = uilabel(app.AbbreviatedinformationPanel);
            app.NoofpixelsisLabel.Text = 'No. of pixels is';
            app.PixNumberLabel = uilabel(app.AbbreviatedinformationPanel);
            app.PixNumberLabel.Text = '';
            app.ypixelsLabel = uilabel(app.AbbreviatedinformationPanel);
            app.ypixelsLabel.Text = 'y-pixels';
            app.yPixNumberLabel = uilabel(app.AbbreviatedinformationPanel);
            app.yPixNumberLabel.Text = '';
            app.xpixelsLabel = uilabel(app.AbbreviatedinformationPanel);
            app.xpixelsLabel.Text = 'x-pixels';
            app.xPixNumberLabel = uilabel(app.AbbreviatedinformationPanel);
            app.xPixNumberLabel.Text = '';
            app.LocationofpixelsLabel = uilabel(app.AbbreviatedinformationPanel);
            app.LocationofpixelsLabel.Text = 'Location of pixels:';
            app.xEditField = uieditfield(app.AbbreviatedinformationPanel, 'text');
            app.xEditField.Enable = 'off';
            app.yEditField = uieditfield(app.AbbreviatedinformationPanel, 'text');
            app.yEditField.Enable = 'off';
            app.xLabel = uilabel(app.AbbreviatedinformationPanel);
            app.xLabel.Text = 'x:';
            app.yLabel = uilabel(app.AbbreviatedinformationPanel);
            app.yLabel.Text = 'y:';
            app.SpectrapreviewButton = uibutton(app.AbbreviatedinformationPanel, 'push');
            app.SpectrapreviewButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrapreviewButtonPushed, true);
            app.SpectrapreviewButton.Enable = 'off';
            app.SpectrapreviewButton.Text = 'Spectra preview';
            app.DetailedinformationPanel = uipanel(app.UIFigure);
            app.DetailedinformationPanel.Title = 'Detailed information';
            app.PreviewAll = uibutton(app.DetailedinformationPanel, 'push');
            app.PreviewAll.ButtonPushedFcn = createCallbackFcn(app, @PreviewAllPushed, true);
            app.PreviewAll.Enable = 'off';
            app.PreviewAll.Text = 'Access to Information';
            app.PlotTICButton = uibutton(app.DetailedinformationPanel, 'push');
            app.PlotTICButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTICButtonPushed, true);
            app.PlotTICButton.Enable = 'off';
            app.PlotTICButton.Text = 'Plot TIC';
            app.MSintensityLabel = uilabel(app.DetailedinformationPanel);
            app.MSintensityLabel.Text = 'MS intensity:';
            app.Maxint = uilabel(app.DetailedinformationPanel);
            app.Maxint.Text = '';
            app.MedianLabel = uilabel(app.DetailedinformationPanel);
            app.MedianLabel.Text = 'Median:';
            app.Medint = uilabel(app.DetailedinformationPanel);
            app.Medint.Text = '';
            app.mzrangefromLabel = uilabel(app.DetailedinformationPanel);
            app.mzrangefromLabel.Text = 'm/z range from';
            app.Mzlow = uilabel(app.DetailedinformationPanel);
            app.Mzlow.Text = '';
            app.toLabel = uilabel(app.DetailedinformationPanel);
            app.toLabel.Text = 'to';
            app.Mzhigh = uilabel(app.DetailedinformationPanel);
            app.Mzhigh.Text = '';
            app.MaxLabel = uilabel(app.DetailedinformationPanel);
            app.MaxLabel.Text = 'Max:';
            app.PeakViewButton = uibutton(app.DetailedinformationPanel, 'push');
            app.PeakViewButton.ButtonPushedFcn = createCallbackFcn(app, @PeakViewButtonPushed, true);
            app.PeakViewButton.Enable = 'off';
            app.PeakViewButton.Text = 'Peak View';
            app.ROIButton = uibutton(app.UIFigure, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Enable = 'off';
            app.ROIButton.Text = 'ROI';
            app.PartialareaROIButton = uibutton(app.UIFigure, 'push');
            app.PartialareaROIButton.ButtonPushedFcn = createCallbackFcn(app, @PartialareaROIButtonPushed, true);
            app.PartialareaROIButton.Enable = 'off';
            app.PartialareaROIButton.Text = 'Partial area ROI';
            app.LibraryPanel = uipanel(app.UIFigure);
            app.LibraryPanel.Title = 'Library';
            app.PathLabel = uilabel(app.LibraryPanel);
            app.PathLabel.Text = 'Path:';
            app.Label = uilabel(app.LibraryPanel);
            app.Label.Text = '';
            app.LoadButton = uibutton(app.LibraryPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.mzcol = uieditfield(app.LibraryPanel, 'text');
            app.mzcol.Enable = 'off';
            app.ThecolumnwheremzislocatedinthedatabaseLabel = uilabel(app.LibraryPanel);
            app.ThecolumnwheremzislocatedinthedatabaseLabel.Text = 'The column where m/z is located in the database';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSiToolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end