classdef RunImageRegistration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Rotatearray90Button         matlab.ui.control.Button
        CroparrayButton             matlab.ui.control.Button
        FliparrayuptodownButton     matlab.ui.control.Button
        FliparraylefttorightButton  matlab.ui.control.Button
        ImageRegistrationButton     matlab.ui.control.Button
        Rotateimage90Button         matlab.ui.control.Button
        CropimageButton             matlab.ui.control.Button
        FlipimageuptodownButton     matlab.ui.control.Button
        FlipimagelefttorightButton  matlab.ui.control.Button
        MSIdataDropDownLabel        matlab.ui.control.Label
        MSIdataDropDown             matlab.ui.control.DropDown
        ImportimageButton           matlab.ui.control.Button
        ImageRegistrationLabel      matlab.ui.control.Label
        ResetMSIButton              matlab.ui.control.Button
        ResetimageButton            matlab.ui.control.Button
        PleaseadjustMSIandstainedimagetobesimilarLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            noFiles=evalin('base','importMSv.load.noFiles;');
            for h=1:noFiles
                name=evalin('base',['importMSv.summary.img_',num2str(h),'.name_short;']);
                llistnnc=name;
                llistannc(h)={llistnnc};
            end
            app.MSIdataDropDown.Items=llistannc;
            evalin('base','importMSv.Registration.currentFile=1;');
            
            MSI=evalin('base','importMSv.ROIplotGUI.img_1.mascara;');
            
            % 设置最大显示intensity为中位数
            mm=MSI(MSI>0);
            m=median(mm);
            MSI(MSI>m)=m;
            
            assignin('base','MSI',MSI)
            evalin('base','importMSv.Registration.img_1.MSI=MSI;');
            evalin("base",'clear MSI;');
            
            
            xx=MSI/max(MSI(:));
            [xxx]=gray2ind(xx,255);
            fixed=ind2rgb(xxx,flipud(hot));
            
            assignin('base','fixed',fixed)
            evalin('base','importMSv.Registration.img_1.fixed=fixed;')
            evalin("base",'clear fixed;');
            
            imshow(fixed,'Parent',app.UIAxes);
        end

        % Value changed function: MSIdataDropDown
        function MSIdataDropDownValueChanged(app, event)
            % 选择MSI
            app.MSIdataDropDown.ItemsData = 1:length(app.MSIdataDropDown.Items);
            currentFile=app.MSIdataDropDown.Value;
            
            assignin('base','currentFile',currentFile)
            evalin('base','importMSv.Registration.currentFile=currentFile;');
            evalin("base",'clear currentFile;');
            
            MSI=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.mascara;']);
            
            % 设置最大显示intensity为中位数
            mm=MSI(MSI>0);
            m=median(mm);
            MSI(MSI>m)=m;
            
            
            assignin('base','MSI',MSI)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.MSI=MSI;']);
            evalin("base",'clear MSI;');
            
            xx=MSI/max(MSI(:));
            [xxx]=gray2ind(xx,255);
            fixed=ind2rgb(xxx,flipud(hot));
            
            assignin('base','fixed',fixed)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed=fixed;']);
            evalin("base",'clear fixed;');
            
            imshow(fixed,'Parent',app.UIAxes);
        end

        % Button pushed function: ResetMSIButton
        function ResetMSIButtonPushed(app, event)
            % 重置
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            MSI= evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.MSI;']);
            
            xx=MSI/max(MSI(:));
            [xxx]=gray2ind(xx,255);
            fixed=ind2rgb(xxx,flipud(hot));
            
            assignin('base','fixed',fixed)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed=fixed;']);
            evalin("base",'clear currentFile fixed;');
            
            imshow(fixed,'Parent',app.UIAxes);
        end

        % Button pushed function: Rotatearray90Button
        function Rotatearray90ButtonPushed(app, event)
            % 旋转90°
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            
            fixed=rot90(fixed);
            
            assignin('base','fixed',fixed)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed=fixed;']);
            evalin("base",'clear fixed;');
            
            imshow(fixed,'Parent',app.UIAxes);
        end

        % Button pushed function: CroparrayButton
        function CroparrayButtonPushed(app, event)
            % 图片裁剪
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            
            fixed = cut_picture(fixed);
            
            assignin('base','fixed',fixed)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed=fixed;']);
            evalin("base",'clear fixed;');
            
            imshow(fixed,'Parent',app.UIAxes);
        end

        % Button pushed function: FliparrayuptodownButton
        function FliparrayuptodownButtonPushed(app, event)
            % 上下翻转
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            
            fixed=flipud(fixed);
            
            assignin('base','fixed',fixed)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed=fixed;']);
            evalin("base",'clear fixed;');
            
            imshow(fixed,'Parent',app.UIAxes);
        end

        % Button pushed function: FliparraylefttorightButton
        function FliparraylefttorightButtonPushed(app, event)
            % 左右翻转
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            
            fixed=fliplr(fixed);
            
            assignin('base','fixed',fixed)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed=fixed;']);
            evalin("base",'clear fixed;');
            
            imshow(fixed,'Parent',app.UIAxes);
        end

        % Button pushed function: ImportimageButton
        function ImportimageButtonPushed(app, event)
            % 选择染色图
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            [filename, pathname] = uigetfile({'*.tif';'*.png';'*.jpg';'*.bpm'}, 'Pick a photo');
            
            if isequal(filename,0) ||isequal(pathname,0)
                errordlg('没有选中文件','错误');
            else
                file=strcat(pathname,filename);
            end
            
            stained=imread(file);
            assignin('base','stained',stained)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.Stained=stained;']);
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving=stained;']);
            evalin('base','clear stained;');
            
            imshow(stained,'Parent',app.UIAxes_2);
        end

        % Button pushed function: ResetimageButton
        function ResetimageButtonPushed(app, event)
            % 重置
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.Stained;']);
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving=moving;']);
            evalin("base",'clear currentFile moving;');
            
            imshow(moving,'Parent',app.UIAxes_2);
        end

        % Button pushed function: Rotateimage90Button
        function Rotateimage90ButtonPushed(app, event)
            % 旋转90°
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            
            moving=rot90(moving);
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving=moving;']);
            evalin("base",'clear moving;');
            
            imshow(moving,'Parent',app.UIAxes_2);
        end

        % Button pushed function: CropimageButton
        function CropimageButtonPushed(app, event)
            % 图片裁剪
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            
            moving = cut_picture(moving);
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving=moving;']);
            evalin("base",'clear moving;');
            
            imshow(moving,'Parent',app.UIAxes_2);
        end

        % Button pushed function: FlipimageuptodownButton
        function FlipimageuptodownButtonPushed(app, event)
            % 上下翻转
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            
            moving=flipud(moving);
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving=moving;']);
            evalin("base",'clear moving;');
            
            imshow(moving,'Parent',app.UIAxes_2);
        end

        % Button pushed function: FlipimagelefttorightButton
        function FlipimagelefttorightButtonPushed(app, event)
            % 左右翻转
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            
            moving=fliplr(moving);
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving=moving;']);
            evalin("base",'clear moving;');
            
            imshow(moving,'Parent',app.UIAxes_2);
        end

        % Button pushed function: ImageRegistrationButton
        function ImageRegistrationButtonPushed(app, event)
            % 配准
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            [m,n,~]=size(moving);
            f_fixed=imresize(fixed,[m,n]);
            
            [selectedMovingPoints,selectedFixedPoints] = cpselect(moving,f_fixed,'Wait',true);
            movingPointsAdjusted = cpcorr(selectedMovingPoints,selectedFixedPoints,moving(:,:,1),f_fixed(:,:,1)); % 自动核对
            tform = fitgeotrans(movingPointsAdjusted,selectedFixedPoints,'nonreflectivesimilarity');
            
            f_moving=imwarp(moving,tform);
            
            assignin('base','f_fixed',f_fixed)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.f_fixed=f_fixed;']);
            
            assignin('base','f_moving',f_moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.f_moving=f_moving;']);
            evalin("base",'clear f_fixed f_moving;');
            
            figure,imshowpair(f_fixed,f_moving,'Scaling','independent');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Rotatearray90Button = uibutton(app.UIFigure, 'push');
            app.Rotatearray90Button.ButtonPushedFcn = createCallbackFcn(app, @Rotatearray90ButtonPushed, true);
            app.Rotatearray90Button.Text = 'Rotate array 90°';
            app.CroparrayButton = uibutton(app.UIFigure, 'push');
            app.CroparrayButton.ButtonPushedFcn = createCallbackFcn(app, @CroparrayButtonPushed, true);
            app.CroparrayButton.Text = 'Crop array';
            app.FliparrayuptodownButton = uibutton(app.UIFigure, 'push');
            app.FliparrayuptodownButton.ButtonPushedFcn = createCallbackFcn(app, @FliparrayuptodownButtonPushed, true);
            app.FliparrayuptodownButton.Text = 'Flip array up to down';
            app.FliparraylefttorightButton = uibutton(app.UIFigure, 'push');
            app.FliparraylefttorightButton.ButtonPushedFcn = createCallbackFcn(app, @FliparraylefttorightButtonPushed, true);
            app.FliparraylefttorightButton.Text = 'Flip array left to right';
            app.ImageRegistrationButton = uibutton(app.UIFigure, 'push');
            app.ImageRegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @ImageRegistrationButtonPushed, true);
            app.ImageRegistrationButton.Text = 'Image Registration';
            app.Rotateimage90Button = uibutton(app.UIFigure, 'push');
            app.Rotateimage90Button.ButtonPushedFcn = createCallbackFcn(app, @Rotateimage90ButtonPushed, true);
            app.Rotateimage90Button.Text = 'Rotate image 90°';
            app.CropimageButton = uibutton(app.UIFigure, 'push');
            app.CropimageButton.ButtonPushedFcn = createCallbackFcn(app, @CropimageButtonPushed, true);
            app.CropimageButton.Text = 'Crop image';
            app.FlipimageuptodownButton = uibutton(app.UIFigure, 'push');
            app.FlipimageuptodownButton.ButtonPushedFcn = createCallbackFcn(app, @FlipimageuptodownButtonPushed, true);
            app.FlipimageuptodownButton.Text = 'Flip image up to down';
            app.FlipimagelefttorightButton = uibutton(app.UIFigure, 'push');
            app.FlipimagelefttorightButton.ButtonPushedFcn = createCallbackFcn(app, @FlipimagelefttorightButtonPushed, true);
            app.FlipimagelefttorightButton.Text = 'Flip image left to right';
            app.MSIdataDropDownLabel = uilabel(app.UIFigure);
            app.MSIdataDropDownLabel.Text = 'MSI data';
            app.MSIdataDropDown = uidropdown(app.UIFigure);
            app.MSIdataDropDown.Items = {};
            app.MSIdataDropDown.ValueChangedFcn = createCallbackFcn(app, @MSIdataDropDownValueChanged, true);
            app.MSIdataDropDown.Value = {};
            app.ImportimageButton = uibutton(app.UIFigure, 'push');
            app.ImportimageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportimageButtonPushed, true);
            app.ImportimageButton.Text = 'Import image';
            app.ImageRegistrationLabel = uilabel(app.UIFigure);
            app.ImageRegistrationLabel.Text = 'Image Registration';
            app.ResetMSIButton = uibutton(app.UIFigure, 'push');
            app.ResetMSIButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMSIButtonPushed, true);
            app.ResetMSIButton.Text = 'Reset MSI';
            app.ResetimageButton = uibutton(app.UIFigure, 'push');
            app.ResetimageButton.ButtonPushedFcn = createCallbackFcn(app, @ResetimageButtonPushed, true);
            app.ResetimageButton.Text = 'Reset image';
            app.PleaseadjustMSIandstainedimagetobesimilarLabel = uilabel(app.UIFigure);
            app.PleaseadjustMSIandstainedimagetobesimilarLabel.Text = 'Please adjust MSI and stained image to be similar';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'MSI')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Stained')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunImageRegistration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end