classdef registration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StretchpictureleftandrightSlider  matlab.ui.control.Slider
        StretchpictureleftandrightLabel  matlab.ui.control.Label
        StretchpictureleftandrightSlider_2  matlab.ui.control.Slider
        StretchpictureupanddownLabel  matlab.ui.control.Label
        StretchpictureleftandrightSlider_3  matlab.ui.control.Slider
        PanningpictureleftandrightLabel  matlab.ui.control.Label
        StretchpictureleftandrightSlider_4  matlab.ui.control.Slider
        PanningpictureupanddownLabel  matlab.ui.control.Label
        StretchpictureleftandrightSlider_5  matlab.ui.control.Slider
        RotatepictureLabel            matlab.ui.control.Label
        StretchpictureleftandrightSlider_6  matlab.ui.control.Slider
        TransparencyLabel             matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        Spinner                       matlab.ui.control.Spinner
        Spinner_2                     matlab.ui.control.Spinner
        Spinner_3                     matlab.ui.control.Spinner
        Spinner_4                     matlab.ui.control.Spinner
        Spinner_5                     matlab.ui.control.Spinner
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            
            [m,n,~]=size(moving);
            fixed=imresize(fixed,[m,n]);
            black_pixels = uint8((moving(:, :, 1) == 0 & moving(:, :, 2) == 0 & moving(:, :, 3) == 0));
            
            alpha = 0.5;  % 替换为您需要的权重值
            % 图像融合
            blended_image = imadd(alpha*moving,fixed);
            % 显示和保存融合后的图像
            imshow(blended_image,'Parent',app.UIAxes);
            
            assignin('base','fixed',fixed)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed=fixed;']);
            
            assignin('base','black_pixels',black_pixels)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels=black_pixels;']);
            
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.alpha=0.5;']);
            
            evalin("base",'clear fixed black_pixels ;');
        end

        % Value changing function: StretchpictureleftandrightSlider
        function StretchpictureleftandrightSliderValueChanging(app, event)
            rl = event.Value;
            
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            black_pixels=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels;']);
            alpha = evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.alpha;']);
            
            % 平移
            moving = imtranslate(moving,[rl,0]);
            black_pixels = imtranslate(black_pixels,[rl,0]);
            
            % 成像
%             % 更改背景颜色
%             moving (moving==0) = 255;
%             moving (black_pixels==1)=0;
            % 裁剪至指定维度
            [m,n,~]=size(fixed);
            moving=imcrop(moving,[0,0,n,m]);
            % 图像融合
            blended_image = imadd(alpha*moving,fixed);
            % 显示和保存融合后的图像
            imshow(blended_image,'Parent',app.UIAxes);
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving_cache=moving;']);
            
            assignin('base','black_pixels',black_pixels)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels=black_pixels;']);
            evalin("base",'clear moving black_pixels;');
            app.Spinner.Value=rl;
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            rl = event.Value;
            
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            black_pixels=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels;']);
            alpha = evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.alpha;']);
            
            % 平移
            moving = imtranslate(moving,[rl,0]);
            black_pixels = imtranslate(black_pixels,[rl,0]);
            
            % 成像
%             % 更改背景颜色
%             moving (moving==0) = 255;
%             moving (black_pixels==1)=0;
            % 裁剪至指定维度
            [m,n,~]=size(fixed);
            moving=imcrop(moving,[0,0,n,m]);
            % 图像融合
            blended_image = imadd(alpha*moving,fixed);
            % 显示和保存融合后的图像
            imshow(blended_image,'Parent',app.UIAxes);
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving_cache=moving;']);
            
            assignin('base','black_pixels',black_pixels)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels=black_pixels;']);
            evalin("base",'clear moving black_pixels;');
            app.StretchpictureleftandrightSlider.Value=rl;
        end

        % Value changing function: 
        % StretchpictureleftandrightSlider_2
        function StretchpictureleftandrightSlider_2ValueChanging(app, event)
            ud = event.Value;
            
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            black_pixels=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels;']);
            alpha = evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.alpha;']);
            
            % 平移
            moving = imtranslate(moving,[0,ud]);
            black_pixels = imtranslate(black_pixels,[0,ud]);
            
            % 成像
            % 更改背景颜色
%             moving (moving==0) = 255;
%             moving (black_pixels==1)=0;
            % 裁剪至指定维度
            [m,n,~]=size(fixed);
            moving=imcrop(moving,[0,0,n,m]);
            % 图像融合
            blended_image = imadd(alpha*moving,fixed);
            % 显示和保存融合后的图像
            imshow(blended_image,'Parent',app.UIAxes);
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving_cache=moving;']);
            
            assignin('base','black_pixels',black_pixels)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels=black_pixels;']);
            evalin("base",'clear moving black_pixels;');
            app.Spinner_2.Value=ud;
        end

        % Value changing function: Spinner_2
        function Spinner_2ValueChanging(app, event)
            ud = event.Value;
            
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            black_pixels=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels;']);
            alpha = evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.alpha;']);
            
            % 平移
            moving = imtranslate(moving,[0,ud]);
            black_pixels = imtranslate(black_pixels,[0,ud]);
            
            % 成像
            % 更改背景颜色
%             moving (moving==0) = 255;
%             moving (black_pixels==1)=0;
            % 裁剪至指定维度
            [m,n,~]=size(fixed);
            moving=imcrop(moving,[0,0,n,m]);
            % 图像融合
            blended_image = imadd(alpha*moving,fixed);
            % 显示和保存融合后的图像
            imshow(blended_image,'Parent',app.UIAxes);
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving_cache=moving;']);
            
            assignin('base','black_pixels',black_pixels)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels=black_pixels;']);
            evalin("base",'clear moving black_pixels;');
            app.StretchpictureleftandrightSlider_2.Value=ud;
        end

        % Value changing function: 
        % StretchpictureleftandrightSlider_3
        function StretchpictureleftandrightSlider_3ValueChanging(app, event)
            value = event.Value;
            
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            black_pixels=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels;']);
            alpha = evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.alpha;']);
            
            % 拉伸
            [mm,nn,~]=size(moving);
            recol=nn+value;
            moving = imresize(moving,[mm,recol]);
            black_pixels = imresize(black_pixels,[mm,recol]);
            
            % 成像
            % 更改背景颜色
%             moving (moving==0) = 255;
%             moving (black_pixels==1)=0;
            
            % 裁剪至指定维度
            [m,n,~]=size(fixed);
            % 计算裁剪区域的左上角坐标
            moving=imcrop(moving,[0, 0, n, m]);
            black_pixels =imcrop(black_pixels,[0, 0, n, m]);
            % 调整大小
            [mmm,nnn,~]=size(moving);
            t = uint8(zeros(m,n,3));
            t(1:mmm,1:nnn,:)=t(1:mmm,1:nnn,:)+ moving ;
            t2=t(:,:,1);
            t2(1:mmm,1:nnn,1)=t2(1:mmm,1:nnn,1)+black_pixels;
            
            moving=t;
            black_pixels=t2;
            
            % 图像融合
            blended_image = imadd(alpha*moving,fixed);
            % 显示和保存融合后的图像
            imshow(blended_image,'Parent',app.UIAxes);
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving_cache=moving;']);
            
            assignin('base','black_pixels',black_pixels)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels=black_pixels;']);
            evalin("base",'clear moving black_pixels;');
            app.Spinner_3.Value=value;
        end

        % Value changing function: Spinner_3
        function Spinner_3ValueChanging(app, event)
            value = event.Value;
            
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            black_pixels=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels;']);
            alpha = evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.alpha;']);
            
            % 拉伸
            [mm,nn,~]=size(moving);
            recol=nn+value;
            moving = imresize(moving,[mm,recol]);
            black_pixels = imresize(black_pixels,[mm,recol]);
            
            % 成像
            % 更改背景颜色
%             moving (moving==0) = 255;
%             moving (black_pixels==1)=0;
            
            % 裁剪至指定维度
            [m,n,~]=size(fixed);
            % 计算裁剪区域的左上角坐标
            moving=imcrop(moving,[0, 0, n, m]);
            black_pixels =imcrop(black_pixels,[0, 0, n, m]);
            % 调整大小
            [mmm,nnn,~]=size(moving);
            t = uint8(zeros(m,n,3));
            t(1:mmm,1:nnn,:)=t(1:mmm,1:nnn,:)+ moving ;
            t2=t(:,:,1);
            t2(1:mmm,1:nnn,1)=t2(1:mmm,1:nnn,1)+black_pixels;
            
            moving=t;
            black_pixels=t2;
            
            % 图像融合
            blended_image = imadd(alpha*moving,fixed);
            % 显示和保存融合后的图像
            imshow(blended_image,'Parent',app.UIAxes);
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving_cache=moving;']);
            
            assignin('base','black_pixels',black_pixels)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels=black_pixels;']);
            evalin("base",'clear moving black_pixels;');
            app.StretchpictureleftandrightSlider_3.Value=value;
        end

        % Value changing function: 
        % StretchpictureleftandrightSlider_4
        function StretchpictureleftandrightSlider_4ValueChanging(app, event)
            value = event.Value;
            
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            black_pixels=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels;']);
            alpha = evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.alpha;']);
            
            % 拉伸
            [mm,nn,~]=size(moving);
            rerow=mm+value;
            moving = imresize(moving,[rerow,nn]);
            black_pixels = imresize(black_pixels,[rerow,nn]);
            
            % 成像
            % 更改背景颜色
%             moving (moving==0) = 255;
%             moving (black_pixels==1)=0;
            
            % 裁剪至指定维度
            [m,n,~]=size(fixed);
            % 计算裁剪区域的左上角坐标
            moving=imcrop(moving,[0, 0, n, m]);
            black_pixels =imcrop(black_pixels,[0, 0, n, m]);
             % 调整大小
            [mmm,nnn,~]=size(moving);
            t = uint8(zeros(m,n,3));
            t(1:mmm,1:nnn,:)=t(1:mmm,1:nnn,:)+ moving ;
            t2=t(:,:,1);
            t2(1:mmm,1:nnn,1)=t2(1:mmm,1:nnn,1)+black_pixels;
            
            moving=t;
            black_pixels=t2;
            
            % 图像融合
            blended_image = imadd(alpha*moving,fixed);
            % 显示和保存融合后的图像
            imshow(blended_image,'Parent',app.UIAxes);
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving_cache=moving;']);
            
            assignin('base','black_pixels',black_pixels)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels=black_pixels;']);
            evalin("base",'clear moving black_pixels;');
            app.Spinner_4.Value=value;
        end

        % Value changing function: Spinner_4
        function Spinner_4ValueChanging(app, event)
            value = event.Value;
            
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            black_pixels=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels;']);
            alpha = evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.alpha;']);
            
            % 拉伸
            [mm,nn,~]=size(moving);
            rerow=mm+value;
            moving = imresize(moving,[rerow,nn]);
            black_pixels = imresize(black_pixels,[rerow,nn]);
            
            % 成像
            % 更改背景颜色
%             moving (moving==0) = 255;
%             moving (black_pixels==1)=0;
            
            % 裁剪至指定维度
            [m,n,~]=size(fixed);
            % 计算裁剪区域的左上角坐标
            moving=imcrop(moving,[0, 0, n, m]);
            black_pixels =imcrop(black_pixels,[0, 0, n, m]);
             % 调整大小
            [mmm,nnn,~]=size(moving);
            t = uint8(zeros(m,n,3));
            t(1:mmm,1:nnn,:)=t(1:mmm,1:nnn,:)+ moving ;
            t2=t(:,:,1);
            t2(1:mmm,1:nnn,1)=t2(1:mmm,1:nnn,1)+black_pixels;
            
            moving=t;
            black_pixels=t2;
            
            % 图像融合
            blended_image = imadd(alpha*moving,fixed);
            % 显示和保存融合后的图像
            imshow(blended_image,'Parent',app.UIAxes);
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving_cache=moving;']);
            
            assignin('base','black_pixels',black_pixels)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels=black_pixels;']);
            evalin("base",'clear moving black_pixels;');
            app.StretchpictureleftandrightSlider_4.Value=value;
        end

        % Value changing function: 
        % StretchpictureleftandrightSlider_5
        function StretchpictureleftandrightSlider_5ValueChanging(app, event)
            value = event.Value;
            
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            black_pixels=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels;']);
            alpha = evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.alpha;']);
            
            % 旋转
            moving=imrotate(moving,value,'crop');
            black_pixels =imrotate(black_pixels ,value,'crop');
            
            % 成像
            % 更改背景颜色
%             moving (moving==0) = 255;
%             moving (black_pixels==1)=0;
            % 裁剪至指定维度
            [m,n,~]=size(fixed);
            moving=imcrop(moving,[0,0,n,m]);
            % 图像融合
            blended_image = imadd(alpha*moving,fixed);
            % 显示和保存融合后的图像
            imshow(blended_image,'Parent',app.UIAxes);
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving_cache=moving;']);
            
            assignin('base','black_pixels',black_pixels)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels=black_pixels;']);
            evalin("base",'clear moving black_pixels;');
            app.Spinner_5.Value=value;
        end

        % Value changing function: Spinner_5
        function Spinner_5ValueChanging(app, event)
            value = event.Value;
            
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            black_pixels=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels;']);
            alpha = evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.alpha;']);
            
            % 旋转
            moving=imrotate(moving,value,'crop');
            black_pixels =imrotate(black_pixels ,value,'crop');
            
            % 成像
            % 更改背景颜色
%             moving (moving==0) = 255;
%             moving (black_pixels==1)=0;
            % 裁剪至指定维度
            [m,n,~]=size(fixed);
            moving=imcrop(moving,[0,0,n,m]);
            % 图像融合
            blended_image = imadd(alpha*moving,fixed);
            % 显示和保存融合后的图像
            imshow(blended_image,'Parent',app.UIAxes);
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving_cache=moving;']);
            
            assignin('base','black_pixels',black_pixels)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.black_pixels=black_pixels;']);
            evalin("base",'clear moving black_pixels;');
            app.StretchpictureleftandrightSlider_5.Value=value;
        end

        % Value changing function: 
        % StretchpictureleftandrightSlider_6
        function StretchpictureleftandrightSlider_6ValueChanging(app, event)
            alpha = event.Value;
            
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving;']);
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            % 图像融合
            blended_image = imadd(alpha*moving,fixed);
            % 显示和保存融合后的图像
            imshow(blended_image,'Parent',app.UIAxes);
            
            assignin('base','alpha',alpha)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.alpha=alpha;']);
            evalin("base",'clear alpha;');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            currentFile=evalin('base','importMSv.Registration.currentFile;');
            moving=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving_cache;']);
            fixed=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.fixed;']);
            alpha = evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.alpha;']);
            MSI=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.mascara;']);
            
            blended_image = imadd(alpha*moving,fixed);
            
            app.StretchpictureleftandrightSlider.Value=0;
            app.StretchpictureleftandrightSlider_2.Value=0;
            app.StretchpictureleftandrightSlider_3.Value=0;
            app.StretchpictureleftandrightSlider_4.Value=0;
            app.StretchpictureleftandrightSlider_5.Value=0;
            
            app.Spinner.Value=0;
            app.Spinner_2.Value=0;
            app.Spinner_3.Value=0;
            app.Spinner_4.Value=0;
            app.Spinner_5.Value=0;
            
            finalre=imresize(blended_image,size(MSI),'nearest');
            
            
            assignin('base','blended_image',blended_image)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.blended_image=blended_image;'])
            
            assignin('base','moving',moving)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.moving=moving;']);
            
            assignin('base','finalre',finalre)
            evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.finalre=finalre;'])
            
            evalin("base",'clear moving blended_image finalre;');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StretchpictureleftandrightSlider = uislider(app.UIFigure);
            app.StretchpictureleftandrightSlider.Limits = [-100 100];
            app.StretchpictureleftandrightSlider.ValueChangingFcn = createCallbackFcn(app, @StretchpictureleftandrightSliderValueChanging, true);
            app.StretchpictureleftandrightLabel = uilabel(app.UIFigure);
            app.StretchpictureleftandrightLabel.Text = {'Stretch picture '; 'left and right'};
            app.StretchpictureleftandrightSlider_2 = uislider(app.UIFigure);
            app.StretchpictureleftandrightSlider_2.Limits = [-100 100];
            app.StretchpictureleftandrightSlider_2.ValueChangingFcn = createCallbackFcn(app, @StretchpictureleftandrightSlider_2ValueChanging, true);
            app.StretchpictureupanddownLabel = uilabel(app.UIFigure);
            app.StretchpictureupanddownLabel.Text = {'Stretch picture'; 'up and down'};
            app.StretchpictureleftandrightSlider_3 = uislider(app.UIFigure);
            app.StretchpictureleftandrightSlider_3.Limits = [-100 100];
            app.StretchpictureleftandrightSlider_3.ValueChangingFcn = createCallbackFcn(app, @StretchpictureleftandrightSlider_3ValueChanging, true);
            app.PanningpictureleftandrightLabel = uilabel(app.UIFigure);
            app.PanningpictureleftandrightLabel.Text = {'Panning picture'; 'left and right'};
            app.StretchpictureleftandrightSlider_4 = uislider(app.UIFigure);
            app.StretchpictureleftandrightSlider_4.Limits = [-100 100];
            app.StretchpictureleftandrightSlider_4.ValueChangingFcn = createCallbackFcn(app, @StretchpictureleftandrightSlider_4ValueChanging, true);
            app.PanningpictureupanddownLabel = uilabel(app.UIFigure);
            app.PanningpictureupanddownLabel.Text = {'Panning picture'; 'up and down'};
            app.StretchpictureleftandrightSlider_5 = uislider(app.UIFigure);
            app.StretchpictureleftandrightSlider_5.Limits = [-100 100];
            app.StretchpictureleftandrightSlider_5.ValueChangingFcn = createCallbackFcn(app, @StretchpictureleftandrightSlider_5ValueChanging, true);
            app.RotatepictureLabel = uilabel(app.UIFigure);
            app.RotatepictureLabel.Text = 'Rotate picture';
            app.StretchpictureleftandrightSlider_6 = uislider(app.UIFigure);
            app.StretchpictureleftandrightSlider_6.Limits = [0 5];
            app.StretchpictureleftandrightSlider_6.ValueChangingFcn = createCallbackFcn(app, @StretchpictureleftandrightSlider_6ValueChanging, true);
            app.StretchpictureleftandrightSlider_6.Value = 0.5;
            app.TransparencyLabel = uilabel(app.UIFigure);
            app.TransparencyLabel.Text = 'Transparency';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.ValueChangingFcn = createCallbackFcn(app, @Spinner_2ValueChanging, true);
            app.Spinner_3 = uispinner(app.UIFigure);
            app.Spinner_3.ValueChangingFcn = createCallbackFcn(app, @Spinner_3ValueChanging, true);
            app.Spinner_4 = uispinner(app.UIFigure);
            app.Spinner_4.ValueChangingFcn = createCallbackFcn(app, @Spinner_4ValueChanging, true);
            app.Spinner_5 = uispinner(app.UIFigure);
            app.Spinner_5.ValueChangingFcn = createCallbackFcn(app, @Spinner_5ValueChanging, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = registration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end