classdef MCR_Image < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ListBox                      matlab.ui.control.ListBox
        DataListBoxLabel             matlab.ui.control.Label
        DataListBox                  matlab.ui.control.ListBox
        MultiplotButton              matlab.ui.control.Button
        FlipyaxisCheckBox            matlab.ui.control.CheckBox
        ContourCheckBox              matlab.ui.control.CheckBox
        IntraimagequantitationLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        ImageconcentrationprofileandspectrumLabel  matlab.ui.control.Label
        StrengthrangeDropDownLabel   matlab.ui.control.Label
        StrengthrangeDropDown        matlab.ui.control.DropDown
        EditField                    matlab.ui.control.EditField
        ValueLabel                   matlab.ui.control.Label
        ExportButton                 matlab.ui.control.Button
        ComponentLabel               matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base','mcr_als.aux.flip=0;');
            evalin('base','mcr_als.cache.range_mode=1;');
            evalin('base','mcr_als.cache.strength=0;');
            noFiles=evalin('base','importMSv.load.noFiles;');
            %选择框
            for h=1:noFiles
                name=evalin('base',['importMSv.summary.img_',num2str(h),'.name_short;']);
                llistnnc_d=name;
                llistannc_d(h)={llistnnc_d};
            end
            app.DataListBox.Items = llistannc_d;
            evalin('base','mcr_als.cache.currentFile_d=1;');
            
            
            nc=evalin('base','mcr_als.CompNumb.nc');
            for h=1:nc
                llistnnc_c=['Species nr. ',num2str(h)];
                llistannc_c(h)={llistnnc_c};
            end
            app.ListBox.Items = llistannc_c;
            
            evalin('base','mcr_als.aux.flip=1;');
            evalin('base','mcr_als.aux.typePlot_image=1;');
        end

        % Value changed function: StrengthrangeDropDown
        function StrengthrangeDropDownValueChanged(app, event)
            app.StrengthrangeDropDown.ItemsData = 1:length(app.StrengthrangeDropDown.Items);
            range_mode = app.StrengthrangeDropDown.Value;
            strength=evalin('base','mcr_als.cache.strength;');
            if range_mode==3
                app.EditField.Editable='on';
                app.EditField.Value=num2str(strength);
            elseif range_mode==4
                app.EditField.Editable='on';
                app.EditField.Value=num2str(strength);
            else
                app.EditField.Value='';
                app.EditField.Editable='off';
            end
            assignin('base','range_mode',range_mode);
            evalin('base','mcr_als.cache.range_mode=range_mode;');
            evalin('base','clear range_mode');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            strength=str2double(app.EditField.Value) ;
            assignin('base','strength',strength );
            evalin('base','mcr_als.cache.strength=strength;');
            evalin('base','clear strength');
        end

        % Value changed function: DataListBox
        function DataListBoxValueChanged(app, event)
            app.DataListBox.ItemsData = 1:length(app.DataListBox.Items);
            currentFile=app.DataListBox.Value;
            images_initialization(currentFile)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            app.Label.Text='';
            assignin('base','currentFile_d',currentFile);
            evalin('base','mcr_als.cache.currentFile_d=currentFile_d;');
            evalin('base','clear currentFile_d');
            app.ListBox.Enable='on';
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.ListBox.ItemsData = 1:length(app.ListBox.Items);
            valorpop=app.ListBox.Value;
            cla(app.UIAxes);
            cla(app.UIAxes2);
            app.Label.Text='';
            
            % mdis
            mdis=evalin('base','mcr_als.aux.mdis;');
            original_data=mdis{1,valorpop};
            % 设置显示范围
            range_mode=evalin('base','mcr_als.cache.range_mode;');
            strength=evalin('base','mcr_als.cache.strength;');
            colorll=evalin('base','importMSv.summary.color;');
            data=set_range(original_data,range_mode,strength);
            
            % type of plot
            typePlot=evalin('base','mcr_als.aux.typePlot_image;');
            valorFLIP=evalin('base','mcr_als.aux.flip;');
            
            %copt
            % reshaping conc profiles into maps frommultisets with images equally sized
            if typePlot==1
                if valorFLIP==1
                    imagesc(app.UIAxes,data);
                    axis(app.UIAxes,'xy','tight','image');
                    colormap(app.UIAxes,colorll);
                    colorbar(app.UIAxes);
                else
                    imagesc(app.UIAxes,data);
                    axis (app.UIAxes,'tight','image');
                    colormap(app.UIAxes,colorll);
                    colorbar(app.UIAxes);
                end
            elseif typePlot==2
                if valorFLIP==1
                    contour(app.UIAxes,data,50);
                    axis(app.UIAxes,'xy','tight','image');
                    colormap(app.UIAxes,colorll);
                    colorbar(app.UIAxes);
                else
                    contour(app.UIAxes,data,50);
                    axis (app.UIAxes,'tight','image');
                    colormap(app.UIAxes,colorll);
                    colorbar(app.UIAxes);
                end
            end
            %zoom(app.UIAxes,'on');
            %sopt
            mzroi=evalin('base','importMSv.ROIplotGUI.current_mzroi');
            sopt=evalin('base','mcr_als.alsOptions.resultats.optim_specs;');
            
            [mz,y]=SupplementZero(mzroi,sopt(valorpop,:));
            plot(app.UIAxes2,mz,y);
            axis(app.UIAxes2,[min(mzroi) max(mzroi) min(sopt(valorpop,:))-0.1*min(sopt(valorpop,:)) max(sopt(valorpop,:))+0.1*max(sopt(valorpop,:))]);
            
            quantc=evalin('base','mcr_als.aux.quantc;');
            valorQ=quantc(1,valorpop);
            app.Label.Text=[num2str(valorQ),'%'];
        end

        % Value changed function: FlipyaxisCheckBox
        function FlipyaxisCheckBoxValueChanged(app, event)
            value = app.FlipyaxisCheckBox.Value;
            valorFLIP=value+1;
            assignin('base','valorFLIP',valorFLIP);
            evalin('base','mcr_als.aux.flip=valorFLIP;');
            evalin('base','clear valorFLIP');
        end

        % Value changed function: ContourCheckBox
        function ContourCheckBoxValueChanged(app, event)
            value = app.ContourCheckBox.Value;
            valorlist=value+1;
            assignin('base','typePlot',valorlist);
            evalin('base','mcr_als.aux.typePlot_image=typePlot;');
            evalin('base','clear typePlot');
        end

        % Button pushed function: MultiplotButton
        function MultiplotButtonPushed(app, event)
            closeallhide
            plotcopt
            plotsopt
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            closeallhide
            exportmcr
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Enable = 'off';
            app.ListBox.Value = {};
            app.DataListBoxLabel = uilabel(app.UIFigure);
            app.DataListBoxLabel.Text = 'Data';
            app.DataListBox = uilistbox(app.UIFigure);
            app.DataListBox.Items = {};
            app.DataListBox.ValueChangedFcn = createCallbackFcn(app, @DataListBoxValueChanged, true);
            app.DataListBox.Value = {};
            app.MultiplotButton = uibutton(app.UIFigure, 'push');
            app.MultiplotButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplotButtonPushed, true);
            app.MultiplotButton.Text = 'Multiplot';
            app.FlipyaxisCheckBox = uicheckbox(app.UIFigure);
            app.FlipyaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlipyaxisCheckBoxValueChanged, true);
            app.FlipyaxisCheckBox.Text = 'Flip y-axis';
            app.ContourCheckBox = uicheckbox(app.UIFigure);
            app.ContourCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContourCheckBoxValueChanged, true);
            app.ContourCheckBox.Text = 'Contour？';
            app.IntraimagequantitationLabel = uilabel(app.UIFigure);
            app.IntraimagequantitationLabel.Text = 'Intraimage quantitation:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ImageconcentrationprofileandspectrumLabel = uilabel(app.UIFigure);
            app.ImageconcentrationprofileandspectrumLabel.Text = 'Image concentration profile and spectrum';
            app.StrengthrangeDropDownLabel = uilabel(app.UIFigure);
            app.StrengthrangeDropDownLabel.Text = 'Strength range';
            app.StrengthrangeDropDown = uidropdown(app.UIFigure);
            app.StrengthrangeDropDown.Items = {'default:Median', 'Max', 'Manual', 'quantile'};
            app.StrengthrangeDropDown.ValueChangedFcn = createCallbackFcn(app, @StrengthrangeDropDownValueChanged, true);
            app.StrengthrangeDropDown.Value = 'default:Median';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Editable = 'off';
            app.ValueLabel = uilabel(app.UIFigure);
            app.ValueLabel.Text = 'Value:';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ComponentLabel = uilabel(app.UIFigure);
            app.ComponentLabel.Text = 'Component';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Concentration distribution')
            app.UIAxes2 = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MCR_Image

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end