classdef MCR_bands < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MCRBANDSresultsLabel           matlab.ui.control.Label
        SpeciestobeconsideredDropDownLabel  matlab.ui.control.Label
        SpeciestobeconsideredDropDown  matlab.ui.control.DropDown
        FvalueforthespeciesintheinitialsolutionLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        FvaluefortheoptimizationLabel  matlab.ui.control.Label
        MaximumLabel                   matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        MinimumLabel                   matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        MultiplotButton                matlab.ui.control.Button
        PlotofCbandsprofilesLabel      matlab.ui.control.Label
        PlotofSbandsprofilesLabel      matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dim=evalin('base','mcr_bands.Results.nsign');
            for i=1:dim
                llistnnc=['Species nr. ',num2str(i)];
                llistannc(i)={llistnnc};
            end
            app.SpeciestobeconsideredDropDown.Items=llistannc;
            app.SpeciestobeconsideredDropDown.ItemsData = 1:length(app.SpeciestobeconsideredDropDown.Items);
            
            % 初始化
            app.Label.Text='';
            app.Label_2.Text='';
            app.Label_3.Text='';
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            % 数字
            finic=evalin('base','mcr_bands.Results.finic');
            fband=evalin('base','mcr_bands.Results.fband');
            fvalue=num2str(finic(1,1),4);  % F value
            app.Label.Text=fvalue;
            fmax=num2str(fband(1,1),4);    % F max
            app.Label_2.Text=fmax;
            fmine=num2str(fband(2,1),4);   % F min
            app.Label_3.Text=fmine;
            % 画图
            sband=evalin('base','mcr_bands.Results.sband;');
            cband=evalin('base','mcr_bands.Results.cband;');
            c=evalin('base','mcr_bands.Data.conc;');
            s=evalin('base','mcr_bands.Data.spec;');
            % copt
            plot(app.UIAxes,cband(:,1));
            figure('visible','off');
            hold(app.UIAxes,'on');
            plot(app.UIAxes,cband(:,2));
            plot(app.UIAxes,c(:,1),'r:')
            zoom(app.UIAxes,'on');
            hold(app.UIAxes,'off');
            % sopt
            plot(app.UIAxes2,sband(1,:)');
            figure('visible','off');
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,sband(2,:)');
            plot(app.UIAxes2,s(1,:),'r:')
            zoom(app.UIAxes2,'on');
            hold(app.UIAxes2,'off');
        end

        % Value changed function: SpeciestobeconsideredDropDown
        function SpeciestobeconsideredDropDownValueChanged(app, event)
            species = app.SpeciestobeconsideredDropDown.Value;
            % 初始化
            app.Label.Text='';
            app.Label_2.Text='';
            app.Label_3.Text='';
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            
            % 数字
            finic=evalin('base','mcr_bands.Results.finic');
            fband=evalin('base','mcr_bands.Results.fband');
            
            fvalue=num2str(finic(1,species),4);  % F value
            app.Label.Text=fvalue;
            fmax=num2str(fband(1,species),4);    % F max
            app.Label_2.Text=fmax;
            fmine=num2str(fband(2,species),4);   % F min
            app.Label_3.Text=fmine;
            
            % 画图
            sband=evalin('base','mcr_bands.Results.sband;');
            cband=evalin('base','mcr_bands.Results.cband;');
            c=evalin('base','mcr_bands.Data.conc;');
            s=evalin('base','mcr_bands.Data.spec;');
            
            max=2*species-1;
            min=2*species;
            
            % copt
            plot(app.UIAxes,cband(:,max));
            figure('visible','off');
            hold(app.UIAxes,'on');
            plot(app.UIAxes,cband(:,min));
            plot(app.UIAxes,c(:,species),'r:')
            zoom(app.UIAxes,'on');
            hold(app.UIAxes,'off');
            % sopt
            plot(app.UIAxes2,sband(max,:)');
            figure('visible','off');
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,sband(min,:)');
            plot(app.UIAxes2,s(species,:),'r:')
            zoom(app.UIAxes2,'on');
            hold(app.UIAxes2,'off');
        end

        % Button pushed function: MultiplotButton
        function MultiplotButtonPushed(app, event)
            sband=evalin('base','mcr_bands.Results.sband;');
            cband=evalin('base','mcr_bands.Results.cband;');
            nsign=evalin('base','mcr_bands.Results.nsign;');
            c=evalin('base','mcr_bands.Data.conc;');
            s=evalin('base','mcr_bands.Data.spec;');
            
            % 画图
            figure
            for ioptim=1:nsign
                max=2*ioptim-1;
                min=2*ioptim;
                %******
                subplot(nsign,2,2*ioptim-1),plot(cband(:,max));
                hold on
                subplot(nsign,2,2*ioptim-1),plot(cband(:,min));
                subplot(nsign,2,2*ioptim-1),plot(c(:,ioptim),'r:')
                title(['Plot of C bands profiles for species: ',num2str(ioptim)]);
                hold off
                
                subplot(nsign,2,2*ioptim),plot(sband(max,:)');
                hold on
                subplot(nsign,2,2*ioptim),plot(sband(min,:)');
                subplot(nsign,2,2*ioptim),plot(s(ioptim,:),'r:')
                title(['Plot of S bands profiles for species: ',num2str(ioptim)]);
                hold off
            end
            %列表
            finic=evalin('base','mcr_bands.Results.finic');
            fband=evalin('base','mcr_bands.Results.fband');
            
            vars={'Species','F_value','Maximum','Minimum'};
            data=[(1:nsign)',finic',fband'];
            
            f=figure('Position',[200,200,500,500]);
            t=uitable('Parent',f,'Position',[10,10,490,450],'Data',data);
            set(t,'ColumnWidth',{50,120,120,120});
            set(t,'ColumnName',vars);
            set(t,'FontSize',16);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MCRBANDSresultsLabel = uilabel(app.UIFigure);
            app.MCRBANDSresultsLabel.Text = 'MCRBANDS results';
            app.SpeciestobeconsideredDropDownLabel = uilabel(app.UIFigure);
            app.SpeciestobeconsideredDropDownLabel.Text = 'Species to be considered';
            app.SpeciestobeconsideredDropDown = uidropdown(app.UIFigure);
            app.SpeciestobeconsideredDropDown.Items = {};
            app.SpeciestobeconsideredDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeciestobeconsideredDropDownValueChanged, true);
            app.SpeciestobeconsideredDropDown.Value = {};
            app.FvalueforthespeciesintheinitialsolutionLabel = uilabel(app.UIFigure);
            app.FvalueforthespeciesintheinitialsolutionLabel.Text = 'F value for the  species in the initial solution';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FvaluefortheoptimizationLabel = uilabel(app.UIFigure);
            app.FvaluefortheoptimizationLabel.Text = 'F value for the optimization:';
            app.MaximumLabel = uilabel(app.UIFigure);
            app.MaximumLabel.Text = 'Maximum:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.MinimumLabel = uilabel(app.UIFigure);
            app.MinimumLabel.Text = 'Minimum:';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.MultiplotButton = uibutton(app.UIFigure, 'push');
            app.MultiplotButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplotButtonPushed, true);
            app.MultiplotButton.Text = 'Multiplot';
            app.PlotofCbandsprofilesLabel = uilabel(app.UIFigure);
            app.PlotofCbandsprofilesLabel.Text = 'Plot of C bands profiles';
            app.PlotofSbandsprofilesLabel = uilabel(app.UIFigure);
            app.PlotofSbandsprofilesLabel.Text = 'Plot of S bands profiles';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MCR_bands

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end