classdef MCR_result < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ImageButton      matlab.ui.control.Button
        MCRBandsButton   matlab.ui.control.Button
        PlotsareoptimumintheiterationNrLabel  matlab.ui.control.Label
        iteration_Nr     matlab.ui.control.Label
        StddevofresidualsvsexpdataLabel  matlab.ui.control.Label
        Std              matlab.ui.control.Label
        FittingerrorlackoffitlofinPCALabel  matlab.ui.control.Label
        PCA              matlab.ui.control.Label
        FittingerrorlackoffitlofinexpLabel  matlab.ui.control.Label
        EXP              matlab.ui.control.Label
        PercentofvarianceexplainedrattheoptimumisLabel  matlab.ui.control.Label
        r                matlab.ui.control.Label
        MCR_resultLabel  matlab.ui.control.Label
        Tips             matlab.ui.control.Label
        DRebuildButton   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            noFiles=evalin('base','importMSv.load.noFiles');
            if noFiles>1
                app.DRebuildButton.Enable='on';
            end
            
            als_end=evalin('base','mcr_als.cache.als_end');
            if als_end == 0
                
                %导入数据
                abss=evalin('base','mcr_als.cache.sx_plot');
                niter=evalin('base','mcr_als.cache.niter_plot');
                sstd=evalin('base','mcr_als.cache.sstd_plot');
                change=evalin('base','mcr_als.cache.change_plot');
                
                %画sopt
                plotsopt(abss);
                
                % 文字部分
                if change < 0.0
                    app.Tips.Text='FITTING IS NOT IMPROVING';
                else
                    app.Tips.Text='FITTING IS IMPROVING';
                end
                
                app.iteration_Nr.Text=num2str(niter);
                app.Std.Text=num2str(change);
                app.PCA.Text= num2str(sstd(1));
                app.EXP.Text=num2str(sstd(2));
                
                pause(.3);
                
            elseif als_end == 1
                
                %导入数据
                sopt_xxx=evalin('base','mcr_als.cache.sopt_xxx');
                itopt_xxx=evalin('base','mcr_als.cache.itopt_xxx');
                sdopt_xxx=evalin('base','mcr_als.cache.sdopt_xxx');
                r2opt_xxx=evalin('base','mcr_als.cache.r2opt_xxx');
                change_xxx=evalin('base','mcr_als.cache.change_xxx');
                
                % 画sopt
                plotsopt(sopt_xxx);
                
                %文字部分
                app.Tips.Text='CONVERGENCE IS ACHIEVED';
                app.iteration_Nr.Text=num2str(itopt_xxx);
                app.Std.Text=num2str(change_xxx);
                app.PCA.Text=num2str(sdopt_xxx(1,1));
                app.EXP.Text=num2str(sdopt_xxx(1,2));
                app.r.Text=num2str(100*r2opt_xxx);
                
            elseif als_end==2
                
                %导入数据
                sopt_xxx=evalin('base','mcr_als.cache.sopt_xxx');
                itopt_xxx=evalin('base','mcr_als.cache.itopt_xxx');
                sdopt_xxx=evalin('base','mcr_als.cache.sdopt_xxx');
                r2opt_xxx=evalin('base','mcr_als.cache.r2opt_xxx');
                change_xxx=evalin('base','mcr_als.cache.change_xxx');
                
               % 画sopt
                plotsopt(sopt_xxx);
                
                %文字部分
                app.Tips.Text='FIT NOT IMPROVING FOR 20 TIMES CONSECUTIVELY (DIVERGENCE?), STOP';
                app.iteration_Nr.Text=num2str(itopt_xxx);
                app.r.Text=num2str(100*r2opt_xxx);
                app.PCA.Text=num2str(sdopt_xxx(1,1));
                app.EXP.Text=num2str(sdopt_xxx(1,2));
                app.Std.Text=num2str(change_xxx);
                
            elseif als_end==3
                
                % 导入数据
                sopt_xxx=evalin('base','mcr_als.cache.sopt_xxx');
                itopt_xxx=evalin('base','mcr_als.cache.itopt_xxx');
                sdopt_xxx=evalin('base','mcr_als.cache.sdopt_xxx');
                r2opt_xxx=evalin('base','mcr_als.cache.r2opt_xxx');
                change_xxx=evalin('base','mcr_als.cache.change_xxx');
                
                %画sopt
                plotsopt(sopt_xxx);
                
                %文字部分
                app.Tips.Text='NUMBER OF ITERATIONS EXCEEDED THE ALLOWED!!!';
                app.iteration_Nr.Text=num2str(itopt_xxx);
                app.r.Text=num2str(100*r2opt_xxx);
                app.PCA.Text=num2str(sdopt_xxx(1,1));
                app.EXP.Text=num2str(sdopt_xxx(1,2));
                app.Std.Text=num2str(change_xxx);
            end
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
           MCR_Image;
        end

        % Button pushed function: MCRBandsButton
        function MCRBandsButtonPushed(app, event)
            Bands;
        end

        % Button pushed function: DRebuildButton
        function DRebuildButtonPushed(app, event)
            Rebuild3D
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageButton = uibutton(app.UIFigure, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.MCRBandsButton = uibutton(app.UIFigure, 'push');
            app.MCRBandsButton.ButtonPushedFcn = createCallbackFcn(app, @MCRBandsButtonPushed, true);
            app.MCRBandsButton.Text = 'MCR Bands';
            app.PlotsareoptimumintheiterationNrLabel = uilabel(app.UIFigure);
            app.PlotsareoptimumintheiterationNrLabel.Text = 'Plots are optimum in the iteration Nr. ';
            app.iteration_Nr = uilabel(app.UIFigure);
            app.iteration_Nr.Text = '';
            app.StddevofresidualsvsexpdataLabel = uilabel(app.UIFigure);
            app.StddevofresidualsvsexpdataLabel.Text = 'Std. dev of residuals vs. exp. data =';
            app.Std = uilabel(app.UIFigure);
            app.Std.Text = '';
            app.FittingerrorlackoffitlofinPCALabel = uilabel(app.UIFigure);
            app.FittingerrorlackoffitlofinPCALabel.Text = 'Fitting error (lack of fit, lof) in % (PCA) =';
            app.PCA = uilabel(app.UIFigure);
            app.PCA.Text = '';
            app.FittingerrorlackoffitlofinexpLabel = uilabel(app.UIFigure);
            app.FittingerrorlackoffitlofinexpLabel.Text = 'Fitting error (lack of fit, lof) in % (exp) =';
            app.EXP = uilabel(app.UIFigure);
            app.EXP.Text = '';
            app.PercentofvarianceexplainedrattheoptimumisLabel = uilabel(app.UIFigure);
            app.PercentofvarianceexplainedrattheoptimumisLabel.Text = 'Percent of variance explained ( r²) at the optimum is = ';
            app.r = uilabel(app.UIFigure);
            app.r.Text = '';
            app.MCR_resultLabel = uilabel(app.UIFigure);
            app.MCR_resultLabel.Text = 'MCR_result';
            app.Tips = uilabel(app.UIFigure);
            app.Tips.Text = '';
            app.DRebuildButton = uibutton(app.UIFigure, 'push');
            app.DRebuildButton.ButtonPushedFcn = createCallbackFcn(app, @DRebuildButtonPushed, true);
            app.DRebuildButton.Enable = 'off';
            app.DRebuildButton.Text = '3D Rebuild';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MCR_result

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end