classdef Rebuild3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DreconstructionLabel          matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        IsosurfaceTab                 matlab.ui.container.Tab
        pelluciditySliderLabel        matlab.ui.control.Label
        pelluciditySlider             matlab.ui.control.Slider
        isovalueSliderLabel           matlab.ui.control.Label
        isovalueSlider                matlab.ui.control.Slider
        UIAxes                        matlab.ui.control.UIAxes
        SliceTab                      matlab.ui.container.Tab
        EnterButton_2                 matlab.ui.control.Button
        XaxisdirectionintervalEditFieldLabel  matlab.ui.control.Label
        XaxisdirectionintervalEditField  matlab.ui.control.EditField
        ZaxisdirectionintervalEditFieldLabel  matlab.ui.control.Label
        ZaxisdirectionintervalEditField  matlab.ui.control.EditField
        YaxisdirectionintervalEditFieldLabel  matlab.ui.control.Label
        YaxisdirectionintervalEditField  matlab.ui.control.EditField
        StrengthrangeDropDownLabel    matlab.ui.control.Label
        StrengthrangeDropDown         matlab.ui.control.DropDown
        ValueEditFieldLabel           matlab.ui.control.Label
        ValueEditField                matlab.ui.control.EditField
        UIAxes2                       matlab.ui.control.UIAxes
        SinglesliceTab                matlab.ui.container.Tab
        XEnterButton                  matlab.ui.control.Button
        YEnterButton                  matlab.ui.control.Button
        ZEnterButton                  matlab.ui.control.Button
        XaxisdirectionLabel           matlab.ui.control.Label
        YaxisdirectionLabel           matlab.ui.control.Label
        ZaxisdirectionLabel           matlab.ui.control.Label
        XslicepositionEditFieldLabel  matlab.ui.control.Label
        XslicepositionEditField       matlab.ui.control.EditField
        YslicepositionEditField       matlab.ui.control.EditField
        YslicepositionEditFieldLabel  matlab.ui.control.Label
        ZslicepositionEditField       matlab.ui.control.EditField
        ZslicepositionEditFieldLabel  matlab.ui.control.Label
        StrengthrangeDropDown_2Label  matlab.ui.control.Label
        StrengthrangeDropDown_2       matlab.ui.control.DropDown
        ValueEditField_2Label         matlab.ui.control.Label
        ValueEditField_2              matlab.ui.control.EditField
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        EnterButton                   matlab.ui.control.Button
        ComponentNOEditFieldLabel     matlab.ui.control.Label
        ComponentNOEditField          matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base','importMSv.Rebuild3D.allsrange_mode=1;');
            evalin('base','importMSv.Rebuild3D.allsstrength=0;');
            evalin('base','importMSv.Rebuild3D.ssrange_mode=1;');
            evalin('base','importMSv.Rebuild3D.ssstrength=0;');
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            cla(app.UIAxes,"reset");
            view(app.UIAxes,3);
            
            n=str2double(app.ComponentNOEditField.Value);
            [photo,photox,photoy,nslice]=extractdata(n);
            [X,Y,Z]=meshgrid((1:photoy),(1:photox),(1:nslice));
            
            maxz=max(photo(:));
            minz=min(photo(:));
            app.isovalueSlider.Limits=[minz maxz];
            
            assignin('base','photo',photo);
            evalin('base','importMSv.Rebuild3D.photo=photo;');
            evalin('base','importMSv.Rebuild3D.to=0.5;');
            assignin('base','isovalue',minz);
            evalin('base','importMSv.Rebuild3D.isovalue=isovalue;');
            assignin('base','X',X);
            evalin('base','importMSv.Rebuild3D.X=X;');
            assignin('base','Y',Y);
            evalin('base','importMSv.Rebuild3D.Y=Y;');
            assignin('base','Z',Z);
            evalin('base','importMSv.Rebuild3D.Z=Z;');
            evalin("base",'clear isovalue X Y Z photo;');
        end

        % Value changing function: pelluciditySlider
        function pelluciditySliderValueChanging(app, event)
            to = event.Value;
            assignin('base','to',to);
            evalin('base','importMSv.Rebuild3D.to=to;');
            evalin("base",'clear to;');
            
            cla(app.UIAxes,"reset");
            view(app.UIAxes,3);
            
            colorll=evalin('base','importMSv.summary.color;');
            photo=evalin('base','importMSv.Rebuild3D.photo');
            X=evalin('base','importMSv.Rebuild3D.X;');
            Y=evalin('base','importMSv.Rebuild3D.Y;');
            Z=evalin('base','importMSv.Rebuild3D.Z;');
            isovalue=evalin('base','importMSv.Rebuild3D.isovalue');
            
            [faces,verts,colors] = isosurface(X,Y,Z,photo,isovalue,photo);
            patch(app.UIAxes,'Vertices',verts,'Faces',faces,'FaceVertexCData',colors,'FaceColor','interp','EdgeColor','none','FaceAlpha',to);
            patch(app.UIAxes,isocaps(X,Y,Z,photo,isovalue),'FaceColor', 'interp', 'EdgeColor', 'none','FaceAlpha',to);
            
            colormap(app.UIAxes,colorll);colorbar(app.UIAxes);axis (app.UIAxes,'tight');box (app.UIAxes,'on');grid (app.UIAxes,'on');
            daspect (app.UIAxes,'auto');camlight(app.UIAxes);lighting (app.UIAxes,'phong');camproj (app.UIAxes,'perspective');
        end

        % Value changing function: isovalueSlider
        function isovalueSliderValueChanging(app, event)
            isovalue = event.Value;
            assignin('base','isovalue',isovalue);
            evalin('base','importMSv.Rebuild3D.isovalue=isovalue;');
            evalin("base",'clear isovalue;');
            
            cla(app.UIAxes,"reset");
            view(app.UIAxes,3);
            
            colorll=evalin('base','importMSv.summary.color;');
            photo=evalin('base','importMSv.Rebuild3D.photo');
            X=evalin('base','importMSv.Rebuild3D.X;');
            Y=evalin('base','importMSv.Rebuild3D.Y;');
            Z=evalin('base','importMSv.Rebuild3D.Z;');
            to=evalin('base','importMSv.Rebuild3D.to');
            
            [faces,verts,colors] = isosurface(X,Y,Z,photo,isovalue,photo);
            patch(app.UIAxes,'Vertices',verts,'Faces',faces,'FaceVertexCData',colors,'FaceColor','interp','EdgeColor','none','FaceAlpha',to);
            patch(app.UIAxes,isocaps(X,Y,Z,photo,isovalue),'FaceColor', 'interp', 'EdgeColor', 'none','FaceAlpha',to);
            
            colormap(app.UIAxes,colorll);colorbar(app.UIAxes);axis (app.UIAxes,'tight');box (app.UIAxes,'on') ;grid (app.UIAxes,'on');
            daspect (app.UIAxes,'auto');camlight(app.UIAxes);lighting (app.UIAxes,'phong');camproj (app.UIAxes,'perspective');
        end

        % Value changed function: StrengthrangeDropDown
        function StrengthrangeDropDownValueChanged(app, event)
            app.StrengthrangeDropDown.ItemsData = 1:length(app.StrengthrangeDropDown.Items);
            range_mode = app.StrengthrangeDropDown.Value;
            strength=evalin('base','importMSv.Rebuild3D.allsstrength;');
            if range_mode==3
                app.ValueEditField.Editable='on';
                app.ValueEditField.Value=num2str(strength);
            elseif range_mode==4
                app.ValueEditField.Editable='on';
                app.ValueEditField.Value=num2str(strength);
            else
                app.ValueEditField.Value='';
                app.ValueEditField.Editable='off';
            end
            assignin('base','range_mode',range_mode);
            evalin('base','importMSv.Rebuild3D.allsrange_mode=range_mode;');
            evalin('base','clear range_mode');
        end

        % Value changed function: ValueEditField
        function ValueEditFieldValueChanged(app, event)
            strength=str2double(app.ValueEditField.Value) ;
            assignin('base','strength',strength );
            evalin('base','importMSv.Rebuild3D.allsstrength=strength;');
            evalin('base','clear strength');
        end

        % Button pushed function: EnterButton_2
        function EnterButton_2Pushed(app, event)
            photo=evalin('base','importMSv.Rebuild3D.photo');
            X=evalin('base','importMSv.Rebuild3D.X;');
            Y=evalin('base','importMSv.Rebuild3D.Y;');
            Z=evalin('base','importMSv.Rebuild3D.Z;');
            colorll=evalin('base','importMSv.summary.color;');
            
            [x,y,z]=size(photo);
            
            xj=str2double(app.XaxisdirectionintervalEditField.Value);
            yj=str2double(app.YaxisdirectionintervalEditField.Value);
            zj=str2double(app.ZaxisdirectionintervalEditField.Value);
            
            range_mode=evalin('base','importMSv.Rebuild3D.allsrange_mode;');
            strength=evalin('base','importMSv.Rebuild3D.allsstrength;');
            photo=set_range(photo,range_mode,strength);
            
            slice(app.UIAxes2,X,Y,Z,photo,[0:yj:y],[0:xj:x],[0:zj:z])
            % 设置颜色底纹属性
            colormap(app.UIAxes2,colorll);colorbar(app.UIAxes2);axis (app.UIAxes2,'tight');box (app.UIAxes2,'on') ;grid (app.UIAxes2,'on');
            shading (app.UIAxes2,'interp');
        end

        % Value changed function: StrengthrangeDropDown_2
        function StrengthrangeDropDown_2ValueChanged(app, event)
            app.StrengthrangeDropDown_2.ItemsData = 1:length(app.StrengthrangeDropDown_2.Items);
            range_mode = app.StrengthrangeDropDown_2.Value;
            strength=evalin('base','importMSv.Rebuild3D.ssstrength;');
            if range_mode==3
                app.ValueEditField_2.Editable='on';
                app.ValueEditField_2.Value=num2str(strength);
            elseif range_mode==4
                app.ValueEditField_2.Editable='on';
                app.ValueEditField_2.Value=num2str(strength);
            else
                app.ValueEditField_2.Value='';
                app.ValueEditField_2.Editable='off';
            end
            assignin('base','range_mode',range_mode);
            evalin('base','importMSv.Rebuild3D.ssrange_mode=range_mode;');
            evalin('base','clear range_mode');
        end

        % Value changed function: ValueEditField_2
        function ValueEditField_2ValueChanged(app, event)
            strength=str2double(app.ValueEditField_2.Value) ;
            assignin('base','strength',strength );
            evalin('base','importMSv.Rebuild3D.ssstrength=strength;');
            evalin('base','clear strength');
        end

        % Button pushed function: XEnterButton
        function XEnterButtonPushed(app, event)
            cla(app.UIAxes3,"reset");
            view(app.UIAxes3,3);
            
            colorll=evalin('base','importMSv.summary.color;');
            photo=evalin('base','importMSv.Rebuild3D.photo');
            X=evalin('base','importMSv.Rebuild3D.X;');
            Y=evalin('base','importMSv.Rebuild3D.Y;');
            Z=evalin('base','importMSv.Rebuild3D.Z;');
            
            range_mode=evalin('base','importMSv.Rebuild3D.ssrange_mode;');
            strength=evalin('base','importMSv.Rebuild3D.ssstrength;');
            photo=set_range(photo,range_mode,strength);
            
            Xslice=str2double(app.XslicepositionEditField.Value);
            slice(app.UIAxes3,X,Y,Z,photo,[],[Xslice],[]);
            colormap(app.UIAxes3,colorll);colorbar(app.UIAxes3);shading(app.UIAxes3,'interp');
            set(app.UIAxes3,'zdir','reverse');axis(app.UIAxes3,'equal');grid(app.UIAxes3,'on');
        end

        % Button pushed function: YEnterButton
        function YEnterButtonPushed(app, event)
            cla(app.UIAxes4,"reset");
            view(app.UIAxes4,3);
            
            colorll=evalin('base','importMSv.summary.color;');
            photo=evalin('base','importMSv.Rebuild3D.photo');
            X=evalin('base','importMSv.Rebuild3D.X;');
            Y=evalin('base','importMSv.Rebuild3D.Y;');
            Z=evalin('base','importMSv.Rebuild3D.Z;');
            
            range_mode=evalin('base','importMSv.Rebuild3D.ssrange_mode;');
            strength=evalin('base','importMSv.Rebuild3D.ssstrength;');
            photo=set_range(photo,range_mode,strength);
            
            Yslice=str2double(app.YslicepositionEditField.Value);
            slice(app.UIAxes4,X,Y,Z,photo,[Yslice],[],[]);
            colormap(app.UIAxes4,colorll);colorbar(app.UIAxes4);shading(app.UIAxes4,'interp');
            set(app.UIAxes4,'zdir','reverse');axis(app.UIAxes4,'equal');grid(app.UIAxes4,'on');
        end

        % Button pushed function: ZEnterButton
        function ZEnterButtonPushed(app, event)
            cla(app.UIAxes5,"reset");
            view(app.UIAxes5,3);
            
            colorll=evalin('base','importMSv.summary.color;');
            photo=evalin('base','importMSv.Rebuild3D.photo');
            X=evalin('base','importMSv.Rebuild3D.X;');
            Y=evalin('base','importMSv.Rebuild3D.Y;');
            Z=evalin('base','importMSv.Rebuild3D.Z;');
            
            range_mode=evalin('base','importMSv.Rebuild3D.ssrange_mode;');
            strength=evalin('base','importMSv.Rebuild3D.ssstrength;');
            photo=set_range(photo,range_mode,strength);
            
            Zslice=str2double(app.ZslicepositionEditField.Value);
            slice(app.UIAxes5,X,Y,Z,photo,[],[],[Zslice]);
            colormap(app.UIAxes5,colorll);colorbar(app.UIAxes5);shading(app.UIAxes5,'interp');
            set(app.UIAxes5,'zdir','reverse');axis(app.UIAxes5,'equal');grid(app.UIAxes5,'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DreconstructionLabel = uilabel(app.UIFigure);
            app.DreconstructionLabel.Text = '3D reconstruction';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IsosurfaceTab = uitab(app.TabGroup);
            app.IsosurfaceTab.Title = 'Isosurface';
            app.pelluciditySliderLabel = uilabel(app.IsosurfaceTab);
            app.pelluciditySliderLabel.Text = 'pellucidity';
            app.pelluciditySlider = uislider(app.IsosurfaceTab);
            app.pelluciditySlider.Limits = [0 1];
            app.pelluciditySlider.ValueChangingFcn = createCallbackFcn(app, @pelluciditySliderValueChanging, true);
            app.isovalueSliderLabel = uilabel(app.IsosurfaceTab);
            app.isovalueSliderLabel.Text = 'isovalue';
            app.isovalueSlider = uislider(app.IsosurfaceTab);
            app.isovalueSlider.ValueChangingFcn = createCallbackFcn(app, @isovalueSliderValueChanging, true);
            app.UIAxes = uiaxes(app.IsosurfaceTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.SliceTab = uitab(app.TabGroup);
            app.SliceTab.Title = 'Slice';
            app.EnterButton_2 = uibutton(app.SliceTab, 'push');
            app.EnterButton_2.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_2Pushed, true);
            app.EnterButton_2.Text = 'Enter';
            app.XaxisdirectionintervalEditFieldLabel = uilabel(app.SliceTab);
            app.XaxisdirectionintervalEditFieldLabel.Text = 'X-axis direction interval';
            app.XaxisdirectionintervalEditField = uieditfield(app.SliceTab, 'text');
            app.ZaxisdirectionintervalEditFieldLabel = uilabel(app.SliceTab);
            app.ZaxisdirectionintervalEditFieldLabel.Text = 'Z-axis direction interval';
            app.ZaxisdirectionintervalEditField = uieditfield(app.SliceTab, 'text');
            app.YaxisdirectionintervalEditFieldLabel = uilabel(app.SliceTab);
            app.YaxisdirectionintervalEditFieldLabel.Text = 'Y-axis direction interval';
            app.YaxisdirectionintervalEditField = uieditfield(app.SliceTab, 'text');
            app.StrengthrangeDropDownLabel = uilabel(app.SliceTab);
            app.StrengthrangeDropDownLabel.Text = 'Strength range';
            app.StrengthrangeDropDown = uidropdown(app.SliceTab);
            app.StrengthrangeDropDown.Items = {'default:Median', 'Max', 'Manual', 'quantile'};
            app.StrengthrangeDropDown.ValueChangedFcn = createCallbackFcn(app, @StrengthrangeDropDownValueChanged, true);
            app.StrengthrangeDropDown.Value = 'default:Median';
            app.ValueEditFieldLabel = uilabel(app.SliceTab);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.SliceTab, 'text');
            app.ValueEditField.ValueChangedFcn = createCallbackFcn(app, @ValueEditFieldValueChanged, true);
            app.ValueEditField.Editable = 'off';
            app.UIAxes2 = uiaxes(app.SliceTab);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.SinglesliceTab = uitab(app.TabGroup);
            app.SinglesliceTab.Title = 'Single slice';
            app.XEnterButton = uibutton(app.SinglesliceTab, 'push');
            app.XEnterButton.ButtonPushedFcn = createCallbackFcn(app, @XEnterButtonPushed, true);
            app.XEnterButton.Text = 'X-Enter';
            app.YEnterButton = uibutton(app.SinglesliceTab, 'push');
            app.YEnterButton.ButtonPushedFcn = createCallbackFcn(app, @YEnterButtonPushed, true);
            app.YEnterButton.Text = 'Y-Enter';
            app.ZEnterButton = uibutton(app.SinglesliceTab, 'push');
            app.ZEnterButton.ButtonPushedFcn = createCallbackFcn(app, @ZEnterButtonPushed, true);
            app.ZEnterButton.Text = 'Z-Enter';
            app.XaxisdirectionLabel = uilabel(app.SinglesliceTab);
            app.XaxisdirectionLabel.Text = 'X-axis direction';
            app.YaxisdirectionLabel = uilabel(app.SinglesliceTab);
            app.YaxisdirectionLabel.Text = 'Y-axis direction';
            app.ZaxisdirectionLabel = uilabel(app.SinglesliceTab);
            app.ZaxisdirectionLabel.Text = 'Z-axis direction';
            app.XslicepositionEditFieldLabel = uilabel(app.SinglesliceTab);
            app.XslicepositionEditFieldLabel.Text = 'X slice position';
            app.XslicepositionEditField = uieditfield(app.SinglesliceTab, 'text');
            app.YslicepositionEditField = uieditfield(app.SinglesliceTab, 'text');
            app.YslicepositionEditFieldLabel = uilabel(app.SinglesliceTab);
            app.YslicepositionEditFieldLabel.Text = 'Y slice position';
            app.ZslicepositionEditField = uieditfield(app.SinglesliceTab, 'text');
            app.ZslicepositionEditFieldLabel = uilabel(app.SinglesliceTab);
            app.ZslicepositionEditFieldLabel.Text = 'Z slice position';
            app.StrengthrangeDropDown_2Label = uilabel(app.SinglesliceTab);
            app.StrengthrangeDropDown_2Label.Text = 'Strength range';
            app.StrengthrangeDropDown_2 = uidropdown(app.SinglesliceTab);
            app.StrengthrangeDropDown_2.Items = {'default:Median', 'Max', 'Manual', 'quantile'};
            app.StrengthrangeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @StrengthrangeDropDown_2ValueChanged, true);
            app.StrengthrangeDropDown_2.Value = 'default:Median';
            app.ValueEditField_2Label = uilabel(app.SinglesliceTab);
            app.ValueEditField_2Label.Text = 'Value';
            app.ValueEditField_2 = uieditfield(app.SinglesliceTab, 'text');
            app.ValueEditField_2.ValueChangedFcn = createCallbackFcn(app, @ValueEditField_2ValueChanged, true);
            app.ValueEditField_2.Editable = 'off';
            app.UIAxes3 = uiaxes(app.SinglesliceTab);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.ZTick = [];
            app.UIAxes4 = uiaxes(app.SinglesliceTab);
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes4.ZTick = [];
            app.UIAxes5 = uiaxes(app.SinglesliceTab);
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes5.ZTick = [];
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ComponentNOEditFieldLabel = uilabel(app.UIFigure);
            app.ComponentNOEditFieldLabel.Text = 'Component NO.';
            app.ComponentNOEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rebuild3D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end