classdef Runmcr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MCRLabel                   matlab.ui.control.Label
        NoiseallowedLabel          matlab.ui.control.Label
        NumberofiterationsLabel    matlab.ui.control.Label
        ConvergencecriterionLabel  matlab.ui.control.Label
        Noiseall                   matlab.ui.control.EditField
        Niterations                matlab.ui.control.EditField
        Convergence                matlab.ui.control.EditField
        RunMCRButton               matlab.ui.control.Button
        ReviewButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %判断工作区中的数据是否运行过MCR
            runmcr=evalin('base','importMSv.summary.runmcr;');
            if runmcr==2
                evalin('base','mcr_als=importMSv.mcr_als;');
                evalin('base','copt=mcr_als.alsOptions.resultats.optim_concs;');
                evalin('base','sopt=mcr_als.alsOptions.resultats.optim_specs;');
                
                noiseLevel=evalin('base','mcr_als.InitEstim.pureNoiseLevel;');
                nit=evalin('base','mcr_als.alsOptions.opt.nit;');
                tolsigma=evalin('base','mcr_als.alsOptions.opt.tolsigma;');
                
                app.Noiseall.Value=num2str(noiseLevel);
                app.Niterations.Value=num2str(nit);
                app.Convergence.Value=num2str(tolsigma);
                
                app.ReviewButton.Enable='on';
            else
                %MCR初始化
                if ((evalin('base','exist(''mcr_als'')'))==1)
                    evalin('base','clear(''mcr_als'')');
                end
                evalin('base','mcr_als.alsOptions.weighted.constrained=0;');
                evalin('base','mcr_als.aux.estat=0;');
                evalin('base','mcr_als.InitEstim.pureNoiseLevel=10;');
                evalin('base','mcr_als.alsOptions.opt.nit=50;');
                evalin('base','mcr_als.alsOptions.opt.tolsigma=0.1;');
            end
        end

        % Value changed function: Noiseall
        function NoiseallValueChanged(app, event)
            % 噪声过滤
            noiseLevel=str2double(app.Noiseall.Value);
            assignin('base','noiseLevel',noiseLevel);
            evalin('base','mcr_als.InitEstim.pureNoiseLevel=noiseLevel;');
            evalin('base','clear noiseLevel');
        end

        % Value changed function: Niterations
        function NiterationsValueChanged(app, event)
            %迭代次数
            nit=str2double(app.Niterations.Value);
            assignin('base','nit',nit);
            evalin('base','mcr_als.alsOptions.opt.nit=nit;');
            evalin('base','clear nit');
        end

        % Value changed function: Convergence
        function ConvergenceValueChanged(app, event)
            % 收敛值
            tolsigma=str2double(app.Convergence.Value);
            assignin('base','tolsigma',tolsigma);
            evalin('base','mcr_als.alsOptions.opt.tolsigma=tolsigma;');
            evalin('base','clear tolsigma');
        end

        % Button pushed function: RunMCRButton
        function RunMCRButtonPushed(app, event)
            % Run MCR
            %SVD
            evalin('base','mcr_als.aux.estat=1;');
            matriu_svd=evalin('base','importMSv.ROIplotGUI.current_MSroi');
            matriu_svd=full(matriu_svd);
            [a,b]=size(matriu_svd);
            evalin('base','mcr_als.aux.estat_svd=1;');
            
            if (a>10000 | b>10000) % avoid problems with big data
                svd_selectionion;
                while evalin('base','mcr_als.aux.estat_svd;')==1
                    pause(0.3);
                end
                nrsvd=evalin('base','mcr_als.aux.nrsvd;');
                [U,S,V]=svds(matriu_svd,nrsvd);
            else
                [U,S,V]=svd(matriu_svd,'econ');
            end
            
            assignin('base','U',U);
            assignin('base','S',S);
            assignin('base','V',V);
            evalin('base','mcr_als.CompNumb.U=U;');
            evalin('base','mcr_als.CompNumb.S=S;');
            evalin('base','mcr_als.CompNumb.V=V;');
            evalin('base','clear U S V');
            svdguii;
            
            while(evalin('base','mcr_als.aux.estat')==1)
                pause(0.3);
            end
            evalin('base','mcr_als.aux.estat=0;');
            
            % pure
            evalin('base','mcr_als.aux.estat=1;');
            pure_2022;
            while(evalin('base','mcr_als.aux.estat')==1)
                pause(0.3);
            end
            evalin('base','mcr_als.aux.estat=0;');
            
            % als
            als_2022
            
            %mcr
            alsOptimization_2022
            
            copt=evalin('base','mcr_als.alsOptions.resultats.optim_concs;');
            noFiles=evalin('base','importMSv.load.noFiles');
            
            for si=1:noFiles
                no_pixel=evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.Allpixel_cut;']);
                
                q=si+1;
                numscan(1)=0;
                numscan(q)=numscan(si)+no_pixel;
                
                newcoptn=copt(numscan(si)+1:numscan(q),:);
                
                assignin('base','newcoptn',newcoptn);
                evalin('base',['mcr_als.alsOptions.resultats.img_',num2str(si),'.copt=newcoptn;']);
                evalin('base','clear newcoptn');
            end
            
            evalin('base','importMSv.mcr_als=mcr_als;');
            evalin('base','importMSv.summary.runmcr=2;');
            app.ReviewButton.Enable='on';
            delete(app.UIFigure)
        end

        % Button pushed function: ReviewButton
        function ReviewButtonPushed(app, event)
            MCR_result
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MCRLabel = uilabel(app.UIFigure);
            app.MCRLabel.Text = 'MCR';
            app.NoiseallowedLabel = uilabel(app.UIFigure);
            app.NoiseallowedLabel.Text = 'Noise allowed (%):';
            app.NumberofiterationsLabel = uilabel(app.UIFigure);
            app.NumberofiterationsLabel.Text = 'Number of iterations:';
            app.ConvergencecriterionLabel = uilabel(app.UIFigure);
            app.ConvergencecriterionLabel.Text = 'Convergence criterion:';
            app.Noiseall = uieditfield(app.UIFigure, 'text');
            app.Noiseall.ValueChangedFcn = createCallbackFcn(app, @NoiseallValueChanged, true);
            app.Noiseall.Value = '10';
            app.Niterations = uieditfield(app.UIFigure, 'text');
            app.Niterations.ValueChangedFcn = createCallbackFcn(app, @NiterationsValueChanged, true);
            app.Niterations.Value = '50';
            app.Convergence = uieditfield(app.UIFigure, 'text');
            app.Convergence.ValueChangedFcn = createCallbackFcn(app, @ConvergenceValueChanged, true);
            app.Convergence.Value = '0.1';
            app.RunMCRButton = uibutton(app.UIFigure, 'push');
            app.RunMCRButton.ButtonPushedFcn = createCallbackFcn(app, @RunMCRButtonPushed, true);
            app.RunMCRButton.Text = 'Run MCR';
            app.ReviewButton = uibutton(app.UIFigure, 'push');
            app.ReviewButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewButtonPushed, true);
            app.ReviewButton.Enable = 'off';
            app.ReviewButton.Text = 'Review';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Runmcr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end