classdef svd_selectionion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        OneofthedimensionsoftheconsideredmatrixislargeLabel  matlab.ui.control.Label
        Label           matlab.ui.control.Label
        SingularvalueslimitselectionLabel  matlab.ui.control.Label
        WritethenumberofsingularvaluestobecalculatedbySVDLabel  matlab.ui.control.Label
        ContinueButton  matlab.ui.control.Button
        EditField       matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            nrsvd=str2double(app.EditField.Value);
            assignin('base','nrsvd',nrsvd);
            evalin('base','mcr_als.aux.nrsvd=nrsvd;');
            evalin('base','clear nrsvd');
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            evalin('base','mcr_als.aux.estat_svd=0;');
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OneofthedimensionsoftheconsideredmatrixislargeLabel = uilabel(app.UIFigure);
            app.OneofthedimensionsoftheconsideredmatrixislargeLabel.Text = 'One of the dimensions of the considered matrix is large(more than 10000 elements).';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'To avoid computer memory problems, calculation of only a few singular values is recommended.';
            app.SingularvalueslimitselectionLabel = uilabel(app.UIFigure);
            app.SingularvalueslimitselectionLabel.Text = 'Singular values limit selection ';
            app.WritethenumberofsingularvaluestobecalculatedbySVDLabel = uilabel(app.UIFigure);
            app.WritethenumberofsingularvaluestobecalculatedbySVDLabel.Text = 'Write the number of singular values to be calculated by SVD:';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = svd_selectionion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end