classdef svdguii < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EigenValuesListBoxLabel  matlab.ui.control.Label
        EigenValuesListBox       matlab.ui.control.ListBox
        SelectednumberofcomponentsLabel  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        ContinueButton           matlab.ui.control.Button
        SingularValueDecompositionLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            S=evalin('base','mcr_als.CompNumb.S;');
            Sd=diag(S);
            
            plot(app.UIAxes,Sd,'o');
            figure('visible','off');
            zoom(app.UIAxes,'on');
            
            for i=1:length(Sd)
                xx=num2str(Sd(i));
                scell(i)={xx};
            end
            app.EigenValuesListBox.Items = scell;
        end

        % Value changed function: EigenValuesListBox
        function EigenValuesListBoxValueChanged(app, event)
            app.EigenValuesListBox.ItemsData = 1:length(app.EigenValuesListBox.Items);
            ns = app.EigenValuesListBox.Value;
            
            S=evalin('base','mcr_als.CompNumb.S');
            
            cla(app.UIAxes,"reset");
            x=diag(S);
            plot(app.UIAxes,x,'o');
            figure('visible','off');
            zoom(app.UIAxes,'on');
            
            app.Label.Text=num2str(ns);
            app.ContinueButton.Enable='on';
            
            assignin('base','nc',ns);
            evalin('base','mcr_als.CompNumb.nc=nc;');
            evalin('base','clear nc');
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            evalin('base','mcr_als.aux.estat=2;');
            evalin('base','mcr_als.CompNumb.method=''SVD'';');
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EigenValuesListBoxLabel = uilabel(app.UIFigure);
            app.EigenValuesListBoxLabel.Text = 'EigenValues';
            app.EigenValuesListBox = uilistbox(app.UIFigure);
            app.EigenValuesListBox.Items = {};
            app.EigenValuesListBox.ValueChangedFcn = createCallbackFcn(app, @EigenValuesListBoxValueChanged, true);
            app.EigenValuesListBox.Value = {};
            app.SelectednumberofcomponentsLabel = uilabel(app.UIFigure);
            app.SelectednumberofcomponentsLabel.Text = 'Selected number of components:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Enable = 'off';
            app.ContinueButton.Text = 'Continue';
            app.SingularValueDecompositionLabel = uilabel(app.UIFigure);
            app.SingularValueDecompositionLabel.Text = 'Singular Value Decomposition';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EIgenValues representation')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = svdguii

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end