classdef RunEqual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RunROIButton                   matlab.ui.control.Button
        AlgorithmparametersPanel       matlab.ui.container.Panel
        editPixels                     matlab.ui.control.EditField
        MinimumnumberofpixelsLabel     matlab.ui.control.Label
        dropCalc                       matlab.ui.control.DropDown
        ROImzvaluescalculationLabel    matlab.ui.control.Label
        MassunitsDropDown              matlab.ui.control.DropDown
        MassunitsLabel                 matlab.ui.control.Label
        editMassError                  matlab.ui.control.EditField
        MasserrortoleranceLabel        matlab.ui.control.Label
        editFactor                     matlab.ui.control.EditField
        FrequencythresholdsingleLabel  matlab.ui.control.Label
        editSignal                     matlab.ui.control.EditField
        SignalintensitythresholdLabel  matlab.ui.control.Label
        NormalizationDropDownLabel     matlab.ui.control.Label
        NormalizationDropDown          matlab.ui.control.DropDown
        MassfilteringPanel             matlab.ui.container.Panel
        editTargErrorMZ                matlab.ui.control.EditField
        AllowederrorDaLabel            matlab.ui.control.Label
        editTargeted                   matlab.ui.control.EditField
        VariablenamewithlistofmzvaluesLabel  matlab.ui.control.Label
        checkTarget                    matlab.ui.control.CheckBox
        editHighMZ                     matlab.ui.control.EditField
        HighmzLabel                    matlab.ui.control.Label
        editLowMZ                      matlab.ui.control.EditField
        LowmzLabel                     matlab.ui.control.Label
        checkMZFilter                  matlab.ui.control.CheckBox
        ROILabel                       matlab.ui.control.Label
        MCRButton                      matlab.ui.control.Button
        AveragemassspectrumButton      matlab.ui.control.Button
        ClusteringButton               matlab.ui.control.Button
        ViewButton                     matlab.ui.control.Button
        ImageregistrationButton        matlab.ui.control.Button
        RenormalizeButton              matlab.ui.control.Button
        PlotTICButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %判断工作区中的数据是否运行过ROI
            runROI= evalin('base','importMSv.summary.runEqual;');
            if runROI==2
                % 启用各个选框
                app.MCRButton.Enable='on';
                app.AveragemassspectrumButton.Enable='on';
                app.ImageregistrationButton.Enable='on';
                app.ClusteringButton.Enable='on';
                app.ViewButton.Enable='on';
                app.RenormalizeButton.Enable='on';
                app.PlotTICButton.Enable='on';
                
                % ROI参数
                thresh=evalin('base','importMSv.msroi.ROI_threshold');
                factorThresh=evalin('base','importMSv.msroi.factorThresh');
                mzerror=evalin('base','importMSv.msroi.ROI_error');
                minPixels=evalin('base','importMSv.msroi.minPixels');
                app.editSignal.Value = num2str(thresh);
                app.editFactor.Value=num2str(factorThresh);
                app.editMassError.Value=num2str(mzerror);
                app.editPixels.Value=num2str(minPixels);
                %mz过滤器
                mzFilter=evalin('base','importMSv.targ.mzFilter;');
                if mzFilter==1
                    app.editLowMZ.Enable = 'on';
                    app.editHighMZ.Enable = 'on';
                    lowMZ=evalin('base','importMSv.targ.lowMZ;');
                    highMZ=evalin('base','importMSv.targ.highMZ;');
                    app.editLowMZ.Value=num2str(lowMZ);
                    app.editHighMZ.Value=num2str(highMZ);
                else
                    app.editLowMZ.Enable = 'off';
                    app.editLowMZ.Value = '';
                    app.editHighMZ.Enable = 'off';
                    app.editHighMZ.Value = '';
                end
                %针对性分析
                mzTargeted=evalin('base','importMSv.targ.mzTargeted;');
                if mzTargeted==1
                    app.editTargeted.Enable = 'on';
                    app.editTargErrorMZ.Enable = 'on';
                    target=evalin('base','importMSv.targ.target;');
                    targError=evalin('base','importMSv.targ.targError;');
                    app.editTargeted.Value=num2str(target);
                    app.editTargErrorMZ.Value=num2str(targError);
                else
                    app.editTargeted.Enable = 'off';
                    app.editTargeted.Value = ' ';
                    app.editTargErrorMZ.Enable = 'off';
                    app.editTargErrorMZ.Value = '';
                end
                
                % 将ROI结果加载进工作区
                %evalin('base','roicell=importMSv.ROIplotGUI.current_roicell;');
                evalin('base','mzroi=importMSv.ROIplotGUI.current_mzroi;');
                evalin('base','MSroi=importMSv.ROIplotGUI.current_MSroi;');
                
                % 判断工作区中的数据是否运行过MCR
                runmcr=evalin('base','importMSv.summary.runmcr;');
                if runmcr==2
                    Runmcr
                end
                % 判断工作区中的数据是否运行过compare
                runcompare=evalin('base','importMSv.summary.runcompare;');
                if runcompare==2
                    Runcompare
                end
                
            else
                % ROI初始化
                evalin('base','importMSv.targ.mzFilter = 0;');
                evalin('base','importMSv.targ.mzTargeted = 0;');
                evalin('base','importMSv.msroi.ROI_threshold=0;');
                evalin('base','importMSv.msroi.factorThresh=20;');
                evalin('base','importMSv.msroi.ROI_error=0.001;');
                evalin('base','importMSv.msroi.minPixels=10;');
                evalin('base',['importMSv.msroi.ROI_units=','''daltons'';']);
                evalin('base',['importMSv.msroi.weighted=','''default: mean'';']);
                evalin('base','importMSv.msroi.nornum=1;');
            end
        end

        % Value changed function: editSignal
        function editSignalValueChanged(app, event)
            % 设置阈值
            threshold = str2double(app.editSignal.Value);
            assignin('base','ROI_threshold',threshold);
            evalin('base','importMSv.msroi.ROI_threshold=ROI_threshold;');
            evalin('base','clear ROI_threshold;');
        end

        % Value changed function: editFactor
        function editFactorValueChanged(app, event)
            %倍数因子
            factorThresh=str2double(app.editFactor.Value);
            assignin('base','factorThresh',factorThresh);
            evalin('base','importMSv.msroi.factorThresh=factorThresh;');
            evalin('base','clear factorThresh;');
        end

        % Value changed function: editMassError
        function editMassErrorValueChanged(app, event)
            %数据精度
            error=str2double(app.editMassError.Value);
            assignin('base','ROI_error',error);
            evalin('base','importMSv.msroi.ROI_error=ROI_error;');
            evalin('base','clear ROI_error;');
        end

        % Value changed function: MassunitsDropDown
        function MassunitsDropDownValueChanged(app, event)
            %数据单位
            units = app.MassunitsDropDown.Value;
            assignin('base','ROI_units',units);
            evalin('base','importMSv.msroi.ROI_units=ROI_units;');
            evalin('base','clear ROI_units');
        end

        % Value changed function: dropCalc
        function dropCalcValueChanged(app, event)
            %计算方式
            mzd = app.dropCalc.Value;
            assignin('base','mzd',mzd);
            evalin('base','importMSv.msroi.weighted=mzd;');
            evalin('base','clear mzd');
        end

        % Value changed function: editPixels
        function editPixelsValueChanged(app, event)
            % 最少出现在像素里的次数
            minPixels=str2double(app.editPixels.Value);
            assignin('base','minPixels',minPixels);
            evalin('base','importMSv.msroi.minPixels=minPixels;');
            evalin('base','clear minPixels;');
        end

        % Value changed function: NormalizationDropDown
        function NormalizationDropDownValueChanged(app, event)
            % normalization方式
            app.NormalizationDropDown.ItemsData = 1:10;
            nornum = app.NormalizationDropDown.Value;
            assignin('base','nornum',nornum);
            evalin('base','importMSv.msroi.nornum=nornum;');
            evalin('base','clear nornum;');
        end

        % Value changed function: checkMZFilter
        function checkMZFilterValueChanged(app, event)
            % mz过滤器
            filter_value = app.checkMZFilter.Value;
            
            if filter_value==1
                evalin('base','importMSv.targ.mzFilter=1;');
                app.editLowMZ.Enable = 'on';
                app.editHighMZ.Enable = 'on';
                evalin('base','importMSv.targ.lowMZ=[];');
                evalin('base','importMSv.targ.highMZ=[];');
            else
                evalin('base','importMSv.targ.mzFilter=0;');
                evalin('base',['importMSv.targ=rmfield(importMSv.targ,','''lowMZ''',');']);
                evalin('base',['importMSv.targ=rmfield(importMSv.targ,','''highMZ''',');']);
                app.editLowMZ.Value = '';
                app.editLowMZ.Enable = 'off';
                app.editHighMZ.Value = '';
                app.editHighMZ.Enable = 'off';
            end
        end

        % Value changed function: editLowMZ
        function editLowMZValueChanged(app, event)
            %最低mz值
            lowMZ = str2double(app.editLowMZ.Value);
            assignin('base','lowMZ',lowMZ);
            evalin('base','importMSv.targ.lowMZ=lowMZ;');
            evalin('base','clear lowMZ;');
        end

        % Value changed function: editHighMZ
        function editHighMZValueChanged(app, event)
            %最高mz值
            highMZ = str2double(app.editHighMZ.Value);
            assignin('base','highMZ',highMZ);
            evalin('base','importMSv.targ.highMZ=highMZ;');
            evalin('base','clear highMZ;');
        end

        % Value changed function: checkTarget
        function checkTargetValueChanged(app, event)
            % 针对性分析
            value = app.checkTarget.Value;
            if value==1
                evalin('base','importMSv.targ.mzTargeted=1;');
                app.editTargeted.Enable = 'on';
                app.editTargErrorMZ.Enable = 'on';
                evalin('base','importMSv.targ.target=[];');
                evalin('base','importMSv.targ.targError=[];');
            else
                evalin('base','importMSv.targ.mzTargeted=0;');
                evalin('base',['importMSv.targ=rmfield(importMSv.targ,','''target''',');']);
                evalin('base',['importMSv.targ=rmfield(importMSv.targ,','''targError''',');']);
                app.editTargeted.Value = '';
                app.editTargeted.Enable = 'off';
                app.editTargErrorMZ.Value = '';
                app.editTargErrorMZ.Enable = 'off';
            end
        end

        % Value changed function: editTargeted
        function editTargetedValueChanged(app, event)
            % 该mz的值
            target = app.editTargeted.Value;
            target=evalin('base',target);
            assignin('base','target',target);
            evalin('base','importMSv.targ.target=target;');
            evalin('base','clear target');
        end

        % Value changed function: editTargErrorMZ
        function editTargErrorMZValueChanged(app, event)
            % 允许误差范围
            targError = str2double(app.editTargErrorMZ.Value);
            assignin('base','targError',targError);
            evalin('base','importMSv.targ.targError=targError;');
            evalin('base','clear targError;');
        end

        % Button pushed function: RunROIButton
        function RunROIButtonPushed(app, event)
            t=0;tic
            % 运行ROI
            
            [mzroi,roicell,nrows] = nn_RunPartialareaROI;
            
            assignin('base','roicell',roicell);
            evalin('base','importMSv.ROIplotGUI.current_roicell=roicell;');
            
            assignin('base','mzroi',mzroi);
            evalin('base','importMSv.ROIplotGUI.current_mzroi=mzroi;');
            
            % 合成矩阵
            nmzroi=length(mzroi);
            MSroi=sparse(nrows,nmzroi);
            
            irow=roicell(:,3);MSI=roicell(:,2);
            
            delete(gcp('nocreate'));  
            numCore = floor(feature('numcores')/2);
            parpool('local',numCore);
            parfor i=1:nmzroi
                ipxs=irow{i};intt=MSI{i}
                [uniquemz, ~, idx] = unique(ipxs);
                sumint = accumarray(idx, intt);
                nmsroi=sparse(uniquemz,1,sumint,nrows,1);
                MSroi(:,i) = nmsroi;
            end
            delete(gcp('nocreate'))
            
            assignin('base','NonorMSroi',MSroi);
            evalin('base','importMSv.ROIplotGUI.current_NonorMSroi=NonorMSroi;');
            
            %标准化
            nornum=evalin('base','importMSv.msroi.nornum');
            allfun=norfun;
            eval(['[MSroi,~]=allfun.fun',num2str(nornum),'(MSroi);']);
            
            % Kmean、Compare初始化
            noFiles=evalin('base','importMSv.load.noFiles');
            colorll=evalin('base','importMSv.summary.color;');
            for si=1:noFiles
                out_roi=evalin('base',['importMSv.Partialarea.img_',num2str(si),'.out_roi']);
                [ypixn,xpixn]=size(out_roi);
                no_pixel=xpixn*ypixn;
                
                q=si+1;
                numscan(1)=0;
                numscan(q)=numscan(si)+no_pixel;
                
                Yn=MSroi(numscan(si)+1:numscan(q),:);
                ori_MSroi=Yn;
                
                tic2=sum(Yn,2);
                
                MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(si),'.MSicut;']);
                if MSicut==2
                    mask=evalin('base',['importMSv.Partialarea.img_',num2str(si),'.mask;']);
                    indexesn=double(mask);
                    indexesn(indexesn==1)=out_roi;
                    mascaran=double(mask);
                    mascaran(mascaran==1)=tic2;
                    [xn_cut,yn_cut]=size(mascaran);
                    
                    Allpixeln_cut=length(tic2);
                    
%                     [~,lie]=size(Yn);
%                     newMSroin=sparse(Allpixeln_cut,lie);
%                     lable=reshape(mask,[],1);
%                     newMSroin(lable==1,:)=Yn;
%                     newMSroin(lable==0,:)=nan;
                    newMSroin=Yn;
                else
                    newMSroin=Yn;
                    indexesn=out_roi;
                    [xn_cut,yn_cut]=size(indexesn);
                    mascaran=reshape(tic2,xn_cut,yn_cut);
                    Allpixeln_cut=length(tic2);
                end
                
                assignin('base','ori_MSroi',ori_MSroi);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.ori_MSroi=ori_MSroi;']);
                
                assignin('base','newMSroi',newMSroin);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.MSroi=newMSroi;']);
                
                assignin('base','mascaran',mascaran);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.mascara=mascaran;']);
                
                assignin('base','indexesn',indexesn);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.indexes=indexesn;']);
                
                assignin('base','x_cut',xn_cut);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.x_cut=x_cut;']);
                
                assignin('base','y_cut',yn_cut);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.y_cut=y_cut;']);
                
                assignin('base','Allpixel_cut',Allpixeln_cut);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.Allpixel_cut=Allpixel_cut;']);
                
                evalin('base','clear NonorMSroi newMSroi mascaran indexesn x_cut y_cut Allpixel_cut ori_MSroi');
                
                name=evalin('base',['importMSv.summary.img_',num2str(si),'.name_short;']);
                [xx,yy]=SupplementZero(mzroi,sum(newMSroin,1)/Allpixeln_cut);
                xmin=min(xx);xmax=max(xx);ymin=min(yy);ymax=max(yy);
                
                figure,plot(xx,yy,'LineWidth',2);
                xlabel('m/z');ylabel('Intensity')
                xticklabels('auto');axis([xmin xmax ymin ymax]);
                title({strcat('name : ',name);'Total average mass spectrum'},'Interpreter','none')
                
                mascara=set_range(mascaran,4,0.95);
                figure,imagesc(mascara)
                axis('xy','tight','image') ;colormap(colorll);colorbar;
                title({strcat('name : ',name);'TIC'},'Interpreter','none')
                
            end
            
            MSroi_cut=[];
            
            for ii=1:noFiles
                MSroi_n=evalin('base',['importMSv.ROIplotGUI.img_',num2str(ii),'.MSroi;']);
                MSroi_cut=[MSroi_cut;MSroi_n];
            end
            assignin('base','MSroi',MSroi_cut);
            evalin('base','importMSv.ROIplotGUI.current_MSroi=MSroi;');
            
            t=t+toc;
            disp(['This module takes：',num2str(t),'s'])
            
            
            app.MCRButton.Enable='on';
            app.AveragemassspectrumButton.Enable='on';
            app.ImageregistrationButton.Enable='on';
            app.ClusteringButton.Enable='on';
            app.ViewButton.Enable='on';
            app.RenormalizeButton.Enable='on';
            app.PlotTICButton.Enable='on';
            
            evalin('base','importMSv.summary.runEqual=2;');
            disp(' ************************** FINISH *******************************');
        end

        % Button pushed function: RenormalizeButton
        function RenormalizeButtonPushed(app, event)
            nornum=evalin('base','importMSv.msroi.nornum');
            MSroi=evalin('base','importMSv.ROIplotGUI.current_NonorMSroi;');
            
            %标准化
            allfun=norfun;
            eval(['[MSroi,~]=allfun.fun',num2str(nornum),'(MSroi);']);
            
            % Kmean、Compare初始化
            noFiles=evalin('base','importMSv.load.noFiles');
            for si=1:noFiles
                out_roi=evalin('base',['importMSv.Partialarea.img_',num2str(si),'.out_roi']);
                [ypixn,xpixn]=size(out_roi);
                no_pixel=xpixn*ypixn;
                
                q=si+1;
                numscan(1)=0;
                numscan(q)=numscan(si)+no_pixel;
                Yn=MSroi(numscan(si)+1:numscan(q),:);
                ori_MSroi=Yn;
                tic2=sum(Yn,2);
                
                MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(si),'.MSicut;']);
                if MSicut==2
                    mask=evalin('base',['importMSv.Partialarea.img_',num2str(si),'.mask;']);
                    indexesn=double(mask);
                    indexesn(indexesn==1)=out_roi;
                    mascaran=double(mask);
                    mascaran(mascaran==1)=tic2;
                    [xn_cut,yn_cut]=size(mascaran);
                    Allpixeln_cut=xn_cut*yn_cut;
                    
                    [~,lie]=size(Yn);
                    newMSroin=zeros(Allpixeln_cut,lie);
                    lable=reshape(mask,[],1);
                    newMSroin(lable==1,:)=Yn;
                    
                else
                    newMSroin=Yn;
                    indexesn=out_roi;
                    [xn_cut,yn_cut]=size(indexesn);
                    mascaran=reshape(tic2,xn_cut,yn_cut);
                    Allpixeln_cut=xn_cut*yn_cut;
                end
                
                assignin('base','ori_MSroi',ori_MSroi);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.ori_MSroi=ori_MSroi;']);
                
                assignin('base','newMSroi',newMSroin);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.MSroi=newMSroi;']);
                
                assignin('base','mascaran',mascaran);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.mascara=mascaran;']);
                
                assignin('base','indexesn',indexesn);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.indexes=indexesn;']);
                
                assignin('base','x_cut',xn_cut);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.x_cut=x_cut;']);
                
                assignin('base','y_cut',yn_cut);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.y_cut=y_cut;']);
                
                assignin('base','Allpixel_cut',Allpixeln_cut);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.Allpixel_cut=Allpixel_cut;']);
                
                evalin('base','clear NonorMSroi newMSroi mascaran indexesn x_cut y_cut Allpixel_cut ori_MSroi');
            end
            
            MSroi_cut=[];
            
            for ii=1:noFiles
                MSroi_n=evalin('base',['importMSv.ROIplotGUI.img_',num2str(ii),'.MSroi;']);
                MSroi_cut=[MSroi_cut;MSroi_n];
            end
            assignin('base','MSroi',MSroi_cut);
            evalin('base','importMSv.ROIplotGUI.current_MSroi=MSroi;');
            disp(' ************************** FINISH *******************************');
        end

        % Button pushed function: MCRButton
        function MCRButtonPushed(app, event)
            Runmcr
        end

        % Button pushed function: AveragemassspectrumButton
        function AveragemassspectrumButtonPushed(app, event)
            Runcompare
        end

        % Button pushed function: ClusteringButton
        function ClusteringButtonPushed(app, event)
            Runclustering
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            RunMSimaging
        end

        % Button pushed function: ImageregistrationButton
        function ImageregistrationButtonPushed(app, event)
            RunImageRegistration
        end

        % Button pushed function: PlotTICButton
        function PlotTICButtonPushed(app, event)
            noFiles=evalin('base','importMSv.load.noFiles;');
            mzroi=evalin('base','importMSv.ROIplotGUI.current_mzroi;');
            colorll=evalin('base','importMSv.summary.color;');
            
            for si=1:noFiles
                name=evalin('base',['importMSv.summary.img_',num2str(si),'.name_short;']);
                newMSroin=evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.MSroi;']);
                mascaran=evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.mascara;']);
                [xx,yy]=SupplementZero(mzroi,mean(newMSroin,1));
                xmin=min(xx);xmax=max(xx);ymin=min(yy);ymax=max(yy);
                
                figure,plot(xx,yy,'LineWidth',2);
                xlabel('m/z');ylabel('Intensity')
                xticklabels('auto');axis([xmin xmax ymin ymax]);
                title({strcat('name : ',name);'Total average mass spectrum'},'Interpreter','none')
                
                mascara=set_range(mascaran,4,0.95);
                figure,imagesc(mascara)
                axis('tight','image') ;colormap(colorll);colorbar;
                title({strcat('name : ',name);'TIC'},'Interpreter','none')
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunROIButton = uibutton(app.UIFigure, 'push');
            app.RunROIButton.ButtonPushedFcn = createCallbackFcn(app, @RunROIButtonPushed, true);
            app.RunROIButton.Text = 'Run ROI';
            app.AlgorithmparametersPanel = uipanel(app.UIFigure);
            app.AlgorithmparametersPanel.Title = 'Algorithm parameters';
            app.editPixels = uieditfield(app.AlgorithmparametersPanel, 'text');
            app.editPixels.ValueChangedFcn = createCallbackFcn(app, @editPixelsValueChanged, true);
            app.editPixels.Value = '10';
            app.MinimumnumberofpixelsLabel = uilabel(app.AlgorithmparametersPanel);
            app.MinimumnumberofpixelsLabel.Text = 'Minimum number of pixels';
            app.dropCalc = uidropdown(app.AlgorithmparametersPanel);
            app.dropCalc.Items = {'default: mean', 'median', 'weighted', 'max'};
            app.dropCalc.ValueChangedFcn = createCallbackFcn(app, @dropCalcValueChanged, true);
            app.dropCalc.Value = 'default: mean';
            app.ROImzvaluescalculationLabel = uilabel(app.AlgorithmparametersPanel);
            app.ROImzvaluescalculationLabel.Text = 'ROI mz values calculation';
            app.MassunitsDropDown = uidropdown(app.AlgorithmparametersPanel);
            app.MassunitsDropDown.Items = {'daltons', 'ppm'};
            app.MassunitsDropDown.ValueChangedFcn = createCallbackFcn(app, @MassunitsDropDownValueChanged, true);
            app.MassunitsDropDown.Value = 'daltons';
            app.MassunitsLabel = uilabel(app.AlgorithmparametersPanel);
            app.MassunitsLabel.Text = 'Mass units';
            app.editMassError = uieditfield(app.AlgorithmparametersPanel, 'text');
            app.editMassError.ValueChangedFcn = createCallbackFcn(app, @editMassErrorValueChanged, true);
            app.editMassError.Value = '0.001';
            app.MasserrortoleranceLabel = uilabel(app.AlgorithmparametersPanel);
            app.MasserrortoleranceLabel.Text = 'Mass error tolerance';
            app.editFactor = uieditfield(app.AlgorithmparametersPanel, 'text');
            app.editFactor.ValueChangedFcn = createCallbackFcn(app, @editFactorValueChanged, true);
            app.editFactor.Value = '20';
            app.FrequencythresholdsingleLabel = uilabel(app.AlgorithmparametersPanel);
            app.FrequencythresholdsingleLabel.Text = 'Frequency threshold (single)';
            app.editSignal = uieditfield(app.AlgorithmparametersPanel, 'text');
            app.editSignal.ValueChangedFcn = createCallbackFcn(app, @editSignalValueChanged, true);
            app.editSignal.Value = '0';
            app.SignalintensitythresholdLabel = uilabel(app.AlgorithmparametersPanel);
            app.SignalintensitythresholdLabel.Text = 'Signal intensity threshold';
            app.NormalizationDropDownLabel = uilabel(app.AlgorithmparametersPanel);
            app.NormalizationDropDownLabel.Text = 'Normalization';
            app.NormalizationDropDown = uidropdown(app.AlgorithmparametersPanel);
            app.NormalizationDropDown.Items = {'default: TIC', 'None', 'Euclidean', 'Vecnorm2', 'Zscore_std', 'Norm2', 'Scale_std', 'Range', 'Center_mean', 'Center_median'};
            app.NormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @NormalizationDropDownValueChanged, true);
            app.NormalizationDropDown.Value = 'default: TIC';
            app.MassfilteringPanel = uipanel(app.UIFigure);
            app.MassfilteringPanel.Title = 'Mass filtering';
            app.editTargErrorMZ = uieditfield(app.MassfilteringPanel, 'text');
            app.editTargErrorMZ.ValueChangedFcn = createCallbackFcn(app, @editTargErrorMZValueChanged, true);
            app.editTargErrorMZ.Enable = 'off';
            app.AllowederrorDaLabel = uilabel(app.MassfilteringPanel);
            app.AllowederrorDaLabel.Text = 'Allowed error (Da)?';
            app.editTargeted = uieditfield(app.MassfilteringPanel, 'text');
            app.editTargeted.ValueChangedFcn = createCallbackFcn(app, @editTargetedValueChanged, true);
            app.editTargeted.Enable = 'off';
            app.VariablenamewithlistofmzvaluesLabel = uilabel(app.MassfilteringPanel);
            app.VariablenamewithlistofmzvaluesLabel.Text = 'Variable name with list of mz values';
            app.checkTarget = uicheckbox(app.MassfilteringPanel);
            app.checkTarget.ValueChangedFcn = createCallbackFcn(app, @checkTargetValueChanged, true);
            app.checkTarget.Text = 'Targeted analysis?';
            app.editHighMZ = uieditfield(app.MassfilteringPanel, 'text');
            app.editHighMZ.ValueChangedFcn = createCallbackFcn(app, @editHighMZValueChanged, true);
            app.editHighMZ.Enable = 'off';
            app.HighmzLabel = uilabel(app.MassfilteringPanel);
            app.HighmzLabel.Text = 'High mz';
            app.editLowMZ = uieditfield(app.MassfilteringPanel, 'text');
            app.editLowMZ.ValueChangedFcn = createCallbackFcn(app, @editLowMZValueChanged, true);
            app.editLowMZ.Enable = 'off';
            app.LowmzLabel = uilabel(app.MassfilteringPanel);
            app.LowmzLabel.Text = 'Low mz';
            app.checkMZFilter = uicheckbox(app.MassfilteringPanel);
            app.checkMZFilter.ValueChangedFcn = createCallbackFcn(app, @checkMZFilterValueChanged, true);
            app.checkMZFilter.Text = 'mzFilter?';
            app.ROILabel = uilabel(app.UIFigure);
            app.ROILabel.Text = 'ROI';
            app.MCRButton = uibutton(app.UIFigure, 'push');
            app.MCRButton.ButtonPushedFcn = createCallbackFcn(app, @MCRButtonPushed, true);
            app.MCRButton.Enable = 'off';
            app.MCRButton.Text = 'MCR';
            app.AveragemassspectrumButton = uibutton(app.UIFigure, 'push');
            app.AveragemassspectrumButton.ButtonPushedFcn = createCallbackFcn(app, @AveragemassspectrumButtonPushed, true);
            app.AveragemassspectrumButton.Enable = 'off';
            app.AveragemassspectrumButton.Text = 'Average mass spectrum';
            app.ClusteringButton = uibutton(app.UIFigure, 'push');
            app.ClusteringButton.ButtonPushedFcn = createCallbackFcn(app, @ClusteringButtonPushed, true);
            app.ClusteringButton.Enable = 'off';
            app.ClusteringButton.Text = 'Clustering';
            app.ViewButton = uibutton(app.UIFigure, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Enable = 'off';
            app.ViewButton.Text = 'View';
            app.ImageregistrationButton = uibutton(app.UIFigure, 'push');
            app.ImageregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @ImageregistrationButtonPushed, true);
            app.ImageregistrationButton.Enable = 'off';
            app.ImageregistrationButton.Text = 'Image registration';
            app.RenormalizeButton = uibutton(app.UIFigure, 'push');
            app.RenormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @RenormalizeButtonPushed, true);
            app.RenormalizeButton.Enable = 'off';
            app.RenormalizeButton.Text = 'Renormalize';
            app.PlotTICButton = uibutton(app.UIFigure, 'push');
            app.PlotTICButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTICButtonPushed, true);
            app.PlotTICButton.Enable = 'off';
            app.PlotTICButton.Text = 'Plot TIC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunEqual

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end