classdef RunPartialarearoi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MSIdataDropDownLabel      matlab.ui.control.Label
        MSIdataDropDown           matlab.ui.control.DropDown
        RectangleSelectionButton  matlab.ui.control.Button
        ROIButton                 matlab.ui.control.Button
        PartialareaROILabel       matlab.ui.control.Label
        FreeselectionButton       matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            noFiles=evalin('base','importMSv.load.noFiles;');
            for h=1:noFiles
                name=evalin('base',['importMSv.summary.img_',num2str(h),'.name_short;']);
                llistnnc=name;
                llistannc(h)={llistnnc};
            end
            app.MSIdataDropDown.Items=llistannc;
            
            runpartialareaROI=evalin('base','importMSv.summary.img_1.runpartialareaROI;');
            if runpartialareaROI==1
                % 初始化
                evalin('base','importMSv.Partialarea.currentFile=1;');
                for i=1:noFiles
                    evalin('base',['importMSv.Partialarea.img_',num2str(i),'.MSicut=1;']);
                    evalin('base',['importMSv.summary.img_',num2str(i),'.runpartialareaROI=2;']);
                end
            else
                try
                    currentFile=evalin('base','importMSv.Partialarea.currentFile;');
                    colorll=evalin('base','importMSv.summary.color;');
                    MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
                    if MSicut==2
                        k=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.k;']);
                        noreg=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.noreg;']);
                        B=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.B;']);
                        mascara=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.mascara;']);
                        
                        mascara=set_range(mascara,4,0.95);
                        
                        imagesc(app.UIAxes,mascara);
                        axis (app.UIAxes,'tight','image');colormap(app.UIAxes,colorll);colorbar(app.UIAxes)
                        for i=1:(noreg-1)
                            m=B{i}(1,:);
                            n=B{i}(2,:);
                            kk=k(i);
                            for ii=2:(kk-1)
                                line(app.UIAxes,[m(ii-1) m(ii)],[n(ii-1) n(ii)],'color','r','LineWidth',2);
                            end
                            line(app.UIAxes,[m(kk-1) m(1)],[n(kk-1) n(1)],'color','r','LineWidth',2);
                        end
                    elseif MSicut==3
                        row=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.row;']);
                        col=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.col;']);
                        high=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.high;']);
                        width=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.width;']);
                        mascara=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.mascara;']);
                        
                        mascara=set_range(mascara,4,0.95);
                        
                        imagesc(app.UIAxes,mascara);
                        axis (app.UIAxes,'tight','image');colormap(app.UIAxes,colorll);colorbar(app.UIAxes)
                        line(app.UIAxes,[col,col],[row,row+high],'color','r','LineWidth',2)
                        line(app.UIAxes,[col,col+width],[row,row],'color','r','LineWidth',2)
                        line(app.UIAxes,[col+width,col+width],[row,row+high],'color','r','LineWidth',2)
                        line(app.UIAxes,[col,col+width],[row+high,row+high],'color','r','LineWidth',2)
                    end
                    
                catch
                end
            end
        end

        % Value changed function: MSIdataDropDown
        function MSIdataDropDownValueChanged(app, event)
            app.MSIdataDropDown.ItemsData = 1:length(app.MSIdataDropDown.Items);
            currentFile=app.MSIdataDropDown.Value;
            
            assignin('base','currentFile',currentFile)
            evalin('base','importMSv.Partialarea.currentFile=currentFile;');
            evalin("base",'clear currentFile;');
            
            cla(app.UIAxes,"reset");
            app.UIAxes.XTick=[];
            app.UIAxes.YTick=[];
            
            colorll=evalin('base','importMSv.summary.color;');
            MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
            if MSicut==2
                k=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.k;']);
                noreg=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.noreg;']);
                B=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.B;']);
                mascara=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.mascara;']);
                
                mascara=set_range(mascara,4,0.95);
                
                imagesc(app.UIAxes,mascara);
                axis (app.UIAxes,'tight','image');colormap(app.UIAxes,colorll);colorbar(app.UIAxes)
                for i=1:(noreg-1)
                    m=B{i}(1,:);
                    n=B{i}(2,:);
                    kk=k(i);
                    for ii=2:(kk-1)
                        line(app.UIAxes,[m(ii-1) m(ii)],[n(ii-1) n(ii)],'color','r','LineWidth',2);
                    end
                    line(app.UIAxes,[m(kk-1) m(1)],[n(kk-1) n(1)],'color','r','LineWidth',2);
                end
            elseif MSicut==3
                row=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.row;']);
                col=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.col;']);
                high=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.high;']);
                width=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.width;']);
                mascara=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.mascara;']);
                
                mascara=set_range(mascara,4,0.95);
                
                imagesc(app.UIAxes,mascara);
                axis (app.UIAxes,'tight','image');colormap(app.UIAxes,colorll);colorbar(app.UIAxes)
                line(app.UIAxes,[col,col],[row,row+high],'color','r','LineWidth',2)
                line(app.UIAxes,[col,col+width],[row,row],'color','r','LineWidth',2)
                line(app.UIAxes,[col+width,col+width],[row,row+high],'color','r','LineWidth',2)
                line(app.UIAxes,[col,col+width],[row+high,row+high],'color','r','LineWidth',2)
            end
        end

        % Button pushed function: FreeselectionButton
        function FreeselectionButtonPushed(app, event)
            currentFile=evalin('base','importMSv.Partialarea.currentFile;');
            MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
            dataPreview=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.dataPreview;']);
            
            if dataPreview==2
                mascara=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mascara']);
                Indexes=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.Indexes']);
                Indexesii=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.Indexesii']);
            else
                if MSicut==1
                    tic=GetTic(currentFile);
                    assignin('base','tic',tic);
                    evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.tic=tic;']);
                    evalin('base','clear tic');
                else
                    tic=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.tic;']);
                end
                
                xpix=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.xpixels']);
                ypix=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.ypixels']);
                [mascara,Indexes,Indexesii]=cut(tic,xpix,ypix);
                
            end
            try
                [out_roi,k,B,noreg,mask] = Free_select_single(mascara,Indexes);
                
                % 裁剪
                zerocol=find(sum(mask,1)==0);
                zerorow=find(sum(mask,2)==0);
                mask(:,zerocol)=[];
                mask(zerorow,:)=[];
                
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut=2;']);
                
                assignin('base','mascara',mascara);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.mascara=mascara;']);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mascara=mascara;']);
                
                assignin('base','Indexes',Indexes);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.Indexes=Indexes;']);
                
                assignin('base','Indexesii',Indexesii);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.Indexesii=Indexesii;']);
                
                assignin('base','out_roi',out_roi);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.out_roi=out_roi;']);
                
                assignin('base','mask',mask);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.mask=mask;']);
                
                assignin('base','k',k);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.k=k;']);
                
                assignin('base','B',B);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.B=B;']);
                
                assignin('base','noreg',noreg);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.noreg=noreg;']);
                
                evalin('base','clear mascara Indexes Indexesii out_roi k B noreg mask;');
                %
                colorll=evalin('base','importMSv.summary.color;');
                % 设置最大显示范围
                mascara=set_range(mascara,4,0.95);
                
                imagesc(app.UIAxes,mascara);
                axis (app.UIAxes,'tight','image')
                colormap(app.UIAxes,colorll);
                colorbar(app.UIAxes)
                for i=1:(noreg-1)
                    m=B{i}(1,:);
                    n=B{i}(2,:);
                    kk=k(i);
                    for ii=2:(kk-1)
                        line(app.UIAxes,[m(ii-1) m(ii)],[n(ii-1) n(ii)],'color','r','LineWidth',2);
                    end
                    line(app.UIAxes,[m(kk-1) m(1)],[n(kk-1) n(1)],'color','r','LineWidth',2);
                end
            catch
            end
        end

        % Button pushed function: RectangleSelectionButton
        function RectangleSelectionButtonPushed(app, event)
            currentFile=evalin('base','importMSv.Partialarea.currentFile;');
            MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
            dataPreview=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.dataPreview;']);
            
            if dataPreview==2
                mascara=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mascara']);
                Indexes=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.Indexes']);
                Indexesii=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.Indexesii']);
            else
                if MSicut==1
                    tic=GetTic(currentFile);
                    assignin('base','tic',tic);
                    evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.tic=tic;']);
                    evalin('base','clear tic');
                else
                    tic=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.tic;']);
                end
                xpix=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.xpixels']);
                ypix=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.ypixels']);
                [mascara,Indexes,Indexesii]=cut(tic,xpix,ypix);
            end
            
            
            try
                [out_roi,row,col,high,width] = Rectangle_select_single(mascara,Indexes);
                
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut=3;']);
                
                assignin('base','mascara',mascara);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.mascara=mascara;']);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mascara=mascara;']);
                
                assignin('base','Indexes',Indexes);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.Indexes=Indexes;']);
                
                assignin('base','Indexesii',Indexesii);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.Indexesii=Indexesii;']);
                
                assignin('base','out_roi',out_roi);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.out_roi=out_roi;']);
                
                assignin('base','row',row);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.row=row;']);
                
                assignin('base','col',col);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.col=col;']);
                
                assignin('base','high',high);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.high=high;']);
                
                assignin('base','width',width);
                evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.width=width;']);
                
                
                evalin('base','clear mascara Indexes Indexesii out_roi row col high width;');
                
                colorll=evalin('base','importMSv.summary.color;');
                % 设置最大显示范围
                mascara=set_range(mascara,4,0.95);
                
                imagesc(app.UIAxes,mascara);
                axis (app.UIAxes,'tight','image')
                colormap(app.UIAxes,colorll);
                colorbar(app.UIAxes)
                line(app.UIAxes,[col,col],[row,row+high],'color','r','LineWidth',2)
                line(app.UIAxes,[col,col+width],[row,row],'color','r','LineWidth',2)
                line(app.UIAxes,[col+width,col+width],[row,row+high],'color','r','LineWidth',2)
                line(app.UIAxes,[col,col+width],[row+high,row+high],'color','r','LineWidth',2)
            catch
            end
        end

        % Button pushed function: ROIButton
        function ROIButtonPushed(app, event)
            RunEqual
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MSIdataDropDownLabel = uilabel(app.UIFigure);
            app.MSIdataDropDownLabel.Text = 'MSI data';
            app.MSIdataDropDown = uidropdown(app.UIFigure);
            app.MSIdataDropDown.Items = {};
            app.MSIdataDropDown.ValueChangedFcn = createCallbackFcn(app, @MSIdataDropDownValueChanged, true);
            app.MSIdataDropDown.Value = {};
            app.RectangleSelectionButton = uibutton(app.UIFigure, 'push');
            app.RectangleSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @RectangleSelectionButtonPushed, true);
            app.RectangleSelectionButton.Text = 'Rectangle Selection';
            app.ROIButton = uibutton(app.UIFigure, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Text = 'ROI';
            app.PartialareaROILabel = uilabel(app.UIFigure);
            app.PartialareaROILabel.Text = 'Partial area ROI';
            app.FreeselectionButton = uibutton(app.UIFigure, 'push');
            app.FreeselectionButton.ButtonPushedFcn = createCallbackFcn(app, @FreeselectionButtonPushed, true);
            app.FreeselectionButton.Text = 'Free selection';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunPartialarearoi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end