classdef RunComperelist < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        ThethresholdofthedifferenceEditFieldLabel  matlab.ui.control.Label
        ThethresholdofthedifferenceEditField  matlab.ui.control.EditField
        MasserrortoleranceEditFieldLabel  matlab.ui.control.Label
        MasserrortoleranceEditField    matlab.ui.control.EditField
        ppmLabel                       matlab.ui.control.Label
        EnterButton                    matlab.ui.control.Button
        ExportpictureButton            matlab.ui.control.Button
        CompereListLabel               matlab.ui.control.Label
        SortbyDropDownLabel            matlab.ui.control.Label
        SortbyDropDown                 matlab.ui.control.DropDown
        AreabsolutevaluesusedCheckBox  matlab.ui.control.CheckBox
        FilterbyratioCheckBox          matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             evalin('base','importMSv.compare.sortvalue=1;');
             evalin('base','importMSv.compare.absvalue=0;');
             evalin('base','importMSv.compare.ratiovalue=0;');
        end

        % Value changed function: SortbyDropDown
        function SortbyDropDownValueChanged(app, event)
            app.SortbyDropDown.ItemsData = 1:3;
            sortvalue = app.SortbyDropDown.Value;
            assignin('base','sortvalue',sortvalue);
            evalin('base','importMSv.compare.sortvalue=sortvalue;');
            evalin('base','clear sortvalue;');
        end

        % Value changed function: AreabsolutevaluesusedCheckBox
        function AreabsolutevaluesusedCheckBoxValueChanged(app, event)
            absvalue = app.AreabsolutevaluesusedCheckBox.Value;
            assignin('base','absvalue',absvalue);
            evalin('base','importMSv.compare.absvalue=absvalue;');
            evalin('base','clear absvalue;');
        end

        % Value changed function: FilterbyratioCheckBox
        function FilterbyratioCheckBoxValueChanged(app, event)
            ratiovalue = app.FilterbyratioCheckBox.Value;
            assignin('base','ratiovalue',ratiovalue);
            evalin('base','importMSv.compare.ratiovalue=ratiovalue;');
            evalin('base','clear ratiovalue;');
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            sortvalue=evalin('base','importMSv.compare.sortvalue;');
            absvalue=evalin('base','importMSv.compare.absvalue;');
            ratiovalue=evalin('base','importMSv.compare.ratiovalue;');
            
            No1=evalin("base",'importMSv.compare.name.No1');
            No2=evalin("base",'importMSv.compare.name.No2');
            
            x=evalin("base",'importMSv.compare.mz.x');
            mean1=evalin('base',['importMSv.compare.save_list.list', No1,'.mean_y;']);
            mean2=evalin('base',['importMSv.compare.save_list.list', No2,'.mean_y;']);
            
            mzerror=str2double(app.MasserrortoleranceEditField.Value);
            MSth=str2double(app.ThethresholdofthedifferenceEditField.Value);
            
            list=[x;mean1;mean2;(mean1-mean2)]';
            asd = sortrows(list, -4, 'ComparisonMethod' ,'abs');
            asd(asd(:,4)==0,:)=[];
            
            
            [m,~]=size(asd);
            list1=[];
            for i=1:m
                if asd(i,2)>0
                    delta=10^-6*mzerror*asd(i,1);
                    ieq=find(abs(asd(:,1)-asd(i,1))<=delta);
                    list1=[list1;[asd(i,1),sum(asd(ieq,2)),sum(asd(ieq,3)),sum(asd(ieq,4)),sum(asd(ieq,3))/sum(asd(ieq,2))]];
                    asd(ieq,:)=0;
                end
            end
            
            list1(list1(:,4)==0,:)=[];
            
            if sortvalue==1
                if absvalue==1
                    list1= sortrows(list1,-4,'ComparisonMethod' ,'abs');
                else
                    list1= sortrows(list1,-4);
                end
            elseif sortvalue==2
                list1= sortrows(list1,1);
            elseif sortvalue==3
                list1= sortrows(list1,5);
            end
            
            if ratiovalue==0
                if absvalue==1
                    list1(abs(list1(:,4))<=MSth,:)=[];
                else
                    list1(list1(:,4)<=MSth,:)=[];
                end
            else
                list1(list1(:,5)>=MSth,:)=[];
            end
            
            vars={'mz',['list',No1],['list',No2],'Difference value',['list',No2,' / ','list',No1]};
            app.UITable.Data=list1;
            app.UITable.ColumnName=vars;
            
            assignin('base','Compere_list',list1);
        end

        % Button pushed function: ExportpictureButton
        function ExportpictureButtonPushed(app, event)
            list=evalin('base','Compere_list;');
            list= sortrows(list,1);
            %{
            画差异谱
            list= sortrows(list,1);
            [xx,yy]=SupplementZero(list(:,1)',abs(list(:,2))');
            figure,plot(xx,yy,'LineWidth',2);
            %}
            No1=evalin("base",'importMSv.compare.name.No1');
            No2=evalin("base",'importMSv.compare.name.No2');
            currentFile1=evalin('base',['importMSv.compare.save_list.list', No1,'.currentFile;']);
            currentFile2=evalin('base',['importMSv.compare.save_list.list', No2,'.currentFile;']);
            mzroi=evalin('base','importMSv.ROIplotGUI.current_mzroi');
            colorll=evalin('base','importMSv.summary.color;');
            mzerror=str2double(app.MasserrortoleranceEditField.Value);
            set(gcf, 'Visible', 'off');
            mz_selet=list(:,1);
            mzlength=length(mz_selet);
            
            % 选择保存的文件夹
            savepath = uigetdir;
            
            % 保存
            if currentFile1==currentFile2
                x_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile1),'.x_cut;']);
                y_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile1),'.y_cut;']);
                MSroi=evalin("base",['importMSv.compare.img_',num2str(currentFile1),'.y;']);
                name=evalin('base',['importMSv.summary.img_',num2str(currentFile1),'.name_short;']);
                for h=1:mzlength
                    % 取精确度范围
                    mz=mz_selet(h);
                    delta=10^-6*mzerror*mz;
                    ieq=find(abs(mzroi-mz)<=delta);
                    
                    %取数据
                    closeallhide;
                    set(gcf, 'Visible', 'off');
                    original_data=sum(MSroi(:,ieq),2);
                    data=set_range(original_data,4,0.95);
                    imagesc(reshape(data,x_pixels,y_pixels));
                    axis('tight','image') ;colormap(colorll);colorbar;% sprintf将数据格式化
                    title({strcat('name : ',name);['m/z : ',num2str(mz),' ± ',num2str(sprintf('%0.4f',delta)),' Th',' (',num2str(mzerror),'ppm)']},'Interpreter','none');
                    
                    % 保存
                    save_image=frame2im(getframe(gcf));
                    %save_image = getframe(gca).cdata;
                    imwrite(save_image,[savepath,'\',strrep(num2str(mz), '.', '_'),'.png'] );
                    disp(['***********************Saved to ',num2str(h),'/',num2str(mzlength),'*****************************'])
                end
                
            else
                x_pixels1=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile1),'.x_cut;']);
                x_pixels2=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile2),'.x_cut;']);
                y_pixels1=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile1),'.y_cut;']);
                y_pixels2=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile2),'.y_cut;']);
                MSroi1=evalin("base",['importMSv.compare.img_',num2str(currentFile1),'.y;']);
                MSroi2=evalin("base",['importMSv.compare.img_',num2str(currentFile2),'.y;']);
                name1=evalin('base',['importMSv.summary.img_',num2str(currentFile1),'.name_short;']);
                name2=evalin('base',['importMSv.summary.img_',num2str(currentFile2),'.name_short;']);
                for h=1:mzlength
                    % 取精确度范围
                    mz=mz_selet(h);
                    delta=10^-6*mzerror*mz;
                    ieq=find(abs(mzroi-mz)<=delta);
                    
                    % 取No1数据
                    closeallhide;
                    set(gcf, 'Visible', 'off');
                    original_data1=sum(MSroi1(:,ieq),2);
                    data1=set_range(original_data1,4,0.95);
                    imagesc(reshape(data1,x_pixels1,y_pixels1));
                    axis('tight','image') ;colormap(colorll);colorbar;
                    title({strcat('name : ',name1);['m/z : ',num2str(mz),' ± ',num2str(sprintf('%0.4f',delta)),' Th',' (',num2str(mzerror),'ppm)']},'Interpreter','none');
                    im1=frame2im(getframe(gcf));
                    
                    % 取No2数据
                    closeallhide;
                    set(gcf, 'Visible', 'off');
                    original_data2=sum(MSroi2(:,ieq),2);
                    data2=set_range(original_data2,4,0.95);
                    imagesc(reshape(data2,x_pixels2,y_pixels2));
                    axis('tight','image') ;colormap(colorll);colorbar;
                    title({strcat('name : ',name2);['m/z : ',num2str(mz),' ± ',num2str(sprintf('%0.4f',delta)),' Th',' (',num2str(mzerror),'ppm)']},'Interpreter','none');
                    im2=frame2im(getframe(gcf));
                    
                    % 保存
                    save_imagee=[im1,im2];
                    imwrite(save_imagee,[savepath,'\',strrep(num2str(mz), '.', '_'),'.png'] );
                    disp(['***********************Saved to ',num2str(h),'/',num2str(mzlength),'*****************************'])
                end
            end
            writematrix(list,[savepath,'\list.txt']);
            disp('*********************** FINISHED *****************************')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'};
            app.UITable.RowName = {};
            app.ThethresholdofthedifferenceEditFieldLabel = uilabel(app.UIFigure);
            app.ThethresholdofthedifferenceEditFieldLabel.Text = 'The threshold of the difference';
            app.ThethresholdofthedifferenceEditField = uieditfield(app.UIFigure, 'text');
            app.ThethresholdofthedifferenceEditField.Value = '2';
            app.MasserrortoleranceEditFieldLabel = uilabel(app.UIFigure);
            app.MasserrortoleranceEditFieldLabel.Text = 'Mass error tolerance';
            app.MasserrortoleranceEditField = uieditfield(app.UIFigure, 'text');
            app.MasserrortoleranceEditField.Value = '10';
            app.ppmLabel = uilabel(app.UIFigure);
            app.ppmLabel.Text = 'ppm';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ExportpictureButton = uibutton(app.UIFigure, 'push');
            app.ExportpictureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportpictureButtonPushed, true);
            app.ExportpictureButton.Text = 'Export picture';
            app.CompereListLabel = uilabel(app.UIFigure);
            app.CompereListLabel.Text = 'Compere List';
            app.SortbyDropDownLabel = uilabel(app.UIFigure);
            app.SortbyDropDownLabel.Text = 'Sort by';
            app.SortbyDropDown = uidropdown(app.UIFigure);
            app.SortbyDropDown.Items = {'default: value', 'm/z', 'ratio'};
            app.SortbyDropDown.ValueChangedFcn = createCallbackFcn(app, @SortbyDropDownValueChanged, true);
            app.SortbyDropDown.Value = 'default: value';
            app.AreabsolutevaluesusedCheckBox = uicheckbox(app.UIFigure);
            app.AreabsolutevaluesusedCheckBox.ValueChangedFcn = createCallbackFcn(app, @AreabsolutevaluesusedCheckBoxValueChanged, true);
            app.AreabsolutevaluesusedCheckBox.Text = 'Are absolute values used?';
            app.FilterbyratioCheckBox = uicheckbox(app.UIFigure);
            app.FilterbyratioCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterbyratioCheckBoxValueChanged, true);
            app.FilterbyratioCheckBox.Text = 'Filter by ''ratio''';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunComperelist

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end