classdef Runcompare < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RectangleSelectionButton       matlab.ui.control.Button
        FreeselectionButton            matlab.ui.control.Button
        CorrespondingdistributionButton  matlab.ui.control.Button
        AveragespectraofthechosenareaLabel  matlab.ui.control.Label
        NoEditField                    matlab.ui.control.EditField
        VSNoEditField                  matlab.ui.control.EditField
        CompareButton                  matlab.ui.control.Button
        NoLabel                        matlab.ui.control.Label
        VSNoLabel                      matlab.ui.control.Label
        LabraryCheckBox                matlab.ui.control.CheckBox
        RedisplaycurrentROIareaButton  matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        NoLabel_2                      matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        NoLabel_3                      matlab.ui.control.Label
        EditField_2                    matlab.ui.control.EditField
        RedisplayButton                matlab.ui.control.Button
        UsemzCheckBox                  matlab.ui.control.CheckBox
        EditField_3                    matlab.ui.control.EditField
        SignalintensitythresholdLabel  matlab.ui.control.Label
        EditField_4                    matlab.ui.control.EditField
        EnterButton                    matlab.ui.control.Button
        ExportlistButton               matlab.ui.control.Button
        NoEditField_2Label             matlab.ui.control.Label
        NoEditField_2                  matlab.ui.control.EditField
        FluxscreeningButton            matlab.ui.control.Button
        MSIdataDropDownLabel           matlab.ui.control.Label
        MSIdataDropDown                matlab.ui.control.DropDown
        UsetheregisteredimageCheckBox  matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 导入数据
            runcompare=evalin('base','importMSv.summary.runcompare;');
            noFiles=evalin('base','importMSv.load.noFiles;');
            
            % 初始化
            for i=1:noFiles
                MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(i),'.MSroi;']);
                mascara=evalin('base',['importMSv.ROIplotGUI.img_',num2str(i),'.mascara;']);
                indexes=evalin('base',['importMSv.ROIplotGUI.img_',num2str(i),'.indexes;']);
                
                runpartialareaROI=evalin('base',['importMSv.summary.img_',num2str(i),'.runpartialareaROI;']);
                if runpartialareaROI==2
                    MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(i),'.MSicut;']);
                    if MSicut==2
                        mask=evalin('base',['importMSv.Partialarea.img_',num2str(i),'.mask;']);
                        x_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(i),'.x_cut;']);
                        y_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(i),'.y_cut;']);
                        
                        mascaran=reshape(double(mask),[],1);
                        loc=find(mascaran~=0);
                        
                        [~,nn]=size(MSroi);
                        mm=x_pixels*y_pixels;
                        
                        mascarann=sparse(mm,nn);
                        mascarann(loc,:)=MSroi;
                        MSroi=mascarann;
                    end
                end
                
                
                y=MSroi;
                re_y = mascara;
                sum_y = reshape(mascara,[],1);
                
                assignin('base','sum_y',sum_y);
                evalin('base',['importMSv.compare.img_',num2str(i),'.sum_y=sum_y;']);
                
                assignin('base','re_y',re_y);
                evalin('base',['importMSv.compare.img_',num2str(i),'.re_y=re_y;']);
                
                assignin('base','y',y);
                evalin('base',['importMSv.compare.img_',num2str(i),'.y=y;']);
                
                assignin('base','indexes',indexes);
                evalin('base',['importMSv.compare.img_',num2str(i),'.indexes=indexes;']);
                
                evalin('base','importMSv.compare.libraryvalue=0;');
                
                evalin('base','clear sum_y re_y y indexes');
            end
            evalin('base','importMSv.compare.mz.x=mzroi;');
            
            if runcompare==2
                app.RedisplaycurrentROIareaButton.Enable='on';
                app.RedisplayButton.Enable='on';
                app.EditField_2.Editable='on';
            end
            %选择框
            noFiles=evalin('base','importMSv.load.noFiles;');
            for h=1:noFiles
                name=evalin('base',['importMSv.summary.img_',num2str(h),'.name_short;']);
                llistnnc=name;
                llistannc(h)={llistnnc};
            end
            app.MSIdataDropDown.Items=llistannc;
            evalin('base','importMSv.compare.currentFile=1;');
            evalin('base','importMSv.summary.runcompare=2;');
            evalin('base','importMSv.compare.Umzvalue=0;');
            evalin('base','importMSv.compare.peizhunvalue=0;');
        end

        % Value changed function: MSIdataDropDown
        function MSIdataDropDownValueChanged(app, event)
            app.MSIdataDropDown.ItemsData = 1:length(app.MSIdataDropDown.Items);
            currentFile=app.MSIdataDropDown.Value;
            try
                xx=evalin('base',['importMSv.compare.img_',num2str(currentFile),'.Supplementx;']);
                yy=evalin('base',['importMSv.compare.img_',num2str(currentFile),'.Supplementy;']);
                Pho=plot(app.UIAxes,xx,yy);
                assignin('base','Pho',Pho);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.Pho=Pho;']);
                evalin('base','clear Pho;');
                app.RedisplaycurrentROIareaButton.Enable='on';
            catch
                cla(app.UIAxes)
                app.RedisplaycurrentROIareaButton.Enable='off';
            end
            
            assignin('base','currentFile',currentFile)
            evalin('base','importMSv.compare.currentFile=currentFile;');
            evalin("base",'clear currentFile;');
        end

        % Value changed function: UsemzCheckBox
        function UsemzCheckBoxValueChanged(app, event)
            Umzvalue = app.UsemzCheckBox.Value;
            if Umzvalue==1
                app.EditField_3.Editable='on';
            else
                app.EditField_3.Editable='off';
            end
            
            assignin('base','Umzvalue',Umzvalue)
            evalin('base','importMSv.compare.Umzvalue=Umzvalue;');
            evalin("base",'clear Umzvalue;');
        end

        % Value changed function: UsetheregisteredimageCheckBox
        function UsetheregisteredimageCheckBoxValueChanged(app, event)
            peizhunvalue = app.UsetheregisteredimageCheckBox.Value;
            assignin('base','peizhunvalue',peizhunvalue)
            evalin('base','importMSv.compare.peizhunvalue=peizhunvalue;');
            evalin("base",'clear peizhunvalue;');
        end

        % Button pushed function: FreeselectionButton
        function FreeselectionButtonPushed(app, event)
            %自由选取
            currentFile=evalin('base','importMSv.compare.currentFile;');
            Umzvalue=evalin('base','importMSv.compare.Umzvalue;');
            peizhunvalue=evalin('base','importMSv.compare.peizhunvalue;');
            evalin('base',['importMSv.compare.img_',num2str(currentFile),'.MSicut=2;']);
            x=evalin("base",'importMSv.compare.mz.x;');
            y=evalin("base",['importMSv.compare.img_',num2str(currentFile),'.y;']);
            exits=0;
            
            % 展示图片
            if peizhunvalue==1
                data=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.finalre;']);
            else
                if Umzvalue==1
                    x_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.x_cut;']);
                    y_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.y_cut;']);
                    %MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
                    
                    mz=str2double(app.EditField_3.Value);
                    [~,n]=min(abs(x-mz));
                    mz_value=x(n);
                    delta=5^-6*10*mz_value;% 10ppm
                    ieq=find(abs(x-mz_value)<=delta);
                    original_data=sum(y(:,ieq),2);
                    data=reshape(original_data,x_pixels,y_pixels);
                    data=set_range(data,4,0.95);
                else
                    data=evalin("base",['importMSv.compare.img_',num2str(currentFile),'.re_y;']);
                    data=set_range(data,4,0.95);
                end
            end
            
            
            % roi区域选择
            try
                [cidx,kk,B,noreg] = Free_select(data);
                exits=1;
            catch
            end
            
            
            if exits==1
                re_cidx = reshape(cidx,[],1);
                Locb = find(re_cidx == 1);
                Y=y(Locb,:);
                mean_y = full(mean(Y));
                
                % 加
                deloc=[];
                runpartialareaROI=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.runpartialareaROI;']);
                if runpartialareaROI==2
                    freecut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
                    if freecut==2
                        mask=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.mask;']);
                        mascaran=reshape(double(mask),[],1);
                        deloc=find(mascaran==0);
                    end
                end
                deloc=sort([deloc;Locb]);
                olocb=(1:size(y,1))';
                olocb(deloc,:)=[];
                
                %olocb= find(re_cidx == 0);
                oY=y(olocb,:);
                omean_y = full(mean(oY));
                
                list=[x;mean_y]';
                
                [xx,yy]=SupplementZero(x,mean_y);
                
                Pho=plot(app.UIAxes,xx,yy);
                figure('visible','off');
                
                assignin('base','Pho',Pho);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.Pho=Pho;']);
                
                assignin('base','xx',xx);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.Supplementx=xx;']);
                
                assignin('base','yy',yy);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.Supplementy=yy;']);
                
                assignin('base','mean_y',mean_y);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.mean_y=mean_y;']);
                
                assignin('base','mean_list',list);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.list=mean_list;']);
                
                assignin('base','kk',kk);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.k=kk;']);
                
                assignin('base','B',B);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.B=B;']);
                
                assignin('base','noreg',noreg);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.noreg=noreg;']);
                
                % 加
                assignin('base','Y',Y);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.Y=Y;']);
                
                assignin('base','oY',oY);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.oY=oY;']);
                
                assignin('base','omean_y',omean_y);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.omean_y=omean_y;']);
                
                assignin('base','Locb',Locb);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.Locb=Locb;']);
                
                %                 assignin('base','olocb',olocb);
                %                 evalin('base',['importMSv.compare.img_',num2str(currentFile),'.olocb=olocb;']);
                
                assignin('base','cidx',cidx);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.cidx=cidx;']);
                
                
                evalin('base','clear mean_y xx yy Pho kk B noreg Y oY omean_y Locb olocb cidx;');
                app.RedisplaycurrentROIareaButton.Enable='on';
            end
        end

        % Button pushed function: RectangleSelectionButton
        function RectangleSelectionButtonPushed(app, event)
            % 矩形选取
            currentFile=evalin('base','importMSv.compare.currentFile;');
            Umzvalue=evalin('base','importMSv.compare.Umzvalue;');
            peizhunvalue=evalin('base','importMSv.compare.peizhunvalue;');
            evalin('base',['importMSv.compare.img_',num2str(currentFile),'.MSicut=3;']);
            x=evalin("base",'importMSv.compare.mz.x');
            y=evalin("base",['importMSv.compare.img_',num2str(currentFile),'.y;']);
            indexes=evalin('base',['importMSv.compare.img_',num2str(currentFile),'.indexes;']);
            exits=0;
            
            if peizhunvalue==1
                data=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.finalre;']);
            else
                if Umzvalue==1
                    x_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.x_cut;']);
                    y_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.y_cut;']);
                    %MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
                    
                    mz=str2double(app.EditField_3.Value);
                    [~,n]=min(abs(x-mz));
                    mz_value=x(n);
                    delta=5^-6*10*mz_value;% 10ppm
                    ieq=find(abs(x-mz_value)<=delta);
                    original_data=sum(y(:,ieq),2);
                    data=reshape(original_data,x_pixels,y_pixels);
                    data=set_range(data,4,0.95);
                else
                    data=evalin("base",['importMSv.compare.img_',num2str(currentFile),'.re_y;']);
                    data=set_range(data,4,0.95);
                end
            end
            
            % roi区域选择
            try
                [cidx,row,col,high,width] = Rectangle_select(data,indexes);
                exits=1;
            catch
            end
            
            if exits==1
                re_cidx = reshape(cidx,[],1);
                re_indexes=reshape(indexes,[],1);
                [~,Locb] = ismember(re_cidx,re_indexes);
                
                Y=y(Locb,:);
                mean_y =full(mean(Y)) ;
                
                % 加
                deloc=[];
                runpartialareaROI=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.runpartialareaROI;']);
                if runpartialareaROI==2
                    freecut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
                    if freecut==2
                        mask=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.mask;']);
                        mascaran=reshape(double(mask),[],1);
                        deloc=find(mascaran==0);
                    end
                end
                deloc=sort([deloc;Locb]);
                olocb=(1:size(y,1))';
                olocb(deloc,:)=[];
                
                %olocb=setxor(Locb,re_indexes);
                oY=y(olocb,:);
                omean_y = full(mean(oY));
                
                
                list=[x;mean_y]';
                
                [xx,yy]=SupplementZero(x,mean_y);
                
                Pho=plot(app.UIAxes,xx,yy);
                figure('visible','off');
                
                assignin('base','Pho',Pho);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.Pho=Pho;']);
                
                assignin('base','xx',xx);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.Supplementx=xx;']);
                
                assignin('base','yy',yy);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.Supplementy=yy;']);
                
                assignin('base','mean_y',mean_y);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.mean_y=mean_y;']);
                
                assignin('base','mean_list',list);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.list=mean_list;']);
                
                assignin('base','row',row);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.row=row;']);
                
                assignin('base','col',col);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.col=col;']);
                
                assignin('base','high',high);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.high=high;']);
                
                assignin('base','width',width);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.width=width;']);
                
                % 加
                assignin('base','Y',Y);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.Y=Y;']);
                
                assignin('base','oY',oY);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.oY=oY;']);
                
                assignin('base','omean_y',omean_y);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.omean_y=omean_y;']);
                
                assignin('base','Locb',Locb);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.Locb=Locb;']);
                
                %                 assignin('base','olocb',olocb);
                %                 evalin('base',['importMSv.compare.img_',num2str(currentFile),'.olocb=olocb;']);
                
                assignin('base','cidx',cidx);
                evalin('base',['importMSv.compare.img_',num2str(currentFile),'.cidx=cidx;']);
                
                evalin('base','clear mean_y xx yy Pho row col high width Y oY omean_y Locb olocb cidx;');
                app.RedisplaycurrentROIareaButton.Enable='on';
            end
        end

        % Button pushed function: CorrespondingdistributionButton
        function CorrespondingdistributionButtonPushed(app, event)
            %对应分布
            currentFile=evalin('base','importMSv.compare.currentFile;');
            x=evalin("base",'importMSv.compare.mz.x');
            Pho=evalin('base',['importMSv.compare.img_',num2str(currentFile),'.Pho;']);
            colorll=evalin('base','importMSv.summary.color;');
            
            sizeaChildren=size(Pho.Children,1);
            xtip=zeros(sizeaChildren,1);
            for k=1:size(Pho.Children,1)
                xtip(k)=Pho.Children(k).X;
            end
            [mm,~]=size(xtip);
            
            libraryvalue=evalin('base','importMSv.compare.libraryvalue;');
            if libraryvalue==1
                library=evalin('base','importMSv.library.database;');
                col=evalin('base','importMSv.library.mzcol;');
                matching_results = find_mz_info(xtip, 1, library, col);
                assignin('base','matching_results',matching_results);
                evalin('base','importMSv.compare.matching_results=matching_results;');
            end
            
            %画图
            closeallhide
            %MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
            y=evalin("base",['importMSv.compare.img_',num2str(currentFile),'.y;']);
            x_cut=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.x_cut;']);
            y_cut=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.y_cut;']);
            for ii=1:mm
                a=find(x==xtip(ii,1));
                MM=y(:,a);
                I=set_range(MM,4,0.95);
                
                figure,imagesc(reshape(I,x_cut,y_cut));
                axis ('tight','image');colormap(colorll);colorbar;
                mz=xtip(ii,1);
                title({['m/z = ',num2str(mz)];''});
                set(gca,'FontSize',16);
            end
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            %数据对比
            No1 = app.NoEditField.Value;
            No2 = app.VSNoEditField.Value;
            
            assignin('base','No1',No1);
            evalin('base','importMSv.compare.name.No1=No1;');
            
            assignin('base','No2',No2);
            evalin('base','importMSv.compare.name.No2=No2;');
            
            evalin('base','clear No1 No2;');
            RunComperelist
        end

        % Value changed function: LabraryCheckBox
        function LabraryCheckBoxValueChanged(app, event)
            libraryvalue = app.LabraryCheckBox.Value;
            assignin('base','libraryvalue',libraryvalue);
            evalin('base','importMSv.compare.libraryvalue=libraryvalue;');
            evalin('base','clear libraryvalue;');
        end

        % Button pushed function: RedisplaycurrentROIareaButton
        function RedisplaycurrentROIareaButtonPushed(app, event)
            currentFile=evalin('base','importMSv.compare.currentFile;');
            Umzvalue=evalin('base','importMSv.compare.Umzvalue;');
            peizhunvalue=evalin('base','importMSv.compare.peizhunvalue;');
            MSicut=evalin('base',['importMSv.compare.img_',num2str(currentFile),'.MSicut;']);
            colorll=evalin('base','importMSv.summary.color;');
            
            if peizhunvalue==1
                mascara=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.finalre;']);
            else
                if Umzvalue==1
                    x=evalin("base",'importMSv.compare.mz.x;');
                    x_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.x_cut;']);
                    y_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.y_cut;']);
                    y=evalin("base",['importMSv.compare.img_',num2str(currentFile),'.y;']);
                    mz=str2double(app.EditField_3.Value);
                    [~,n]=min(abs(x-mz));
                    mz_value=x(n);
                    delta=10^-6*10*mz_value;
                    ieq=find(abs(x-mz_value)<=delta);
                    original_data=sum(y(:,ieq),2);
                    mascara=reshape(original_data,x_pixels,y_pixels);
                    mascara=set_range(mascara,4,0.95);
                else
                    mascara=evalin("base",['importMSv.compare.img_',num2str(currentFile),'.re_y;']);
                    mascara=set_range(mascara,4,0.95);
                end
            end
            
            
            if MSicut==2
                k=evalin('base',['importMSv.compare.img_',num2str(currentFile),'.k;']);
                noreg=evalin('base',['importMSv.compare.img_',num2str(currentFile),'.noreg;']);
                B=evalin('base',['importMSv.compare.img_',num2str(currentFile),'.B;']);
                
                figure;imagesc(mascara);
                axis ('tight','image');colormap(colorll);colorbar
                hold on
                for i=1:(noreg-1)
                    m=B{i}(1,:);
                    n=B{i}(2,:);
                    kk=k(i);
                    for ii=2:(kk-1)
                        line([m(ii-1) m(ii)],[n(ii-1) n(ii)],'color','r','LineWidth',2);
                    end
                    line([m(kk-1) m(1)],[n(kk-1) n(1)],'color','r','LineWidth',2);
                end
                hold off
            elseif MSicut==3
                row=evalin('base',['importMSv.compare.img_',num2str(currentFile),'.row;']);
                col=evalin('base',['importMSv.compare.img_',num2str(currentFile),'.col;']);
                high=evalin('base',['importMSv.compare.img_',num2str(currentFile),'.high;']);
                width=evalin('base',['importMSv.compare.img_',num2str(currentFile),'.width;']);
                
                figure;imagesc(mascara);
                axis ('tight','image','off');colormap(colorll);%colorbar;
                hold on
                line([col,col],[row,row+high],'color','r','LineWidth',2)
                line([col,col+width],[row,row],'color','r','LineWidth',2)
                line([col+width,col+width],[row,row+high],'color','r','LineWidth',2)
                line([col,col+width],[row+high,row+high],'color','r','LineWidth',2)
                hold off
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            list_name=evalin('base','importMSv.compare.list_namer;');
            currentFile=evalin('base','importMSv.compare.currentFile;');
            
            assignin('base','currentFile',currentFile);
            evalin('base',['importMSv.compare.save_list.list', list_name,'.currentFile=currentFile;']);
            
            evalin("base",'x=importMSv.compare.mz.x;');
            evalin('base',['importMSv.compare.save_list.list', list_name,'.x=x;']);
            
            evalin('base',['mean_y=importMSv.compare.img_',num2str(currentFile),'.mean_y;']);
            evalin('base',['importMSv.compare.save_list.list', list_name,'.mean_y=mean_y;']);
            
            evalin('base',['im=importMSv.compare.img_',num2str(currentFile),';']);
            evalin('base',['importMSv.compare.save_list.list', list_name,'.im=im;']);
            
            %加o
            evalin('base',['importMSv.compare.save_list.listo', list_name,'.currentFile=currentFile;']);
            
            evalin('base',['importMSv.compare.save_list.listo', list_name,'.x=x;']);
            
            evalin('base',['omean_y=importMSv.compare.img_',num2str(currentFile),'.omean_y;']);
            evalin('base',['importMSv.compare.save_list.listo', list_name,'.mean_y=omean_y;']);
            
            evalin('base',['importMSv.compare.save_list.listo', list_name,'.im=im;']);


            evalin('base','clear currentFile name_short x mean_y im omean_y');
            
            app.RedisplayButton.Enable='on';
            app.EditField_2.Editable='on';
            disp(['************************** Save the list. Name:',list_name,'*******************************'])
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            list_name = app.EditField.Value;
            assignin('base','list_name',list_name);
            evalin('base','importMSv.compare.list_namer=list_name;');
            evalin('base','clear list_name');
        end

        % Button pushed function: RedisplayButton
        function RedisplayButtonPushed(app, event)
            cla(app.UIAxes)
            list_name=app.EditField_2.Value;
            
            colorll=evalin('base','importMSv.summary.color;');
            currentFile=evalin('base',['importMSv.compare.save_list.list', list_name,'.currentFile;']);
            
            x=evalin('base',['importMSv.compare.save_list.list', list_name,'.x;']);
            mean_y=evalin('base',['importMSv.compare.save_list.list', list_name,'.mean_y;']);
            
            [xx,yy]=SupplementZero(x,mean_y);
            
            Pho=plot(app.UIAxes,xx,yy);
            
            list=[x;mean_y]';
            
            assignin('base','mean_list',list);
            assignin('base','Pho',Pho);
            evalin('base',['importMSv.compare.img_',num2str(currentFile),'.Pho=Pho;']);
            evalin('base','clear Pho;');
            
            %
            MSicut=evalin('base',['importMSv.compare.save_list.list', list_name,'.im.MSicut;']);
            Umzvalue=evalin('base','importMSv.compare.Umzvalue;');
            peizhunvalue=evalin('base','importMSv.compare.peizhunvalue;');
            
            if peizhunvalue==1
                mascara=evalin('base',['importMSv.Registration.img_',num2str(currentFile),'.finalre;']);
            else
                if Umzvalue==1
                    x=evalin("base",'importMSv.compare.mz.x;');
                    x_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.x_cut;']);
                    y_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.y_cut;']);
                    %MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
                    y=evalin("base",['importMSv.compare.img_',num2str(currentFile),'.y;']);
                    mz=str2double(app.EditField_3.Value);
                    [~,n]=min(abs(x-mz));
                    mz_value=x(n);
                    delta=10^-6*10*mz_value;
                    ieq=find(abs(x-mz_value)<=delta);
                    original_data=sum(y(:,ieq),2);
                    mascara=reshape(original_data,x_pixels,y_pixels);
                    mascara=set_range(mascara,4,0.95);
                else
                    mascara=evalin("base",['importMSv.compare.img_',num2str(currentFile),'.re_y;']);
                    mascara=set_range(mascara,4,0.95);
                    
                end
            end
            
            if MSicut==2
                k=evalin('base',['importMSv.compare.save_list.list', list_name,'.im.k;']);
                noreg=evalin('base',['importMSv.compare.save_list.list', list_name,'.im.noreg;']);
                B=evalin('base',['importMSv.compare.save_list.list', list_name,'.im.B;']);
                
                figure;imagesc(mascara);
                axis ('tight','image','off');colormap(colorll);colorbar
                hold on
                for i=1:(noreg-1)
                    m=B{i}(1,:);
                    n=B{i}(2,:);
                    kk=k(i);
                    for ii=2:(kk-1)
                        line([m(ii-1) m(ii)],[n(ii-1) n(ii)],'color','r','LineWidth',2);
                    end
                    line([m(kk-1) m(1)],[n(kk-1) n(1)],'color','r','LineWidth',2);
                end
                hold off
            elseif MSicut==3
                row=evalin('base',['importMSv.compare.save_list.list', list_name,'.im.row;']);
                col=evalin('base',['importMSv.compare.save_list.list', list_name,'.im.col;']);
                high=evalin('base',['importMSv.compare.save_list.list', list_name,'.im.high;']);
                width=evalin('base',['importMSv.compare.save_list.list', list_name,'.im.width;']);
                
                figure;imagesc(mascara);
                axis ('tight','image','off');colormap(colorll);%colorbar
                hold on
                line([col,col],[row,row+high],'color','r','LineWidth',2)
                line([col,col+width],[row,row],'color','r','LineWidth',2)
                line([col+width,col+width],[row,row+high],'color','r','LineWidth',2)
                line([col,col+width],[row+high,row+high],'color','r','LineWidth',2)
                hold off
            end
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            threshold=str2double(app.EditField_4.Value);
            currentFile=evalin('base','importMSv.compare.currentFile;');
            x=evalin("base",'importMSv.compare.mz.x;');
            mean_y=evalin('base',['importMSv.compare.img_',num2str(currentFile),'.mean_y']);
            list=[x;mean_y]';
            
            list(list(:,2)<=threshold,:)=[];
            
            [xx,yy]=SupplementZero(list(:,1),list(:,2));
            xmin=min(xx);xmax=max(xx);ymin=min(yy);ymax=max(yy);
            
            assignin('base','mean_list',list)
            
            plot(app.UIAxes,xx,yy);
            xticklabels(app.UIAxes,'auto');axis(app.UIAxes,[xmin xmax ymin ymax]);
        end

        % Button pushed function: ExportlistButton
        function ExportlistButtonPushed(app, event)
            list=evalin('base','mean_list');
            
            [file,path,Index] = uiputfile({'*.csv';'*.txt';'*.xlsx';'*.xls';'*.*'},'Please select a save folder');
            if Index == 0
                return;
            else
                save_file=strcat(path,file);
                writecell([{'m/z','intensity'};num2cell(list)],save_file);
                disp([' ****************************Saved to ' save_file '*****************************']);
            end
        end

        % Button pushed function: FluxscreeningButton
        function FluxscreeningButtonPushed(app, event)
            %通量分析
            No = app.NoEditField_2.Value;
            
            assignin('base','No1',No);
            evalin('base','importMSv.compare.name.No1=No1;');
            
            assignin('base','No2',['o',No]);
            evalin('base','importMSv.compare.name.No2=No2;');
            
            evalin('base','clear No1 No2;');
            RunComperelist
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RectangleSelectionButton = uibutton(app.UIFigure, 'push');
            app.RectangleSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @RectangleSelectionButtonPushed, true);
            app.RectangleSelectionButton.Text = 'Rectangle Selection';
            app.FreeselectionButton = uibutton(app.UIFigure, 'push');
            app.FreeselectionButton.ButtonPushedFcn = createCallbackFcn(app, @FreeselectionButtonPushed, true);
            app.FreeselectionButton.Text = 'Free selection';
            app.CorrespondingdistributionButton = uibutton(app.UIFigure, 'push');
            app.CorrespondingdistributionButton.ButtonPushedFcn = createCallbackFcn(app, @CorrespondingdistributionButtonPushed, true);
            app.CorrespondingdistributionButton.Text = 'Corresponding distribution';
            app.AveragespectraofthechosenareaLabel = uilabel(app.UIFigure);
            app.AveragespectraofthechosenareaLabel.Text = 'Average spectra of the chosen area ';
            app.NoEditField = uieditfield(app.UIFigure, 'text');
            app.VSNoEditField = uieditfield(app.UIFigure, 'text');
            app.CompareButton = uibutton(app.UIFigure, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.NoLabel = uilabel(app.UIFigure);
            app.NoLabel.Text = ' No.';
            app.VSNoLabel = uilabel(app.UIFigure);
            app.VSNoLabel.Text = 'VS No.';
            app.LabraryCheckBox = uicheckbox(app.UIFigure);
            app.LabraryCheckBox.ValueChangedFcn = createCallbackFcn(app, @LabraryCheckBoxValueChanged, true);
            app.LabraryCheckBox.Text = 'Labrary?';
            app.RedisplaycurrentROIareaButton = uibutton(app.UIFigure, 'push');
            app.RedisplaycurrentROIareaButton.ButtonPushedFcn = createCallbackFcn(app, @RedisplaycurrentROIareaButtonPushed, true);
            app.RedisplaycurrentROIareaButton.Enable = 'off';
            app.RedisplaycurrentROIareaButton.Text = 'Redisplay current ROI area';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.NoLabel_2 = uilabel(app.UIFigure);
            app.NoLabel_2.Text = 'No.';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.NoLabel_3 = uilabel(app.UIFigure);
            app.NoLabel_3.Text = 'No.';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Editable = 'off';
            app.RedisplayButton = uibutton(app.UIFigure, 'push');
            app.RedisplayButton.ButtonPushedFcn = createCallbackFcn(app, @RedisplayButtonPushed, true);
            app.RedisplayButton.Enable = 'off';
            app.RedisplayButton.Text = 'Redisplay';
            app.UsemzCheckBox = uicheckbox(app.UIFigure);
            app.UsemzCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsemzCheckBoxValueChanged, true);
            app.UsemzCheckBox.Text = 'Use m/z?';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Editable = 'off';
            app.SignalintensitythresholdLabel = uilabel(app.UIFigure);
            app.SignalintensitythresholdLabel.Text = 'Signal intensity threshold';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ExportlistButton = uibutton(app.UIFigure, 'push');
            app.ExportlistButton.ButtonPushedFcn = createCallbackFcn(app, @ExportlistButtonPushed, true);
            app.ExportlistButton.Text = 'Export list';
            app.NoEditField_2Label = uilabel(app.UIFigure);
            app.NoEditField_2Label.Text = 'No.';
            app.NoEditField_2 = uieditfield(app.UIFigure, 'text');
            app.FluxscreeningButton = uibutton(app.UIFigure, 'push');
            app.FluxscreeningButton.ButtonPushedFcn = createCallbackFcn(app, @FluxscreeningButtonPushed, true);
            app.FluxscreeningButton.Text = 'Flux screening';
            app.MSIdataDropDownLabel = uilabel(app.UIFigure);
            app.MSIdataDropDownLabel.Text = 'MSI data';
            app.MSIdataDropDown = uidropdown(app.UIFigure);
            app.MSIdataDropDown.Items = {};
            app.MSIdataDropDown.ValueChangedFcn = createCallbackFcn(app, @MSIdataDropDownValueChanged, true);
            app.MSIdataDropDown.Value = {};
            app.UsetheregisteredimageCheckBox = uicheckbox(app.UIFigure);
            app.UsetheregisteredimageCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsetheregisteredimageCheckBoxValueChanged, true);
            app.UsetheregisteredimageCheckBox.Text = 'Use the registered image?';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'm/z')
            ylabel(app.UIAxes, 'Intensity')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Runcompare

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end