classdef Runclustering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        KmeanLabel                 matlab.ui.control.Label
        HowmanycategorieswouldyouliketoclassifyLabel  matlab.ui.control.Label
        Editclassify               matlab.ui.control.EditField
        RunKmeanButton             matlab.ui.control.Button
        MSIdataDropDownLabel       matlab.ui.control.Label
        MSIdataDropDown            matlab.ui.control.DropDown
        Label                      matlab.ui.control.Label
        EvaluateButton             matlab.ui.control.Button
        AveragemassspectrumButton  matlab.ui.control.Button
        MethodDropDownLabel        matlab.ui.control.Label
        MethodDropDown             matlab.ui.control.DropDown
        ViewButton                 matlab.ui.control.Button
        UseCoptCheckBox            matlab.ui.control.CheckBox
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            runmcr=evalin('base','importMSv.summary.runmcr;');
            if runmcr==2
                app.UseCoptCheckBox.Enable='on';
            end
            evalin('base','importMSv.kmean.usecopt=0;');
            noFiles=evalin('base','importMSv.load.noFiles;');
            for h=1:noFiles
                name=evalin('base',['importMSv.summary.img_',num2str(h),'.name_short;']);
                llistnnc=name;
                llistannc(h)={llistnnc};
            end
            app.MSIdataDropDown.Items=llistannc;
            
            runclustering=evalin('base','importMSv.summary.runclustering;');
            if runclustering==1
                evalin('base','importMSv.kmean.currentFile=1;');
                evalin('base','importMSv.kmean.method=1;');
                % 全数据分类
                evalin('base','importMSv.kmean.tsne.clustering=1;');
                evalin('base','importMSv.kmean.pca.clustering=1;');
                % 单独分类
                %                 for i=1:noFiles
                %                     evalin('base',['importMSv.kmean.img_',num2str(i),'.tsne.clustering=1;']);
                %                     evalin('base',['importMSv.kmean.img_',num2str(i),'.pca.clustering=1;']);
                %                 end
                evalin('base','importMSv.summary.runclustering=2;');
            end
            
            Allpixel(1)=0;
            for i=1:noFiles
                pix=evalin('base',['importMSv.ROIplotGUI.img_',num2str(i),'.Allpixel_cut;']);
                Allpixel(i+1)=Allpixel(i)+pix;
            end
            assignin('base','Allpixel',Allpixel)
            evalin('base','importMSv.kmean.Allpixel=Allpixel;');
            evalin("base",'clear Allpixel;');
            
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            app.Label.Text='';
        end

        % Value changed function: UseCoptCheckBox
        function UseCoptCheckBoxValueChanged(app, event)
            usecopt = app.UseCoptCheckBox.Value;
            assignin('base','usecopt',usecopt);
            evalin('base','importMSv.kmean.usecopt=usecopt;');
            evalin('base','clear usecopt;');
            
            evalin('base','importMSv.kmean.tsne.clustering=1;');
            evalin('base','importMSv.kmean.pca.clustering=1;');
        end

        % Value changed function: MSIdataDropDown
        function MSIdataDropDownValueChanged(app, event)
            app.MSIdataDropDown.ItemsData = 1:length(app.MSIdataDropDown.Items);
            currentFile=app.MSIdataDropDown.Value;
            
            assignin('base','currentFile',currentFile)
            evalin('base','importMSv.kmean.currentFile=currentFile;');
            evalin("base",'clear currentFile;');
            
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            app.Label.Text='';
            
            method=evalin('base','importMSv.kmean.method;');
            dataaa=1;
            if method==1
                % tsne
                runclustering=evalin('base','importMSv.kmean.tsne.clustering;');
                if runclustering==2
                    Dim_data=evalin('base','importMSv.kmean.tsne.Dim_data;');
                    dataaa=2;
                end
            else
                % pca
                runclustering=evalin('base','importMSv.kmean.pca.clustering;');
                if runclustering==2
                    Dim_data=evalin('base','importMSv.kmean.pca.Dim_data;');
                    dataaa=2;
                end
            end
            
            if dataaa==2
                cidxn=evalin('base','importMSv.kmean.cidxn;');
                cmap=evalin('base','importMSv.kmean.cmap;');
                class=evalin('base','importMSv.kmean.class;');
                
                Allpixel=evalin('base','importMSv.kmean.Allpixel;');
                m=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.x_cut;']);
                n=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.y_cut;']);
                name_short=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.name_short;']);
                app.Label.Text=name_short;
                
                Dim_data=Dim_data(Allpixel(currentFile)+1:Allpixel(currentFile+1),:);
                cidxn=cidxn(Allpixel(currentFile)+1:Allpixel(currentFile+1),:);
                
                %                 partialareaROI=evalin('base',' importMSv.summary.runpartialareaROI;');
                %                 if partialareaROI==2
                %                     MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
                %                     if MSicut==2
                %                         mask=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.mask;']);
                %                         mask=reshape(mask,[],1); mask(mask==1)=cidxn;cidxn=mask;
                %                         class_name='Class0';
                %                         for j=1:class
                %                             name=['Class',num2str(j)] ;
                %                             name=string(name);
                %                             class_name=[class_name,name];
                %                         end
                %                         class=class+1;
                %                     end
                %                 else
                
                class_name='Class1';
                for j=2:class
                    name=['Class',num2str(j)] ;
                    name=string(name);
                    class_name=[class_name,name];
                end
                cidxnn=cidxn;
                
                runpartialareaROI=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.runpartialareaROI;']);
                if runpartialareaROI==2
                    MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
                    if MSicut==2
                        mask=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.mask;']);
                        mascaran=reshape(double(mask),[],1);
                        mascaran(mascaran==1)=cidxn;
                        cidxn=mascaran;
                        
                        class_name='Pixelless';
                        for j=1:class
                            name=['Class',num2str(j)] ;
                            name=string(name);
                            class_name=[class_name,name];
                        end
                        class=class+1;
                        
                    end
                end
                
                %end
                imagesc(app.UIAxes,reshape(cidxn,m,n));
                figure('visible','off');axis(app.UIAxes,'tight','image');colormap(app.UIAxes,cmap);
                %                 L = line(app.UIAxes,ones(class),ones(class), 'LineWidth',2);
                %                 set(L,{'color'},mat2cell(cmap,ones(1,class),3));
                %                 legend(app.UIAxes,class_name,'FontSize',16,'Location','northeastoutside')
                L2 = line(app.UIAxes,ones(class),ones(class), 'LineWidth',2);
                set(L2,{'color'},mat2cell(cmap,ones(1,class),3));
                legend(app.UIAxes,class_name,'FontSize',16,'Location','northeastoutside')
                
                % 可视化成像
                scatter(app.UIAxes2,Dim_data(:,1), Dim_data(:,2), 10, cidxnn,'filled','HandleVisibility','off');
                xmin=min(Dim_data(:,1));xmax=max(Dim_data(:,1));ymin=min(Dim_data(:,2));ymax=max(Dim_data(:,2));
                figure('visible','off');axis(app.UIAxes2,[xmin xmax ymin ymax]);colormap(app.UIAxes2,cmap);
                
                if runpartialareaROI==2
                    MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
                    if MSicut==2
                        colormap(app.UIAxes2,cmap(2:end,:));
                    end
                end
                
            end
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            app.MethodDropDown.ItemsData = 1:length(app.MethodDropDown.Items);
            method=app.MethodDropDown.Value;
            
            assignin('base','method',method)
            evalin('base','importMSv.kmean.method=method;');
            evalin("base",'clear method;');
            
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            app.Label.Text='';
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            % 轮廓系数评估聚类数
            % 全数据聚类
            usecopt=evalin('base','importMSv.kmean.usecopt;');
            dim_pca=100;
            if usecopt==1
                k_data=evalin('base','importMSv.mcr_als.cache.copt_xxx;');
                dim_pca=0;
            else
                MSroi=evalin('base','importMSv.ROIplotGUI.current_MSroi;');
                k_data=full(MSroi);
            end
            
            method=evalin('base','importMSv.kmean.method;');
            rng default
            if method==1
                % tsne
                runclustering=evalin('base','importMSv.kmean.tsne.clustering;');
                if runclustering==1
                    Dim_data=tsne(k_data,'Algorithm','barneshut',...
                        'Distance','cosine',...
                        'Exaggeration',4,...
                        'NumDimensions',3,...
                        'NumPCAComponents',dim_pca,...
                        'Perplexity',30,...
                        'Standardize',false,...
                        'LearnRate',500,...
                        'NumPrint',50);
                    assignin('base','Dim_data',Dim_data)
                    evalin('base','importMSv.kmean.tsne.Dim_data=Dim_data;');
                    evalin("base",'clear Dim_data;');
                    evalin('base','importMSv.kmean.tsne.clustering=2;');
                else
                    Dim_data=evalin('base','importMSv.kmean.tsne.Dim_data;');
                end
            else
                %pca
                runclustering=evalin('base','importMSv.kmean.pca.clustering;');
                if runclustering==1
                    [~,Dim_data]=pca(k_data,'NumComponents',3);
                    %Dim_data=normalize(Dim_data,"center","mean");
                    assignin('base','Dim_data',Dim_data)
                    evalin('base','importMSv.kmean.pca.Dim_data=Dim_data;');
                    evalin("base",'clear Dim_data;');
                    evalin('base','importMSv.kmean.pca.clustering=2;');
                else
                    Dim_data=evalin('base','importMSv.kmean.pca.Dim_data;');
                end
            end
            
            %             % 单独聚类
            %             currentFile=evalin('base','importMSv.kmean.currentFile;');
            %             partialareaROI=evalin('base',' importMSv.summary.runpartialareaROI;');
            %             if partialareaROI==2
            %                 MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
            %                 if MSicut==2
            %                     MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.ori_MSroi;']);
            %                 else
            %                     MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
            %                 end
            %             else
            %                 MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
            %             end
            %
            %             MSroi=full(MSroi);
            %
            %             method=evalin('base','importMSv.kmean.method;');
            %             rng default
            %             if method==1
            %                 % tsne
            %                 % 单独聚类
            %                 runclustering=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.clustering;']);
            %
            %                 if runclustering==1
            %                     Dim_data=tsne(MSroi,'Algorithm','barneshut',...
            %                         'Distance','euclidean',...
            %                         'Exaggeration',4,...
            %                         'NumDimensions',2,...
            %                         'NumPCAComponents',0,...
            %                         'Perplexity',30,...
            %                         'Standardize',false,...
            %                         'LearnRate',500,...
            %                         'NumPrint',50);
            %                     assignin('base','Dim_data',Dim_data)
            %                     evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.Dim_data=Dim_data;']);
            %                     evalin("base",'clear Dim_data;');
            %                     evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.clustering=2;']);
            %
            %                 else
            %                     Dim_data=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.Dim_data;']);
            %                 end
            %             else
            %                 %pca
            %                 runclustering=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.clustering;']);
            %                 if runclustering==1
            %                     [~,Dim_data]=pca(MSroi);
            %                     Dim_data=normalize(Dim_data,"center","mean");
            %                     assignin('base','Dim_data',Dim_data)
            %                     evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.Dim_data=Dim_data;']);
            %                     evalin("base",'clear Dim_data;');
            %                     evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.clustering=2;']);
            %                 else
            %                     Dim_data=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.Dim_data;']);
            %                 end
            %             end
            
            % 计算每个 K 值对应的轮廓系数
            % 计算每个 K 值对应的轮廓系数
            sse=[];
            silhouette_scores=[];
            
            for i = 2:20
                [idx,~,sumd ]= kmeans(Dim_data, i,"Distance","cosine");
                silhouette_scores(i) = mean(silhouette(Dim_data,idx));
                sse(i,1) = sum(sumd);
            end
            figure;plot(2:20,silhouette_scores(2:20), '-o');xlabel('Number of Clusters (k)');ylabel('Silhouette Coefficient');title('silhouette scores')
            figure,plot(2:20,sse(2:20), '-o');xlabel('Number of Clusters (k)');ylabel('sse');title('Elbow method');
        end

        % Button pushed function: RunKmeanButton
        function RunKmeanButtonPushed(app, event)
            %kmean
            class=str2double(app.Editclassify.Value);
            assignin('base','kmean_class',class)
            evalin('base','importMSv.kmean.class=kmean_class;');
            evalin("base",'clear kmean_class;');
            
            currentFile=evalin('base','importMSv.kmean.currentFile;');
            name_short=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.name_short;']);
            app.Label.Text=name_short;
            % 全数据聚类
            usecopt=evalin('base','importMSv.kmean.usecopt;');
            dim_pca=100;
            if usecopt==1
                k_data=evalin('base','importMSv.mcr_als.cache.copt_xxx;');
                dim_pca=0;
            else
                MSroi=evalin('base','importMSv.ROIplotGUI.current_MSroi;');
                k_data=full(MSroi);
            end
            
            
            method=evalin('base','importMSv.kmean.method;');
            rng default
            if method==1
                % tsne
                runclustering=evalin('base','importMSv.kmean.tsne.clustering;');
                if runclustering==1
                    Dim_data=tsne(k_data,'Algorithm','barneshut',...
                        'Distance','cosine',...
                        'Exaggeration',4,...
                        'NumDimensions',3,...
                        'NumPCAComponents',dim_pca,...
                        'Perplexity',30,...
                        'Standardize',false,...
                        'LearnRate',500,...
                        'NumPrint',50);
                    assignin('base','Dim_data',Dim_data)
                    evalin('base','importMSv.kmean.tsne.Dim_data=Dim_data;');
                    evalin("base",'clear Dim_data;');
                    evalin('base','importMSv.kmean.tsne.clustering=2;');
                else
                    Dim_data=evalin('base','importMSv.kmean.tsne.Dim_data;');
                end
            else
                %pca
                runclustering=evalin('base','importMSv.kmean.pca.clustering;');
                if runclustering==1
                    [~,Dim_data]=pca(k_data,'NumComponents',3);
                    %Dim_data=normalize(Dim_data,"center","mean");
                    assignin('base','Dim_data',Dim_data)
                    evalin('base','importMSv.kmean.pca.Dim_data=Dim_data;');
                    evalin("base",'clear Dim_data;');
                    evalin('base','importMSv.kmean.pca.clustering=2;');
                else
                    Dim_data=evalin('base','importMSv.kmean.pca.Dim_data;');
                end
            end
            
            % 单独聚类
            % partialareaROI=evalin('base',' importMSv.summary.runpartialareaROI;');
            %             if partialareaROI==2
            %                 MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
            %                 if MSicut==2
            %                     MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.ori_MSroi;']);
            %                 else
            %                     MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
            %                 end
            %             else
            %                 MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
            %             end
            %
            %             MSroi=full(MSroi);
            %
            %             method=evalin('base','importMSv.kmean.method;');
            %             rng default
            %             if method==1
            %                 % tsne
            %                 runclustering=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.clustering;']);
            %                 if runclustering==1
            %                     Dim_data=tsne(MSroi,'Algorithm','barneshut',...
            %                         'Distance','euclidean',...
            %                         'Exaggeration',4,...
            %                         'NumDimensions',2,...
            %                         'NumPCAComponents',0,...
            %                         'Perplexity',30,...
            %                         'Standardize',false,...
            %                         'LearnRate',500,...
            %                         'NumPrint',50);
            %                     assignin('base','Dim_data',Dim_data)
            %                     evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.Dim_data=Dim_data;']);
            %                     evalin("base",'clear Dim_data;');
            %                     evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.clustering=2;']);
            %                 else
            %                     Dim_data=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.Dim_data;']);
            %                 end
            %             else
            %                 %pca
            %                 runclustering=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.clustering;']);
            %                 if runclustering==1
            %                     [~,Dim_data]=pca(MSroi);
            %                     Dim_data=normalize(Dim_data,"center","mean");
            %                     assignin('base','Dim_data',Dim_data)
            %                     evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.Dim_data=Dim_data;']);
            %                     evalin("base",'clear Dim_data;');
            %                     evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.clustering=2;']);
            %                 else
            %                     Dim_data=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.Dim_data;']);
            %                 end
            %             end
            
            % 聚类
            [cidxn,~] = kmeans(Dim_data,class,"Distance",'cosine');
            assignin('base','cidxn',cidxn)
            evalin('base','importMSv.kmean.cidxn=cidxn;');
            evalin("base",'clear cidxn;');
            
            Allpixel=evalin('base','importMSv.kmean.Allpixel;');
            m=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.x_cut;']);
            n=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.y_cut;']);
            
            Dim_data=Dim_data(Allpixel(currentFile)+1:Allpixel(currentFile+1),:);
            cidxn=cidxn(Allpixel(currentFile)+1:Allpixel(currentFile+1),:);
            
            % 聚类成像
            %             if partialareaROI==2
            %                 MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
            %                 if MSicut==2
            %                     mask=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.mask;']);
            %                     mask=reshape(mask,[],1); mask(mask==1)=cidxn;cidxn=mask;
            %                     class_name='Class0';
            %                     for j=1:class
            %                         name=['Class',num2str(j)] ;
            %                         name=string(name);
            %                         class_name=[class_name,name];
            %                     end
            %                     class=class+1;
            %                 end
            %             else
            
            class_name='Class1';
            for j=2:class
                name=['Class',num2str(j)] ;
                name=string(name);
                class_name=[class_name,name];
            end
            cidxnn=cidxn;
            
            runpartialareaROI=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.runpartialareaROI;']);
            if runpartialareaROI==2
                MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
                if MSicut==2
                    mask=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.mask;']);
                    mascaran=reshape(double(mask),[],1);
                    mascaran(mascaran==1)=cidxn;
                    cidxn=mascaran;
                    
                    class_name='Pixelless';
                    for j=1:class
                        name=['Class',num2str(j)] ;
                        name=string(name);
                        class_name=[class_name,name];
                    end
                    class=class+1;
                    
                end
            end
            
            % 类别颜色flipud parula
            cmap = slanCL(556,1:class);
            assignin('base','cmap',cmap)
            evalin('base','importMSv.kmean.cmap=cmap;');
            evalin("base",'clear cmap;');
            
            %end
            imagesc(app.UIAxes,reshape(cidxn,m,n));
            figure('visible','off');axis(app.UIAxes,'tight','image');colormap(app.UIAxes,cmap);
            %             L = line(app.UIAxes,ones(class),ones(class), 'LineWidth',2);
            %             set(L,{'color'},mat2cell(cmap,ones(1,class),3));
            %             legend(app.UIAxes,class_name,'FontSize',16,'Location','northeastoutside')
            L2 = line(app.UIAxes,ones(class),ones(class), 'LineWidth',2);
            set(L2,{'color'},mat2cell(cmap,ones(1,class),3));
            legend(app.UIAxes,class_name,'FontSize',16,'Location','northeastoutside')
            
            % 可视化成像
            scatter(app.UIAxes2,Dim_data(:,1), Dim_data(:,2), 10, cidxnn,'filled','HandleVisibility','off');
            xmin=min(Dim_data(:,1));xmax=max(Dim_data(:,1));ymin=min(Dim_data(:,2));ymax=max(Dim_data(:,2));
            figure('visible','off');axis(app.UIAxes2,[xmin xmax ymin ymax]);
            if runpartialareaROI==2
                MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
                if MSicut==2
                    colormap(app.UIAxes2,cmap(2:end,:));
                else
                    colormap(app.UIAxes2,cmap);
                end
            else
                colormap(app.UIAxes2,cmap);
            end
            
            
        end

        % Button pushed function: AveragemassspectrumButton
        function AveragemassspectrumButtonPushed(app, event)
            closeallhide
            exportcluster
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            Clu_view
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KmeanLabel = uilabel(app.UIFigure);
            app.KmeanLabel.Text = 'K-mean';
            app.HowmanycategorieswouldyouliketoclassifyLabel = uilabel(app.UIFigure);
            app.HowmanycategorieswouldyouliketoclassifyLabel.Text = 'How many categories would you like to classify?';
            app.Editclassify = uieditfield(app.UIFigure, 'text');
            app.RunKmeanButton = uibutton(app.UIFigure, 'push');
            app.RunKmeanButton.ButtonPushedFcn = createCallbackFcn(app, @RunKmeanButtonPushed, true);
            app.RunKmeanButton.Text = 'Run Kmean';
            app.MSIdataDropDownLabel = uilabel(app.UIFigure);
            app.MSIdataDropDownLabel.Text = 'MSI data';
            app.MSIdataDropDown = uidropdown(app.UIFigure);
            app.MSIdataDropDown.Items = {};
            app.MSIdataDropDown.ValueChangedFcn = createCallbackFcn(app, @MSIdataDropDownValueChanged, true);
            app.MSIdataDropDown.Value = {};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EvaluateButton = uibutton(app.UIFigure, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.AveragemassspectrumButton = uibutton(app.UIFigure, 'push');
            app.AveragemassspectrumButton.ButtonPushedFcn = createCallbackFcn(app, @AveragemassspectrumButtonPushed, true);
            app.AveragemassspectrumButton.Text = 'Average mass spectrum';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'t-sne', 'PCA'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 't-sne';
            app.ViewButton = uibutton(app.UIFigure, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.UseCoptCheckBox = uicheckbox(app.UIFigure);
            app.UseCoptCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseCoptCheckBoxValueChanged, true);
            app.UseCoptCheckBox.Enable = 'off';
            app.UseCoptCheckBox.Text = 'Use Copt';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Runclustering

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end