classdef Editcolor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EditcolormapLabel       matlab.ui.control.Label
        ColormapDropDownLabel   matlab.ui.control.Label
        ColormapDropDown        matlab.ui.control.DropDown
        InvertcolormapCheckBox  matlab.ui.control.CheckBox
        EnterButton             matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            for attempt = 1:4
                if attempt==1
                    try
                        mascara=evalin('base','importMSv.summary.img_1.mascara');
                        break;
                    catch
                    end
                elseif attempt==2
                    try
                        mascara=evalin('base','importMSv.ROIplotGUI.img_1.mascara');
                        break;
                    catch
                    end
                elseif attempt==3
                    try
                        filep=evalin('base','importMSv.load.filep;');
                        load_file=strcat(filep,'\function\preview\expim.mat');
                        assignin('base','load_file',load_file);
                        evalin('base','mas=importdata(string(load_file));');
                        mascara=evalin('base','mas;');
                        evalin('base','clear mas load_file;');
                        break;
                    catch
                    end
                elseif attempt==4
                    disp('*******************Please click ^Access to Information^ or ^run roi^ first***********************')
                end
            end
            
            data=set_range(mascara,4,0.95);
            imagesc(app.UIAxes,data)
            axis(app.UIAxes,'tight','image') ;
            colormap(app.UIAxes,'jet');
            colorbar(app.UIAxes);
            
            assignin('base','data',data);
            evalin('base','importMSv.load.expim=data;');
            evalin('base','clear data;');
            evalin('base','importMSv.summary.color=jet;');
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            cla(app.UIAxes,"reset");
            app.UIAxes.XTick=[];
            app.UIAxes.YTick=[];
            
            fli=app.InvertcolormapCheckBox.Value;
            value = app.ColormapDropDown.Value;
            
            warning('off', 'all')
            
            im=evalin('base','importMSv.load.expim;');
            imagesc(app.UIAxes,im)
            axis(app.UIAxes,'tight','image') ;
            colorr=colormap(app.UIAxes,value);
            if fli==1
                colorr=colormap(app.UIAxes,flipud(colorr));
            end
            colorbar(app.UIAxes);
            
            assignin('base','colorr',colorr);
            evalin('base','importMSv.summary.color=colorr;');
            evalin('base','clear colorr;');
        end

        % Value changed function: InvertcolormapCheckBox
        function InvertcolormapCheckBoxValueChanged(app, event)
            cla(app.UIAxes,"reset");
            app.UIAxes.XTick=[];
            app.UIAxes.YTick=[];
            
            fli=app.InvertcolormapCheckBox.Value;
            value = app.ColormapDropDown.Value;
            
            warning('off', 'all')
            
            im=evalin('base','importMSv.load.expim;');
            imagesc(app.UIAxes,im)
            axis(app.UIAxes,'tight','image') ;
            colorr=colormap(app.UIAxes,value);
            if fli==1
                colorr=colormap(app.UIAxes,flipud(colorr));
            end
            colorbar(app.UIAxes);
            
            assignin('base','colorr',colorr);
            evalin('base','importMSv.summary.color=colorr;');
            evalin('base','clear colorr;');
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditcolormapLabel = uilabel(app.UIFigure);
            app.EditcolormapLabel.Text = 'Edit colormap';
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {'jet', 'hot', 'parula', 'hsv', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'flag'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'jet';
            app.InvertcolormapCheckBox = uicheckbox(app.UIFigure);
            app.InvertcolormapCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertcolormapCheckBoxValueChanged, true);
            app.InvertcolormapCheckBox.Text = 'Invert colormap?';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Editcolor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end