classdef PeakView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectpixelsfromLabel          matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        toLabel                        matlab.ui.control.Label
        EditField_2                    matlab.ui.control.EditField
        EnterButton                    matlab.ui.control.Button
        PeakViewLabel                  matlab.ui.control.Label
        SignalintensitythresholdLabel  matlab.ui.control.Label
        EditField_3                    matlab.ui.control.EditField
        EnterButton_2                  matlab.ui.control.Button
        ExportlistButton               matlab.ui.control.Button
        MSIdataDropDownLabel           matlab.ui.control.Label
        MSIdataDropDown                matlab.ui.control.DropDown
        ViewtheLabel                   matlab.ui.control.Label
        EditField_4                    matlab.ui.control.EditField
        DropDown                       matlab.ui.control.DropDown
        EnterButton_3                  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            noFiles=evalin('base','importMSv.load.noFiles;');
            for h=1:noFiles
                name=evalin('base',['importMSv.summary.img_',num2str(h),'.name_short;']);
                llistnnc=name;
                llistannc(h)={llistnnc};
            end
            app.MSIdataDropDown.Items=llistannc;
            evalin('base','importMSv.peakview.currentFile=1;');
            evalin('base','importMSv.peakview.kind=1;');
        end

        % Value changed function: MSIdataDropDown
        function MSIdataDropDownValueChanged(app, event)
            app.MSIdataDropDown.ItemsData = 1:length(app.MSIdataDropDown.Items);
            currentFile=app.MSIdataDropDown.Value;
            
            assignin('base','currentFile',currentFile)
            evalin('base','importMSv.peakview.currentFile=currentFile;');
            evalin("base",'clear currentFile;');
            
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.DropDown.ItemsData = 1:2;
            kind=app.DropDown.Value;
            
            assignin('base','kind',kind)
            evalin('base','importMSv.peakview.kind=kind;');
            evalin("base",'clear kind;');
        end

        % Button pushed function: EnterButton_3
        function EnterButton_3Pushed(app, event)
            currentFile=evalin('base','importMSv.peakview.currentFile;');
            kind=evalin('base','importMSv.peakview.kind;');
            no=str2double(app.EditField_4.Value);
            
            [rc_pack,lt,tic]=previewaverage(currentFile,no,kind);
            
            plot(app.UIAxes,tic);
            
            assignin('base','rc_pack',rc_pack)
            evalin('base','importMSv.peakview.rc_pack=rc_pack;');
            
            assignin('base','lt',lt)
            evalin('base','importMSv.peakview.lt=lt;');
            
            assignin('base','tic',tic)
            evalin('base','importMSv.peakview.tic=tic;');
            
            evalin("base",'clear rc_pack lt tic;');
            
            app.EditField.Editable='on';
            app.EditField_2.Editable='on';
            app.EnterButton.Enable='on';
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            lt=evalin('base','importMSv.peakview.lt;');
            minpixel=ceil(str2double(app.EditField.Value));
            maxpixel=ceil(str2double(app.EditField_2.Value));
            
            if minpixel<=0
                disp('****************Please change the starting pixel point***********************')
            elseif maxpixel>lt
                disp('****************Please change the termination pixel point***********************')
            else
                rc_pack=evalin('base','importMSv.peakview.rc_pack;');
                pack=rc_pack(minpixel:maxpixel);
                
                [mzroi,roicell]=new_peakviewroi(pack);
                meanint=(cellfun(@(x) sum(x), roicell(:,2))/length(pack))';
                
                [xx,yy]=SupplementZero(mzroi,meanint);
                xmin=min(xx);xmax=max(xx);ymin=min(yy);ymax=max(yy);
                
                plot(app.UIAxes2,xx,yy);
                xticklabels(app.UIAxes2,'auto');axis(app.UIAxes2,[xmin xmax ymin ymax]);
                
                assignin('base','pmzroi',mzroi)
                evalin('base','importMSv.peakview.mzroi=pmzroi;');
                
                assignin('base','proicell',roicell)
                evalin('base','importMSv.peakview.roicell=proicell;');
                
                assignin('base','pmeanint',meanint)
                evalin('base','importMSv.peakview.meanint=pmeanint;');
                
                evalin("base",'clear pmzroi proicell pmeanint;');
                
                list=full([mzroi;meanint]');
                assignin('base','peakviewlist',list)
                evalin('base','importMSv.peakview.list=peakviewlist;')
                
                app.EditField_3.Editable='on';
                app.EnterButton_2.Enable='on';
            end
        end

        % Button pushed function: EnterButton_2
        function EnterButton_2Pushed(app, event)
            threshold=str2double(app.EditField_3.Value);
            mz=evalin('base','importMSv.peakview.mzroi;');
            meanint=evalin('base','importMSv.peakview.meanint;');
            
            idx=find(meanint<=threshold);
            
            mz(:,idx)=[];
            meanint(:,idx)=[];
            
            [xx,yy]=SupplementZero(mz,meanint);
            xmin=min(xx);xmax=max(xx);ymin=min(yy);ymax=max(yy);
            
            plot(app.UIAxes2,xx,yy);
            xticklabels(app.UIAxes2,'auto');axis(app.UIAxes2,[xmin xmax ymin ymax]);
            
            list=full([mz;meanint]');
            assignin('base','peakviewlist',list)
            evalin('base','importMSv.peakview.list=peakviewlist;')
            app.ExportlistButton.Enable='on';
        end

        % Button pushed function: ExportlistButton
        function ExportlistButtonPushed(app, event)
            peakviewlist=evalin('base','importMSv.peakview.list;');
            
            [file,path,Index] = uiputfile({'*.csv';'*.xlsx';'*.xls';'*.txt';'*.*'},'Please select a save folder');
            if Index == 0
                return;
            else
                save_file=strcat(path,file);
                writecell([{'m/z','intensity'};num2cell(peakviewlist)],save_file); 
                disp([' ****************************Saved to ' save_file '*****************************']);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectpixelsfromLabel = uilabel(app.UIFigure);
            app.SelectpixelsfromLabel.Text = 'Select pixels from';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.toLabel = uilabel(app.UIFigure);
            app.toLabel.Text = 'to';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Editable = 'off';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Enable = 'off';
            app.EnterButton.Text = 'Enter';
            app.PeakViewLabel = uilabel(app.UIFigure);
            app.PeakViewLabel.Text = 'Peak View';
            app.SignalintensitythresholdLabel = uilabel(app.UIFigure);
            app.SignalintensitythresholdLabel.Text = 'Signal intensity threshold';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Editable = 'off';
            app.EnterButton_2 = uibutton(app.UIFigure, 'push');
            app.EnterButton_2.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_2Pushed, true);
            app.EnterButton_2.Enable = 'off';
            app.EnterButton_2.Text = 'Enter';
            app.ExportlistButton = uibutton(app.UIFigure, 'push');
            app.ExportlistButton.ButtonPushedFcn = createCallbackFcn(app, @ExportlistButtonPushed, true);
            app.ExportlistButton.Enable = 'off';
            app.ExportlistButton.Text = 'Export list';
            app.MSIdataDropDownLabel = uilabel(app.UIFigure);
            app.MSIdataDropDownLabel.Text = 'MSI data';
            app.MSIdataDropDown = uidropdown(app.UIFigure);
            app.MSIdataDropDown.Items = {};
            app.MSIdataDropDown.ValueChangedFcn = createCallbackFcn(app, @MSIdataDropDownValueChanged, true);
            app.MSIdataDropDown.Value = {};
            app.ViewtheLabel = uilabel(app.UIFigure);
            app.ViewtheLabel.Text = 'View the';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Row', 'Column'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Row';
            app.EnterButton_3 = uibutton(app.UIFigure, 'push');
            app.EnterButton_3.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_3Pushed, true);
            app.EnterButton_3.Text = 'Enter';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'TIC')
            xlabel(app.UIAxes, 'Pixels')
            ylabel(app.UIAxes, 'Intensity')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Average mass spectrum')
            xlabel(app.UIAxes2, 'm/z')
            ylabel(app.UIAxes2, 'Intensity')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PeakView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end