classdef RunAUG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MSiToolboxAUGLabel           matlab.ui.control.Label
        DataPanel                    matlab.ui.container.Panel
        DataimportPanel              matlab.ui.container.Panel
        FilenameLabel                matlab.ui.control.Label
        SelectButton                 matlab.ui.control.Button
        FileNameLabel                matlab.ui.control.Label
        EnterButton                  matlab.ui.control.Button
        TotalnumberofdataLabel       matlab.ui.control.Label
        EditNoImages                 matlab.ui.control.EditField
        ListListBox                  matlab.ui.control.ListBox
        ListLabel                    matlab.ui.control.Label
        AbbreviatedinformationPanel  matlab.ui.container.Panel
        NoofpixelsisLabel            matlab.ui.control.Label
        PixNumberLabel               matlab.ui.control.Label
        ypixelsLabel                 matlab.ui.control.Label
        yPixNumberLabel              matlab.ui.control.Label
        xpixelsLabel                 matlab.ui.control.Label
        xPixNumberLabel              matlab.ui.control.Label
        LocationofpixelsLabel        matlab.ui.control.Label
        xEditField                   matlab.ui.control.EditField
        yEditField                   matlab.ui.control.EditField
        xLabel                       matlab.ui.control.Label
        yLabel                       matlab.ui.control.Label
        SpectrapreviewButton         matlab.ui.control.Button
        DetailedinformationPanel     matlab.ui.container.Panel
        PreviewAll                   matlab.ui.control.Button
        PlotTICButton                matlab.ui.control.Button
        MSintensityLabel             matlab.ui.control.Label
        Maxint                       matlab.ui.control.Label
        MedianLabel                  matlab.ui.control.Label
        Medint                       matlab.ui.control.Label
        mzrangefromLabel             matlab.ui.control.Label
        Mzlow                        matlab.ui.control.Label
        toLabel                      matlab.ui.control.Label
        Mzhigh                       matlab.ui.control.Label
        MaxLabel                     matlab.ui.control.Label
        PickviewButton               matlab.ui.control.Button
        AUGButton                    matlab.ui.control.Button
        AlgorithmparametersPanel     matlab.ui.container.Panel
        MasserrortoleranceLabel      matlab.ui.control.Label
        editMassError                matlab.ui.control.EditField
        MassunitsLabel               matlab.ui.control.Label
        MassunitsDropDown            matlab.ui.control.DropDown
        NormalizationDropDownLabel   matlab.ui.control.Label
        NormalizationDropDown        matlab.ui.control.DropDown
        MCRButton                    matlab.ui.control.Button
        AveragemassspectrumButton    matlab.ui.control.Button
        ClusteringButton             matlab.ui.control.Button
        ViewButton                   matlab.ui.control.Button
        ImageregistrationButton      matlab.ui.control.Button
        RenormalizeButton            matlab.ui.control.Button
        PlotTICButton_2              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base','clear all;');
            
            filep=pwd;
            addpath(genpath(filep));
            assignin('base','filep',filep);
            evalin('base','importMSv.load.filep=filep;');
            evalin('base','importMSv.summary.color=jet;');
            evalin('base','clear filep;');
            
            evalin('base','importMSv.targ.mzFilter = 0;');
            evalin('base','importMSv.targ.mzTargeted = 0;');
            evalin('base','importMSv.msroi.ROI_threshold=0;');
            evalin('base','importMSv.msroi.factorThresh=20;');
            evalin('base','importMSv.msroi.ROI_error=0.01;');
            evalin('base','importMSv.msroi.minPixels=10;');
            evalin('base',['importMSv.msroi.ROI_units=','''daltons'';']);
            evalin('base',['importMSv.msroi.weighted=','''default: mean'';']);
            evalin('base','importMSv.msroi.nornum=1;');
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            % 输入文件个数
            noFiles = str2double(app.EditNoImages.Value);
            assignin('base','noFiles',noFiles);
            evalin('base','importMSv.load.noFiles=noFiles;');
            evalin('base','clear noFiles;');
            %  Listbox
            if noFiles==1
                llistannc(1)={'Image no. 1'};
                
                app.ListListBox.Items = llistannc;
                app.ListListBox.Enable = 'off';
            else
                for i=1:noFiles
                    llistnnc=['Image no. ',num2str(i)];
                    llistannc(i)={llistnnc};
                end
                
                app.ListListBox.Items = llistannc;
                app.ListListBox.Enable = 'on';
                
                evalin('base','importMSv.load.currentFile=1;');
                loadedFiles=zeros(noFiles,1);
                assignin('base','loadedFiles',loadedFiles);
                evalin('base','importMSv.load.loadedFiles=loadedFiles;');
                evalin('base','clear loadedFiles;');
            end
            
            if noFiles==1
                app.SelectButton.Enable = 'on';
            end
        end

        % Value changed function: ListListBox
        function ListListBoxValueChanged(app, event)
            % 指定文件
            app.ListListBox.ItemsData = 1:length(app.ListListBox.Items);
            currentFile = app.ListListBox.Value;
            assignin('base','currentFile',currentFile);
            evalin('base','importMSv.load.currentFile=currentFile;');
            evalin('base','clear currentFile;');
            % loadedbefore?
            loadedFile=evalin('base','importMSv.load.loadedFiles;');
            
            if loadedFile(currentFile,1)==1
                
                name_short=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.name_short;']);
                no_pixels=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.no_pixels;']);
                ypixels=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.ypixels;']);
                xpixels=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.xpixels;']);
                
                % summary
                app.FileNameLabel.Text = [name_short, ' (reloaded)'];
                app.PixNumberLabel.Text = num2str(no_pixels);
                app.xPixNumberLabel.Text = num2str(xpixels);
                app.yPixNumberLabel.Text = num2str(ypixels);
                app.xEditField.Enable='on';
                app.yEditField.Enable='on';
                
                % dataPreview?
                dataPreview=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.dataPreview;']);
                if dataPreview==2
                    maxInt=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.maxInt;']);
                    medInt=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.medInt;']);
                    mzLow=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mzLow;']);
                    mzHigh=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mzHigh;']);
                    
                    app.Maxint.Text=num2str(maxInt);
                    app.Medint.Text=num2str(medInt);
                    app.Mzlow.Text=num2str(mzLow);
                    app.Mzhigh.Text=num2str(mzHigh);
                    
                    app.PlotTICButton.Enable='on';
                else
                    app.Maxint.Text='';
                    app.Medint.Text='';
                    app.Mzlow.Text='';
                    app.Mzhigh.Text='';
                    
                    app.PlotTICButton.Enable='off';
                end
            else % nous valors
                % summary
                app.FileNameLabel.Text = '';
                app.PixNumberLabel.Text = '';
                app.xPixNumberLabel.Text = '';
                app.yPixNumberLabel.Text = '';
                app.xEditField.Value='';
                app.yEditField.Value='';
                app.Maxint.Text='';
                app.Medint.Text='';
                app.Mzlow.Text='';
                app.Mzhigh.Text='';
                
                app.xEditField.Enable='off';
                app.yEditField.Enable='off';
            end
            app.SelectButton.Enable = 'on';
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % 选择数据
            currentFile=evalin('base','importMSv.load.currentFile;');
            [filename, pathname,Index] = uigetfile('*.mat', 'Pick a mat file');
            if Index==0
                return;
            else
                app.FileNameLabel.Text = filename;
                
                
                load_file=strcat(pathname,filename);
                assignin('base','load_file',load_file);
                evalin('base','data=importdata(string(load_file));');
                evalin('base','clear load_file;');
                
                %evalin('base',['importMSv.aug.data',num2str(currentFile),'=data;']);
                
                evalin('base',['importMSv.load.img_',num2str(currentFile),'=data.load.img_1;']);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'=data.summary.img_1;'])
                
                [~,name_short,~] = fileparts(filename);
                assignin('base','name_short',name_short);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.name_short=name_short;']);
                evalin('base','clear name_short;');
                
                runpartialareaROI=evalin('base','data.summary.img_1.runpartialareaROI;');
                if runpartialareaROI==2
                   evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'=data.Partialarea.img_1;']); 
                end
%                     MSicut=evalin('base','data.Partialarea.img_1.MSicut;');
%                     
%                     if MSicut==2
%                         data_shape=evalin('base','data.Partialarea.img_1.mask;');
%                         Yn=evalin('base','data.ROIplotGUI.current_NonorMSroi');
%                         mask
%                         [xn_cut,yn_cut]=size(data_shape);
%                         Allpixeln_cut=xn_cut*yn_cut;
%                         
%                         [~,lie]=size(Yn);
%                         newMSroin=sparse(Allpixeln_cut,lie);
%                         lable=reshape(data_shape,[],1);
%                         newMSroin(lable==1,:)=Yn;
%                         
%                         assignin('base','newMSroin',newMSroin);
%                         evalin('base',['importMSv.AUG.img_',num2str(currentFile),'.MSroi=newMSroin;'])
%                         evalin('base','clear newMSroin;');
%                     else
%                         data_shape=evalin('base','data.Partialarea.img_1.out_roi');
%                         evalin('base',['importMSv.AUG.img_',num2str(currentFile),'.MSroi=data.ROIplotGUI.current_NonorMSroi;'])
%                     end
%                     [ypixn,xpixn]=size(data_shape);
%                     no_pixel=xpixn*ypixn;
%                     assignin('base','no_pixel',no_pixel);
%                     assignin('base','xpixn',xpixn);
%                     assignin('base','ypixn',ypixn);
%                     
%                     evalin('base',['importMSv.AUG.img_',num2str(currentFile),'.Allpixel=no_pixel;'])
%                     evalin('base',['importMSv.AUG.img_',num2str(currentFile),'.xpixels=xpixn;'])
%                     evalin('base',['importMSv.AUG.img_',num2str(currentFile),'.ypixels=ypixn;'])
%                     
%                     evalin('base','clear no_pixel xpixn ypixn;');
%                 else
%                     evalin('base',['importMSv.AUG.img_',num2str(currentFile),'.Allpixel=data.ROIplotGUI.img_1.Allpixel_cut;'])
%                     evalin('base',['importMSv.AUG.img_',num2str(currentFile),'.xpixels=data.ROIplotGUI.img_1.x_cut;'])
%                     evalin('base',['importMSv.AUG.img_',num2str(currentFile),'.ypixels=data.ROIplotGUI.img_1.y_cut;'])
%                     evalin('base',['importMSv.AUG.img_',num2str(currentFile),'.MSroi=data.ROIplotGUI.img_1.ori_MSroi;'])
%                 end
%                 
                evalin('base',['importMSv.AUG.img_',num2str(currentFile),'.Allpixel=data.ROIplotGUI.img_1.Allpixel_cut;'])
                evalin('base',['importMSv.AUG.img_',num2str(currentFile),'.xpixels=data.ROIplotGUI.img_1.x_cut;'])
                evalin('base',['importMSv.AUG.img_',num2str(currentFile),'.ypixels=data.ROIplotGUI.img_1.y_cut;'])
                evalin('base',['importMSv.AUG.img_',num2str(currentFile),'.MSroi=data.ROIplotGUI.img_1.ori_MSroi;'])
                
                evalin('base',['importMSv.AUG.img_',num2str(currentFile),'.roicell=data.ROIplotGUI.current_roicell;'])
                evalin('base',['importMSv.AUG.img_',num2str(currentFile),'.mzroi=data.ROIplotGUI.current_mzroi;'])
                
                allpixel=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.no_pixels;']);
                cols=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.xpixels;']);
                rows=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.ypixels;']);
                
                app.PixNumberLabel.Text = num2str(allpixel);
                app.xPixNumberLabel.Text = num2str(cols);
                app.yPixNumberLabel.Text = num2str(rows);
                
                loadedFile=evalin('base','importMSv.load.loadedFiles;');
                loadedFile(currentFile,1)=1;
                assignin('base','loadedFile',loadedFile);
                evalin('base','importMSv.load.loadedFiles=loadedFile;');
                
                if sum(loadedFile)==evalin('base','importMSv.load.noFiles;')
                    evalin('base','importMSv.summary.conditionsROI=1;');
                end
                evalin('base','clear loadedFile;');
                
                app.xEditField.Enable='on';
                app.yEditField.Enable='on';
                app.SpectrapreviewButton.Enable='on';
                app.PreviewAll.Enable='on';
                app.PickviewButton.Enable='on';
                app.AUGButton.Enable='on';
            end
            
            evalin('base','clear data');
        end

        % Button pushed function: SpectrapreviewButton
        function SpectrapreviewButtonPushed(app, event)
            %预览特定图谱
            x = str2double(app.xEditField.Value);
            y= str2double(app.yEditField.Value);
            try
                Preview(x,y)
            catch
                errordlg('Out of index range','错误');
                disp('***********************Out of index range***********************');
            end
        end

        % Button pushed function: PickviewButton
        function PickviewButtonPushed(app, event)
            pickview
        end

        % Button pushed function: PreviewAll
        function PreviewAllPushed(app, event)
            %预览图谱
            currentFile=evalin('base','importMSv.load.currentFile;');
            dataPreview=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.dataPreview;']);
            name_short=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.name_short;']);
            xpix=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.xpixels']);
            ypix=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.ypixels']);
            tic_name=['tic_',name_short];
            %Frequency_name=['Frequency_',name_short];
            
            if dataPreview==1
                [tic,maximImg,medianImg,mzMax,mzMin,~]=PreviewIntensity;
                
                app.Maxint.Text=num2str(maximImg);
                app.Medint.Text=num2str(medianImg);
                app.Mzlow.Text=num2str(mzMin);
                app.Mzhigh.Text=num2str(mzMax);
                
                [mascara,Indexes,Indexesii]=cut(tic,xpix,ypix);
                
                newtic=tic(Indexes,:);
                
                assignin('base','mascara',mascara);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mascara=mascara;']);
                
                assignin('base','Indexes',Indexes);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.Indexes=Indexes;']);
                
                assignin('base','Indexesii',Indexesii);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.Indexesii=Indexesii;']);
                
                assignin('base',tic_name,newtic);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.tic_summary=',tic_name,';']);
                
                assignin('base','maxInt',maximImg);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.maxInt=maxInt;']);
                
                assignin('base','medInt',medianImg);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.medInt=medInt;']);
                
                assignin('base','mzLow',mzMin);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mzLow=mzLow;']);
                
                assignin('base','mzHigh',mzMax);
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mzHigh=mzHigh;']);
                
                evalin('base','clear Frequency maxInt medInt mzLow mzHigh mascara Indexes Indexesii');
                evalin('base',['importMSv.summary.img_',num2str(currentFile),'.dataPreview=2;']);
            end
            
            app.PlotTICButton.Enable='on';
        end

        % Value changed function: editMassError
        function editMassErrorValueChanged(app, event)
            error = str2double(app.editMassError.Value);
            assignin('base','ROI_error',error);
            evalin('base','importMSv.msroi.ROI_error=ROI_error;');
            evalin('base','clear ROI_error;');
        end

        % Value changed function: MassunitsDropDown
        function MassunitsDropDownValueChanged(app, event)
            units = app.MassunitsDropDown.Value;
            assignin('base','ROI_units',units);
            evalin('base','importMSv.msroi.ROI_units=ROI_units;');
            evalin('base','clear ROI_units');
        end

        % Value changed function: NormalizationDropDown
        function NormalizationDropDownValueChanged(app, event)
            app.NormalizationDropDown.ItemsData = 1:10;
            nornum = app.NormalizationDropDown.Value;
            assignin('base','nornum',nornum);
            evalin('base','importMSv.msroi.nornum=nornum;');
            evalin('base','clear nornum;');
        end

        % Button pushed function: AUGButton
        function AUGButtonPushed(app, event)
            noFiles=evalin('base','importMSv.load.noFiles;');
            
            units=evalin('base','importMSv.msroi.ROI_units;');
            mzerror=evalin('base','importMSv.msroi.ROI_error;');
            
            mzroi=evalin('base','importMSv.AUG.img_1.mzroi;');
            MSroi=evalin('base','importMSv.AUG.img_1.MSroi;');
            for i=2:noFiles
                mz=evalin('base',['importMSv.AUG.img_',num2str(i),'.mzroi;']);
                MS=evalin('base',['importMSv.AUG.img_',num2str(i),'.MSroi;']);
                Allpixel=evalin('base',['importMSv.AUG.img_',num2str(i),'.Allpixel;']);
                
                [m,n]=size(MSroi);
                
                new_mzroi=[mzroi,mz];
                new_MSroi=sparse(m+Allpixel,n+length(mz));
                new_MSroi(1:m,1:n)=MSroi;
                new_MSroi(m+1:end,n+1:end)=MS;
                
                [new_mzroi,isort]=sort(new_mzroi);
                new_MSroi=new_MSroi(:,isort);
                
                % 合并
                check_error=1;
                while check_error==1
                    error_mzroi=abs(diff(new_mzroi));
                    
                    if strcmp(units,'daltons')
                        valor=find(error_mzroi<mzerror);
                    elseif strcmp(units,'ppm')
                        delta=10^-6*mzerror*new_mzroi(1:end-1,1);
                        valor=find(error_mzroi<delta);
                    end
                    
                    valor(:,(find(abs(diff(valor))==1)+1))=[];
                    
                    if isempty(valor)
                        check_error=0;
                    else
                        new_mzroi(:,valor)=(new_mzroi(:,valor)+new_mzroi(:,valor+1))/2;
                        new_MSroi(:,valor)=(new_MSroi(:,valor)+new_MSroi(:,valor+1));
                        
                        new_MSroi(:,valor+1)=[];
                        new_mzroi(:,valor+1)=[];
                    end
                end
                
                MSroi=new_MSroi;
                mzroi=new_mzroi;
            end
            
            %
            assignin('base','mzroi',mzroi);
            evalin('base','importMSv.ROIplotGUI.current_mzroi=mzroi;');
            
            assignin('base','NonorMSroi',MSroi);
            evalin('base','importMSv.ROIplotGUI.current_NonorMSroi=NonorMSroi;');
            
            %标准化
            nornum=evalin('base','importMSv.msroi.nornum');
            allfun=norfun;
            eval(['[MSroi,~]=allfun.fun',num2str(nornum),'(MSroi);']);
            
            % Kmean、Compare初始化
            noFiles=evalin('base','importMSv.load.noFiles');
            colorll=evalin('base','importMSv.summary.color;');
            for si=1:noFiles
                runpartialareaROI=evalin('base',['importMSv.summary.img_',num2str(si),'.runpartialareaROI;']);
                if runpartialareaROI==1
                    no_pixel=evalin('base',['importMSv.AUG.img_',num2str(si),'.Allpixel;']);
                    xpixn=evalin('base',['importMSv.AUG.img_',num2str(si),'.xpixels;']);
                    ypixn=evalin('base',['importMSv.AUG.img_',num2str(si),'.ypixels;']);
                    
                    q=si+1;
                    numscan(1)=0;
                    numscan(q)=numscan(si)+no_pixel;
                    
                    Yn=MSroi(numscan(si)+1:numscan(q),:);
                    ori_MSroi=Yn;
                    
                    [mascaran,indexesn,~,xn_cut,yn_cut]=cut(Yn,xpixn,ypixn);
                    
                    Allpixeln_cut=xn_cut*yn_cut;
                    
                    newMSroin=Yn(indexesn,:);
                    
                else
                    out_roi=evalin('base',['importMSv.Partialarea.img_',num2str(si),'.out_roi']);
                    [ypixn,xpixn]=size(out_roi);
                    no_pixel=xpixn*ypixn;
                    
                    q=si+1;
                    numscan(1)=0;
                    numscan(q)=numscan(si)+no_pixel;
                    
                    Yn=MSroi(numscan(si)+1:numscan(q),:);
                    ori_MSroi=Yn;
                    
                    tic2=sum(Yn,2);
                    
                    MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(si),'.MSicut;']);
                    if MSicut==2
                        mask=evalin('base',['importMSv.Partialarea.img_',num2str(si),'.mask;']);
                        indexesn=double(mask);
                        indexesn(indexesn==1)=out_roi;
                        mascaran=double(mask);
                        mascaran(mascaran==1)=tic2;
                        [xn_cut,yn_cut]=size(mascaran);
                        
                        Allpixeln_cut=length(tic2);
                        
                        %                     [~,lie]=size(Yn);
                        %                     newMSroin=sparse(Allpixeln_cut,lie);
                        %                     lable=reshape(mask,[],1);
                        %                     newMSroin(lable==1,:)=Yn;
                        %                     newMSroin(lable==0,:)=nan;
                        newMSroin=Yn;
                    else
                        newMSroin=Yn;
                        indexesn=out_roi;
                        [xn_cut,yn_cut]=size(indexesn);
                        mascaran=reshape(tic2,xn_cut,yn_cut);
                        Allpixeln_cut=length(tic2);
                    end
                    
                end
                
                
                assignin('base','ori_MSroi',ori_MSroi);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.ori_MSroi=ori_MSroi;']);
                
                assignin('base','newMSroi',newMSroin);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.MSroi=newMSroi;']);
                
                assignin('base','mascaran',mascaran);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.mascara=mascaran;']);
                
                assignin('base','indexesn',indexesn);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.indexes=indexesn;']);
                
                assignin('base','x_cut',xn_cut);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.x_cut=x_cut;']);
                
                assignin('base','y_cut',yn_cut);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.y_cut=y_cut;']);
                
                assignin('base','Allpixel_cut',Allpixeln_cut);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.Allpixel_cut=Allpixel_cut;']);
                
                evalin('base','clear NonorMSroi newMSroi mascaran indexesn x_cut y_cut Allpixel_cut ori_MSroi nmzerror');
                
                name=evalin('base',['importMSv.summary.img_',num2str(si),'.name_short;']);
                [xx,yy]=SupplementZero(mzroi,mean(newMSroin,1));
                xmin=min(xx);xmax=max(xx);ymin=min(yy);ymax=max(yy);
                
                figure,plot(xx,yy,'LineWidth',2);
                xlabel('m/z');ylabel('Intensity')
                xticklabels('auto');axis([xmin xmax ymin ymax]);
                title({strcat('name : ',name);'Total average mass spectrum'},'Interpreter','none')
                mascara=set_range(mascaran,4,0.95);
                figure,imagesc(mascara)
                axis('xy','tight','image') ;colormap(colorll);colorbar;
                title({strcat('name : ',name);'TIC'},'Interpreter','none')
            end
            
            MSroi_cut=[];
            for ii=1:noFiles
                MSroi_n=evalin('base',['importMSv.ROIplotGUI.img_',num2str(ii),'.MSroi;']);
                MSroi_cut=[MSroi_cut;MSroi_n];
            end
            assignin('base','MSroi',MSroi_cut);
            evalin('base','importMSv.ROIplotGUI.current_MSroi=MSroi;');
            
            app.PlotTICButton_2.Enable='on';
            app.MCRButton.Enable='on';
            app.AveragemassspectrumButton.Enable='on';
            app.ImageregistrationButton.Enable='on';
            app.ClusteringButton.Enable='on';
            app.ViewButton.Enable='on';
            app.RenormalizeButton.Enable='on';
            app.PlotTICButton.Enable='on';
            
            evalin('base','importMSv.summary.runROI=2;');
            evalin('base','importMSv.summary.runmcr=1;');
            evalin('base','importMSv.summary.runcompare=1;');
            evalin('base','importMSv.summary.runclustering=1;');
            evalin('base','importMSv.summary.runEqual=1;');
            
            disp(' ************************** FINISH *******************************');
        end

        % Button pushed function: PlotTICButton
        function PlotTICButtonPushed(app, event)
            % 画总强度图
            currentFile=evalin('base','importMSv.load.currentFile;');
            mascara=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.mascara']);
            Indexes=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.Indexes']);
            Indexesii=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.Indexesii']);
            name_short=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.name_short;']);
            colorll=evalin('base','importMSv.summary.color;');
            
            I=set_range(mascara,4,0.95);
            
            figure,imagesc(I);
            axis('xy','tight','image') ;colormap(colorll);colorbar;
            title({['Name: ',name_short]; []},'Interpreter','none');
            
            % 点击像素点查看质谱图
            exite=0;
            try
                [x,y]=ginput(1);
                exite=1;
            catch
            end
            if exite==1
                x=round(x);
                y=round(y);
                % 定位十字
                hold on;
                [m,n]=size(mascara);
                level_x=[0,n];
                level_y=[y,y];
                vertical_x=[x,x];
                vertical_y=[0,m];
                plot(level_x,level_y,'k',vertical_x,vertical_y,'k','linewidth',2);
                hold off;
                
                % 对应到原始谱中
                point=Indexes(y,x);
                [y1,x1]=find(Indexesii==point);
                try
                    Preview(x1,y1)
                catch
                    errordlg('Out of index range','错误');
                    disp('***********************Out of index range***********************');
                end
            end
        end

        % Button pushed function: PlotTICButton_2
        function PlotTICButton_2Pushed(app, event)
            noFiles=evalin('base','importMSv.load.noFiles;');
            mzroi=evalin('base','importMSv.ROIplotGUI.current_mzroi;');
            colorll=evalin('base','importMSv.summary.color;');
            
            for si=1:noFiles
                name=evalin('base',['importMSv.summary.img_',num2str(si),'.name_short;']);
                newMSroin=evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.MSroi;']);
                mascaran=evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.mascara;']);
                [xx,yy]=SupplementZero(mzroi,mean(newMSroin,1));
                xmin=min(xx);xmax=max(xx);ymin=min(yy);ymax=max(yy);
                
                figure,plot(xx,yy,'LineWidth',2);
                xlabel('m/z');ylabel('Intensity')
                xticklabels('auto');axis([xmin xmax ymin ymax]);
                title({strcat('name : ',name);'Total average mass spectrum'},'Interpreter','none')
                
                mascara=set_range(mascaran,4,0.95);
                figure,imagesc(mascara)
                axis('tight','image') ;colormap(colorll);colorbar;
                title({strcat('name : ',name);'TIC'},'Interpreter','none')
                
            end
        end

        % Button pushed function: RenormalizeButton
        function RenormalizeButtonPushed(app, event)
            nornum=evalin('base','importMSv.msroi.nornum');
            MSroi=evalin('base','importMSv.ROIplotGUI.current_NonorMSroi;');
            
            %标准化
            allfun=norfun;
            eval(['[MSroi,~]=allfun.fun',num2str(nornum),'(MSroi);']);
            
            % Kmean、Compare初始化
            noFiles=evalin('base','importMSv.load.noFiles');
            
            for si=1:noFiles
                no_pixel=evalin('base',['importMSv.AUG.img_',num2str(si),'.Allpixel;']);
                xpixn=evalin('base',['importMSv.AUG.img_',num2str(si),'.xpixels;']);
                ypixn=evalin('base',['importMSv.AUG.img_',num2str(si),'.ypixels;']);
                
                q=si+1;
                numscan(1)=0;
                numscan(q)=numscan(si)+no_pixel;
                
                Yn=MSroi(numscan(si)+1:numscan(q),:);
                ori_MSroi=Yn;
                
                [mascaran,indexesn,~,xn_cut,yn_cut]=cut(Yn,ypixn,xpixn);
                
                Allpixeln_cut=xn_cut*yn_cut;
                
                newMSroin=Yn(indexesn,:);
                
                
                assignin('base','ori_MSroi',ori_MSroi);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.ori_MSroi=ori_MSroi;']);
                
                assignin('base','newMSroi',newMSroin);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.MSroi=newMSroi;']);
                
                assignin('base','mascaran',mascaran);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.mascara=mascaran;']);
                
                assignin('base','indexesn',indexesn);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.indexes=indexesn;']);
                
                assignin('base','x_cut',xn_cut);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.x_cut=x_cut;']);
                
                assignin('base','y_cut',yn_cut);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.y_cut=y_cut;']);
                
                assignin('base','Allpixel_cut',Allpixeln_cut);
                evalin('base',['importMSv.ROIplotGUI.img_',num2str(si),'.Allpixel_cut=Allpixel_cut;']);
                
                evalin('base','clear newMSroi mascaran indexesn x_cut y_cut Allpixel_cut ori_MSroi');
                
            end
            
            MSroi_cut=[];
            for ii=1:noFiles
                MSroi_n=evalin('base',['importMSv.ROIplotGUI.img_',num2str(ii),'.MSroi;']);
                MSroi_cut=[MSroi_cut;MSroi_n];
            end
            assignin('base','MSroi',MSroi_cut);
            evalin('base','importMSv.ROIplotGUI.current_MSroi=MSroi;');
            disp(' ************************** FINISH *******************************');
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            RunMSimaging
        end

        % Button pushed function: MCRButton
        function MCRButtonPushed(app, event)
            Runmcr
        end

        % Button pushed function: ClusteringButton
        function ClusteringButtonPushed(app, event)
            Runclustering
        end

        % Button pushed function: AveragemassspectrumButton
        function AveragemassspectrumButtonPushed(app, event)
            Runcompare
        end

        % Button pushed function: ImageregistrationButton
        function ImageregistrationButtonPushed(app, event)
            RunImageRegistration
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MSiToolboxAUGLabel = uilabel(app.UIFigure);
            app.MSiToolboxAUGLabel.Text = 'MSiToolbox AUG';
            app.DataPanel = uipanel(app.UIFigure);
            app.DataPanel.Title = 'Data';
            app.DataimportPanel = uipanel(app.DataPanel);
            app.DataimportPanel.Title = 'Data import';
            app.FilenameLabel = uilabel(app.DataimportPanel);
            app.FilenameLabel.Text = 'File name:';
            app.SelectButton = uibutton(app.DataimportPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Enable = 'off';
            app.SelectButton.Text = 'Select';
            app.FileNameLabel = uilabel(app.DataimportPanel);
            app.FileNameLabel.Text = '';
            app.EnterButton = uibutton(app.DataimportPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.TotalnumberofdataLabel = uilabel(app.DataimportPanel);
            app.TotalnumberofdataLabel.Text = 'Total number of data:';
            app.EditNoImages = uieditfield(app.DataimportPanel, 'text');
            app.ListListBox = uilistbox(app.DataimportPanel);
            app.ListListBox.Items = {};
            app.ListListBox.ValueChangedFcn = createCallbackFcn(app, @ListListBoxValueChanged, true);
            app.ListListBox.Enable = 'off';
            app.ListListBox.Value = {};
            app.ListLabel = uilabel(app.DataimportPanel);
            app.ListLabel.Text = 'List';
            app.AbbreviatedinformationPanel = uipanel(app.DataPanel);
            app.AbbreviatedinformationPanel.Title = 'Abbreviated information';
            app.NoofpixelsisLabel = uilabel(app.AbbreviatedinformationPanel);
            app.NoofpixelsisLabel.Text = 'No. of pixels is';
            app.PixNumberLabel = uilabel(app.AbbreviatedinformationPanel);
            app.PixNumberLabel.Text = '';
            app.ypixelsLabel = uilabel(app.AbbreviatedinformationPanel);
            app.ypixelsLabel.Text = 'y-pixels';
            app.yPixNumberLabel = uilabel(app.AbbreviatedinformationPanel);
            app.yPixNumberLabel.Text = '';
            app.xpixelsLabel = uilabel(app.AbbreviatedinformationPanel);
            app.xpixelsLabel.Text = 'x-pixels';
            app.xPixNumberLabel = uilabel(app.AbbreviatedinformationPanel);
            app.xPixNumberLabel.Text = '';
            app.LocationofpixelsLabel = uilabel(app.AbbreviatedinformationPanel);
            app.LocationofpixelsLabel.Text = 'Location of pixels:';
            app.xEditField = uieditfield(app.AbbreviatedinformationPanel, 'text');
            app.xEditField.Enable = 'off';
            app.yEditField = uieditfield(app.AbbreviatedinformationPanel, 'text');
            app.yEditField.Enable = 'off';
            app.xLabel = uilabel(app.AbbreviatedinformationPanel);
            app.xLabel.Text = 'x:';
            app.yLabel = uilabel(app.AbbreviatedinformationPanel);
            app.yLabel.Text = 'y:';
            app.SpectrapreviewButton = uibutton(app.AbbreviatedinformationPanel, 'push');
            app.SpectrapreviewButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrapreviewButtonPushed, true);
            app.SpectrapreviewButton.Enable = 'off';
            app.SpectrapreviewButton.Text = 'Spectra preview';
            app.DetailedinformationPanel = uipanel(app.UIFigure);
            app.DetailedinformationPanel.Title = 'Detailed information';
            app.PreviewAll = uibutton(app.DetailedinformationPanel, 'push');
            app.PreviewAll.ButtonPushedFcn = createCallbackFcn(app, @PreviewAllPushed, true);
            app.PreviewAll.Enable = 'off';
            app.PreviewAll.Text = 'Access to Information';
            app.PlotTICButton = uibutton(app.DetailedinformationPanel, 'push');
            app.PlotTICButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTICButtonPushed, true);
            app.PlotTICButton.Enable = 'off';
            app.PlotTICButton.Text = 'Plot TIC';
            app.MSintensityLabel = uilabel(app.DetailedinformationPanel);
            app.MSintensityLabel.Text = 'MS intensity:';
            app.Maxint = uilabel(app.DetailedinformationPanel);
            app.Maxint.Text = '';
            app.MedianLabel = uilabel(app.DetailedinformationPanel);
            app.MedianLabel.Text = 'Median:';
            app.Medint = uilabel(app.DetailedinformationPanel);
            app.Medint.Text = '';
            app.mzrangefromLabel = uilabel(app.DetailedinformationPanel);
            app.mzrangefromLabel.Text = 'm/z range from';
            app.Mzlow = uilabel(app.DetailedinformationPanel);
            app.Mzlow.Text = '';
            app.toLabel = uilabel(app.DetailedinformationPanel);
            app.toLabel.Text = 'to';
            app.Mzhigh = uilabel(app.DetailedinformationPanel);
            app.Mzhigh.Text = '';
            app.MaxLabel = uilabel(app.DetailedinformationPanel);
            app.MaxLabel.Text = 'Max:';
            app.PickviewButton = uibutton(app.DetailedinformationPanel, 'push');
            app.PickviewButton.ButtonPushedFcn = createCallbackFcn(app, @PickviewButtonPushed, true);
            app.PickviewButton.Enable = 'off';
            app.PickviewButton.Text = 'Pickview';
            app.AUGButton = uibutton(app.UIFigure, 'push');
            app.AUGButton.ButtonPushedFcn = createCallbackFcn(app, @AUGButtonPushed, true);
            app.AUGButton.Enable = 'off';
            app.AUGButton.Text = 'AUG';
            app.AlgorithmparametersPanel = uipanel(app.UIFigure);
            app.AlgorithmparametersPanel.Title = 'Algorithm parameters';
            app.MasserrortoleranceLabel = uilabel(app.AlgorithmparametersPanel);
            app.MasserrortoleranceLabel.Text = 'Mass error tolerance';
            app.editMassError = uieditfield(app.AlgorithmparametersPanel, 'text');
            app.editMassError.ValueChangedFcn = createCallbackFcn(app, @editMassErrorValueChanged, true);
            app.editMassError.Value = '0.01';
            app.MassunitsLabel = uilabel(app.AlgorithmparametersPanel);
            app.MassunitsLabel.Text = 'Mass units';
            app.MassunitsDropDown = uidropdown(app.AlgorithmparametersPanel);
            app.MassunitsDropDown.Items = {'daltons', 'ppm'};
            app.MassunitsDropDown.ValueChangedFcn = createCallbackFcn(app, @MassunitsDropDownValueChanged, true);
            app.MassunitsDropDown.Value = 'daltons';
            app.NormalizationDropDownLabel = uilabel(app.AlgorithmparametersPanel);
            app.NormalizationDropDownLabel.Text = 'Normalization';
            app.NormalizationDropDown = uidropdown(app.AlgorithmparametersPanel);
            app.NormalizationDropDown.Items = {'default: TIC', 'None', 'Euclidean', 'Vecnorm2', 'Zscore_std', 'Norm2', 'Scale_std', 'Range', 'Center_mean', 'Center_median'};
            app.NormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @NormalizationDropDownValueChanged, true);
            app.NormalizationDropDown.Value = 'default: TIC';
            app.MCRButton = uibutton(app.UIFigure, 'push');
            app.MCRButton.ButtonPushedFcn = createCallbackFcn(app, @MCRButtonPushed, true);
            app.MCRButton.Enable = 'off';
            app.MCRButton.Text = 'MCR';
            app.AveragemassspectrumButton = uibutton(app.UIFigure, 'push');
            app.AveragemassspectrumButton.ButtonPushedFcn = createCallbackFcn(app, @AveragemassspectrumButtonPushed, true);
            app.AveragemassspectrumButton.Enable = 'off';
            app.AveragemassspectrumButton.Text = 'Average mass spectrum';
            app.ClusteringButton = uibutton(app.UIFigure, 'push');
            app.ClusteringButton.ButtonPushedFcn = createCallbackFcn(app, @ClusteringButtonPushed, true);
            app.ClusteringButton.Enable = 'off';
            app.ClusteringButton.Text = 'Clustering';
            app.ViewButton = uibutton(app.UIFigure, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Enable = 'off';
            app.ViewButton.Text = 'View';
            app.ImageregistrationButton = uibutton(app.UIFigure, 'push');
            app.ImageregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @ImageregistrationButtonPushed, true);
            app.ImageregistrationButton.Enable = 'off';
            app.ImageregistrationButton.Text = 'Image registration';
            app.RenormalizeButton = uibutton(app.UIFigure, 'push');
            app.RenormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @RenormalizeButtonPushed, true);
            app.RenormalizeButton.Enable = 'off';
            app.RenormalizeButton.Text = 'Renormalize';
            app.PlotTICButton_2 = uibutton(app.UIFigure, 'push');
            app.PlotTICButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotTICButton_2Pushed, true);
            app.PlotTICButton_2.Enable = 'off';
            app.PlotTICButton_2.Text = 'Plot TIC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunAUG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end