classdef exportpicture < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ListpathLabel       matlab.ui.control.Label
        Label               matlab.ui.control.Label
        LoadButton          matlab.ui.control.Button
        ExportpictureLabel  matlab.ui.control.Label
        ThecolumnwheremzislocatedinthelistLabel  matlab.ui.control.Label
        EditField           matlab.ui.control.EditField
        ExportimagebasedonanexistingExceltableLabel  matlab.ui.control.Label
        ExportButton        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % 导入数据
            try
                mzcol=str2double(app.EditField.Value);
            catch
                disp('******************Enter the column in the list that contains m/z**********************')
            end
            [file,path,Index] = uigetfile({'*.csv';'*.xlsx';'*.xls';'*.txt';'*.mat';'*.*'},'Please select the file you want to load');
            if Index == 0
                return;
            else
                load_file=strcat(path,file);
                app.Label.Text=string(load_file);
                
                assignin('base','load_file',load_file);
                evalin('base','Exportlist=importdata(string(load_file));');
                list=evalin('base','Exportlist;');
                try
                    if isa(list,'double')
                        mzlist=list(:,mzcol);
                    else
                        mzlist=list.data(:,mzcol);
                    end
                    mzlist=reshape(mzlist,[],1);
                    
                    assignin('base','mzlist',mzlist);
                    evalin('base','importMSv.Exportpicture.listpath=load_file;');
                    evalin('base','importMSv.Exportpicture.mzlist=mzlist;');
                catch
                    disp('*********Please change to txt file***************')
                end
                
                evalin('base','clear load_file Exportlist mzlist;');
                
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
             try
                savepath = uigetdir;
                assignin('base','savepath',savepath);
                evalin('base','importMSv.Exportpicture.savepath=savepath;')
                evalin('base','clear savepath;');
            catch
            end
            
            list=evalin('base','importMSv.Exportpicture.mzlist;');
            currentFile=evalin('base','importMSv.MSimaging.currentFile;');
            mzerror=evalin('base','importMSv.MSimaging.mzerror;');
            units=evalin('base','importMSv.MSimaging.units');
            savepath=evalin('base','importMSv.Exportpicture.savepath;');
            strength=evalin('base','importMSv.MSimaging.strength;');
            range_mode=evalin('base','importMSv.MSimaging.range_mode;');
            
            Export_picture(list,currentFile,mzerror,units,savepath,range_mode,strength)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListpathLabel = uilabel(app.UIFigure);
            app.ListpathLabel.Text = 'List path:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExportpictureLabel = uilabel(app.UIFigure);
            app.ExportpictureLabel.Text = 'Export picture';
            app.ThecolumnwheremzislocatedinthelistLabel = uilabel(app.UIFigure);
            app.ThecolumnwheremzislocatedinthelistLabel.Text = 'The column where m/z is located in the list';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.ExportimagebasedonanexistingExceltableLabel = uilabel(app.UIFigure);
            app.ExportimagebasedonanexistingExceltableLabel.Text = 'Export image based on an existing Excel table';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exportpicture

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end